classdef DialogGroup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        StudyPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        AddButton                      matlab.ui.control.Button
        RemoveButton                   matlab.ui.control.Button
        toDoListBox                    matlab.ui.control.ListBox
        ProcessListBox                 matlab.ui.control.ListBox
        StudyFileFilterEditFieldLabel  matlab.ui.control.Label
        StudyFileFilterEditField       matlab.ui.control.EditField
        DisProcessListBox              matlab.ui.control.ListBox
        ProceseePanel                  matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        SetParametersofProcessButton   matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        NetworkPanel                   matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ListBox_ProcessIP              matlab.ui.control.ListBox
        ListBox_toDoIP                 matlab.ui.control.ListBox
        ListBox_UnProcessIP            matlab.ui.control.ListBox
        Button_Add                     matlab.ui.control.Button
        Button_Remove                  matlab.ui.control.Button
        AddButton_IP                   matlab.ui.control.Button
        IPEditFieldLabel               matlab.ui.control.Label
        IPTextArea                     matlab.ui.control.EditField
        TestButton                     matlab.ui.control.Button
        isNetworkPanel                 matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        UsingNetworkButton             matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        CallingApp % Description
        FileFilter;
        Study;
        Subject;
        Subject_Idx
        SubjectSelected
        Idx;
        StudyState;    %[study,subject,protocol]
        StudySelected;
        
        DialogProcessAll;
        
        IP;
        Port;
        NotreadyIP;
        readyIP;
        toDoIP;
        NotreadyPort;
        readyPort;
        toDoPort;
        readyDeviceID;
        toDoDeviceID;
        readyDeviceName;
        toDoDeviceName;
    end
    
    properties (Access = public)
        IEDdetectionOpt;
        VirtualSensorOpt;
        HFOdetectionOpt;
        PreprocessOpt; % Description
        Interupt;
    end
    
    methods (Access = private)
        
        function GetStudyState(app)
            if(app.PreprocessOpt.isTSSSandICA)
                app.StudyState = repmat([1,0,0,0], size(app.Idx,1), 1);
            else
                app.StudyState = zeros(size(app.Idx,1),4);
                for i=1:size(app.Idx,1)
                    WaitBar = uiprogressdlg(app.UIFigure,'Title','Geting Study State','Message',['Geting State: ',num2str(i),'/',num2str(size(app.Idx,1))],'value',i/size(app.Idx,1));
                    Protocols = load(bst_get('BrainstormDbFile'));
                    ProtocolName = Protocols.ProtocolsListInfo(app.Idx(i,3)).Comment;
                    ProtocolFile = load(fullfile(Protocols.ProtocolsListInfo(app.Idx(i,3)).STUDIES,'protocol.mat'));
                    SubjName = ProtocolFile.ProtocolSubjects.Subject(app.Idx(i,2)).Name;
                    StudyName = ProtocolFile.ProtocolStudies.Study(app.Idx(i,1)).Condition{:};
                    app.StudyState(i,:) = [1,0,0,0]; % 0: cannot be processed; 1: can be processed; 2: processed
                    % Load SaveHFOdetectionResults
                    if(~exist(fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SaveHFOViewResults.mat'),'file'))
                        app.StudyState(i,:) = [2,2,2,1]; 
                    else
                        app.StudyState(i,4) = 2;
                    end 
                    % Load SaveVirtualSensorResults
                    if(~exist(fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SaveVirtualSensorResults.mat'),'file'))
                        app.StudyState(i,:) = [2,2,1,0]; 
                    else
                        app.StudyState(i,3) = 2;
                    end
                    % Load SaveSpikeViewResults
                    if(~exist(fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SaveSpikeViewResults.mat'),'file'))
                        app.StudyState(i,:) = [2,1,0,0];
                    else
                        app.StudyState(i,2) = 2;
                    end
                    % Load SaveBadEventDetectionResults
                    if(~exist(fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SavePreProcessResults.mat'),'file'))
                        app.StudyState(i,:) = [1,0,0,0]; 
                    else
                        app.StudyState(i,1) = 2;
                    end                  
                end
                close(WaitBar);
            end
        end      
        
        function GetStudy(app)
            WaitBar = uiprogressdlg(app.UIFigure,'Title','Geting Study State','Message','Reloading Database......','Indeterminate','on');
            close(WaitBar);
            WaitBar = uiprogressdlg(app.UIFigure,'Title','Geting Study','Message','Geting Study......','Indeterminate','on');
            Filter = cat(2,'^',strrep(app.FileFilter,'*','.*'),'$');
            % Get Studies
            Protocols = load(bst_get('BrainstormDbFile'));
            app.Study = cell(length(Protocols.ProtocolsListInfo),1);app.Idx = app.Study;
            for prtl=1:length(Protocols.ProtocolsListInfo)
                ProtocolFile = load(fullfile(Protocols.ProtocolsListInfo(prtl).STUDIES,'protocol.mat'));
                if(app.PreprocessOpt.isTSSSandICA)
                    % if do TSSSandICA, the raw fif filename was selected
                    StudyName = dir(fullfile(app.CallingApp.BrainstormDbDir, Protocols.ProtocolsListInfo(prtl).Comment, 'RAW'));StudyName = StudyName(contains({StudyName.name},'.fif'));
                    StudyName = cellfun(@(x, y)strrep(fullfile(x, y), '/System/Volumes/Data', ''),{StudyName.folder},{StudyName.name},'UniformOutput',false)';
                    % For the splitted saved fif file
                    StudyName = unique(cellfun(@(x)strrep(x,'-1.fif', '.fif'),StudyName,'UniformOutput',false));
                    StudyIdx = (1:length(StudyName))';
                    SubjectName = repmat({ProtocolFile.ProtocolSubjects.Subject.Name},length(StudyIdx),1);
                    SubjectIdx = repmat(find(ismember({ProtocolFile.ProtocolSubjects.Subject.Name},SubjectName)),length(StudyIdx),1);
                else
                    % if do not do TSSSandICA, the study in bst was selected
                    StudyName = cat(1,ProtocolFile.ProtocolStudies.Study.Condition);
                    StudyIdx = find(cellfun(@(x)~isempty(x),regexp(StudyName,Filter)));
                    StudyName = StudyName(StudyIdx);
                    SubjectName = cellfun(@(x)fileparts(x),{ProtocolFile.ProtocolStudies.Study(StudyIdx).BrainStormSubject},'UniformOutput',false)';
                    SubjectIdx = cellfun(@(x)find(ismember({ProtocolFile.ProtocolSubjects.Subject.Name},x)),SubjectName);
                end
                ProtocolName = repmat({Protocols.ProtocolsListInfo(prtl).Comment},length(StudyIdx),1);
                ProtocolIdx = repmat(prtl,length(StudyIdx),1);
                app.Study{prtl} = [StudyName,SubjectName,ProtocolName];
                app.Idx{prtl} = [StudyIdx,SubjectIdx,ProtocolIdx];
            end
            app.Idx(cellfun(@(x)isempty(x),app.Study))=[];
            app.Study(cellfun(@(x)isempty(x),app.Study))=[];
            app.Study = cat(1,app.Study{:});
            app.Idx = cat(1,app.Idx{:});
            app.Subject = unique(app.Study(:,3));
            app.Subject_Idx = cellfun(@(x)find(ismember(app.Study(:,3),x)),app.Subject,'UniformOutput',false);
            close(WaitBar);
        end
        
        function [status, Out] = Test(~, IP, Port)
               [status, Out] = GetDevice(IP, Port, 'cuiwei', 'Cwq3048284!');
               status = ~status;
        end
        
        function SetIPtable(app)
            app.readyIP = {};
            app.NotreadyIP = {};
            app.toDoIP = {};
            app.readyPort = [];
            app.NotreadyPort = [];
            app.toDoPort = [];
            app.readyDeviceID = [];
            app.toDoDeviceID = [];
            app.readyDeviceName = {};
            app.toDoDeviceName = {};
            for i=1:length(app.IP)
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Loading IP','Message',['IP: ',num2str(i),'/',num2str(length(app.IP))],'value',i/length(app.IP));
                [status, Out] = Test(app, app.IP{i}, app.Port(i));
                if(status)
                    app.readyIP = cat(1, app.readyIP, repmat(app.IP(i), size(Out.DeviceID,1), 1));
                    app.readyPort = cat(1, app.readyPort, repmat(app.Port(i), size(Out.DeviceID,1), 1));
                    app.readyDeviceID = cat(1, app.readyDeviceID, Out.DeviceID);
                    app.readyDeviceName = cat(1, app.readyDeviceName, Out.Device);
                else
                    app.NotreadyIP = [app.NotreadyIP, app.IP(i)];
                    app.NotreadyPort = [app.NotreadyPort, app.Port(i)];
                end
                close(WaitBar);
            end
            temp = cellfun(@(z, x, y)[z, ': ', x, ':', num2str(y)],app.readyDeviceName, app.readyIP, num2cell(app.readyPort), 'UniformOutput', false);
            app.ListBox_ProcessIP.Items = sort(temp);
            temp = cellfun(@(x, y)[x, ':', num2str(y)],app.NotreadyIP, num2cell(app.NotreadyPort), 'UniformOutput', false);
            app.ListBox_UnProcessIP.Items = sort(temp);
            temp = cellfun(@(z, x, y)[z, ': ', x, ':', num2str(y)],app.toDoDeviceName, app.toDoIP,num2cell(app.toDoPort), 'UniformOutput', false);
            app.ListBox_toDoIP.Items = sort(temp);
        end
    end
    
    methods (Access = public)
        
        function SetMode(app)
            GetStudyState(app);
            app.toDoListBox.Items = {};
            app.SubjectSelected = [];
            WaitBar = uiprogressdlg(app.UIFigure,'Title','Loading Study','Message','Loading Study......','Indeterminate','on');
            State = app.StudyState(:,1);
            DisList = find(cellfun(@(x)any(State(x)==0), app.Subject_Idx));
            UnList = find(cellfun(@(x)any(State(x)>0&app.StudyState(x,4)<2), app.Subject_Idx));
            ProList = find(cellfun(@(x)all(State(x)==2), app.Subject_Idx));
            app.DisProcessListBox.Items = cellfun(@(x)cat(2,x),app.Subject(DisList,:),'UniformOutput',false);
            UnListName = cellfun(@(x)cat(2,'U: ',x),app.Subject(UnList,:),'UniformOutput',false);
            ProListName = cellfun(@(x)cat(2,'P: ',x),app.Subject(ProList,:),'UniformOutput',false);
            app.ProcessListBox.Items = sort([UnListName;ProListName]);
            close(WaitBar);
        end
        % To make the same return format of dialog with main function
        function ModeInit(app)
            WaitBar = uiprogressdlg(app.UIFigure,'Title','Loading Group data','Message','Loading data','Indeterminate','on');
            GetStudy(app);
            close(WaitBar);
            SetMode(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Name = 'Process Data';
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp; 
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            app.DisProcessListBox.Enable = 0;
            app.CallingApp.GroupProcessIsNet = 0;
            app.NetworkPanel.Visible = 0;
            
            app.SubjectSelected = [];
           
            WaitBar = uiprogressdlg(app.UIFigure,'Title','Loading IP','Message','Loading IP','Indeterminate','on');
            app.IP = {};
            if(exist(fullfile(fileparts(which('Main.mlapp')),'/NetRun', 'IP.txt'), 'file'))
                Temp = importdata(fullfile(fileparts(which('Main.mlapp')),'/NetRun', 'IP.txt'));
                if(isfield(Temp, 'data'))
                    app.IP = Temp.textdata;
                    app.Port = Temp.data;
                else
                    app.IP = Temp;
                    app.Port = 22*ones(length(Temp), 1);
                end
            end
            app.ListBox_UnProcessIP.Enable = 0;
            SetIPtable(app);
            close(WaitBar);
                
            app.FileFilter = '@raw*_tsss';
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if(app.CallingApp.GroupProcessIsNet==1&&isempty(app.ListBox_toDoIP.Items))
                msgbox('no IP Select!');
                return
            end
            app.CallingApp.GroupProcessStudy = cellfun(@(x)app.Study(x, :),app.Subject_Idx(app.SubjectSelected),'UniformOutput',false);
            Temp = cellfun(@(x)strfind(x,':'), app.ListBox_toDoIP.Items,'UniformOutput',false);
            app.CallingApp.GroupProcessDevice = cellfun(@(x, y)str2double(strrep(strrep(x(1:y(1)-1),'GPU',''),'CPU','-1')), app.ListBox_toDoIP.Items, Temp,'UniformOutput',false);
            app.CallingApp.GroupProcessIP = cellfun(@(x, y)x(y(1)+2:y(2)-1), app.ListBox_toDoIP.Items, Temp,'UniformOutput',false);
            app.CallingApp.GroupProcessPort = cellfun(@(x, y)str2double(x(y(2)+1:end)), app.ListBox_toDoIP.Items, Temp,'UniformOutput',false);
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.PreprocessOpt = app.PreprocessOpt;
            app.CallingApp.IEDdetectionOpt = app.IEDdetectionOpt;
            app.CallingApp.HFOdetectionOpt = app.HFOdetectionOpt;
            app.CallingApp.VirtualSensorOpt = app.VirtualSensorOpt;
            Output = app.CallingApp;
            delete(app);
            if(~isempty(Output.GroupProcessStudy))
                GroupProcess(Output);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Interupt = 1;
            app.CallingApp.GridLayout.Visible = 1;
            delete(app);
        end

        % Value changed function: StudyFileFilterEditField
        function StudyFileFilterEditFieldValueChanged(app, event)
            app.FileFilter = app.StudyFileFilterEditField.Value;
            GetStudy(app);
            SetMode(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            Selected = app.ProcessListBox.Value;
            Selected = cellfun(@(x)strrep(x,'P: ',''),Selected,'UniformOutput',false);Selected = cellfun(@(x)strrep(x,'U: ',''),Selected,'UniformOutput',false);
            app.SubjectSelected = cat(1,find(ismember(app.Subject, Selected)),app.SubjectSelected);
            app.toDoListBox.Items = sort(cat(2,app.toDoListBox.Items,app.ProcessListBox.Value));
            app.ProcessListBox.Items(ismember(app.ProcessListBox.Items,app.ProcessListBox.Value)) = [];
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            Selected = app.toDoListBox.Value;
            Selected = cellfun(@(x)strrep(x,'P: ',''),Selected,'UniformOutput',false);Selected = cellfun(@(x)strrep(x,'U: ',''),Selected,'UniformOutput',false);
            app.SubjectSelected(ismember(app.SubjectSelected,find(ismember(app.Subject, Selected)))) = [];
            app.ProcessListBox.Items = sort(cat(2,app.ProcessListBox.Items,app.toDoListBox.Value));
            app.toDoListBox.Items(ismember(app.toDoListBox.Items,app.toDoListBox.Value)) = [];      
        end

        % Button pushed function: SetParametersofProcessButton
        function SetParametersofProcessButtonPushed(app, event)
            app.GridLayout.Visible = 0;
            app.DialogProcessAll = DialogProcess(app);  
        end

        % Button pushed function: AddButton_IP
        function AddButton_IPPushed(app, event)
            value = app.IPTextArea.Value;
            WaitBar = uiprogressdlg(app.UIFigure,'Title','Adding IP','Message','Adding IP......','Indeterminate','on');
            Temp = strfind(value,':');
            if(isempty(Temp))
                value = [value,':','22'];
                ipTemp = value;
                portTemp = 22;
            else
                ipTemp = value(1:Temp-1);
                portTemp = str2double(value(Temp+1:end));
            end
            if(any(cellfun(@(x)strcmp(x, value),app.readyIP))||any(cellfun(@(x)strcmp(x, value),app.toDoIP))||any(cellfun(@(x)strcmp(x, value),app.NotreadyIP)))
                msgbox('IP exsit!');
            else
                [status, Out] = Test(app, ipTemp, portTemp);
                if(status)
                    app.readyIP = cat(1, app.readyIP, repmat({ipTemp}, size(Out.DeviceID,1), 1));
                    app.readyPort = cat(1, app.readyPort, repmat(portTemp, size(Out.DeviceID,1), 1));
                    app.readyDeviceID = cat(1, app.readyDeviceID, Out.DeviceID);
                    app.readyDeviceName = cat(1, app.readyDeviceName, Out.Device);
                else
                    app.NotreadyIP = [app.NotreadyIP, portTemp];
                    app.NotreadyPort = [app.NotreadyPort, portTemp];
                end
            end
            temp = cellfun(@(z, x, y)[z, ': ', x, ':', num2str(y)],app.readyDeviceName, app.readyIP, num2cell(app.readyPort), 'UniformOutput', false);
            app.ListBox_ProcessIP.Items = sort(temp);
            temp = cellfun(@(x, y)[x, ':', num2str(y)],app.NotreadyIP, num2cell(app.NotreadyPort), 'UniformOutput', false);
            app.ListBox_UnProcessIP.Items = sort(temp);
            close(WaitBar);
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.readyIP = {};
            app.NotreadyIP = {};
            app.toDoIP = {};
            app.readyPort = [];
            app.NotreadyPort = [];
            app.toDoPort = [];
            app.readyDeviceID = [];
            app.toDoDeviceID = [];
            app.readyDeviceName = {};
            app.toDoDeviceName = {};
            for i=1:length(app.IP)
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Loading IP','Message',['IP: ',num2str(i),'/',num2str(length(app.IP))],'value',i/length(app.IP));
                [status, Out] = Test(app, app.IP{i}, app.Port(i));
                if(status)
                    app.readyIP = cat(1, app.readyIP, repmat(app.IP(i), size(Out.DeviceID,1), 1));
                    app.readyPort = cat(1, app.readyPort, repmat(app.Port(i), size(Out.DeviceID,1), 1));
                    app.readyDeviceID = cat(1, app.readyDeviceID, Out.DeviceID);
                    app.readyDeviceName = cat(1, app.readyDeviceName, Out.Device);
                else
                    app.NotreadyIP = [app.NotreadyIP, app.IP(i)];
                    app.NotreadyPort = [app.NotreadyPort, app.Port(i)];
                end
                close(WaitBar);
            end
            temp = cellfun(@(z, x, y)[z, ': ', x, ':', num2str(y)],app.readyDeviceName, app.readyIP, num2cell(app.readyPort), 'UniformOutput', false);
            app.ListBox_ProcessIP.Items = sort(temp);
            temp = cellfun(@(x, y)[x, ':', num2str(y)],app.NotreadyIP, num2cell(app.NotreadyPort), 'UniformOutput', false);
            app.ListBox_UnProcessIP.Items = sort(temp);
            temp = cellfun(@(z, x, y)[z, ': ', x, ':', num2str(y)],app.toDoDeviceName, app.toDoIP,num2cell(app.toDoPort), 'UniformOutput', false);
            app.ListBox_toDoIP.Items = sort(temp);
        end

        % Button pushed function: Button_Add
        function Button_AddPushed(app, event)
            Selected = app.ListBox_ProcessIP.Value;
            SelectIdx = cellfun(@(x)find(contains(app.ListBox_ProcessIP.Items,x)),Selected);
            app.ListBox_ProcessIP.Items(SelectIdx) = [];
            app.ListBox_toDoIP.Items = sort([app.ListBox_toDoIP.Items, Selected]);
        end

        % Button pushed function: Button_Remove
        function Button_RemovePushed(app, event)
            Selected = app.ListBox_toDoIP.Value;
            SelectIdx = cellfun(@(x)find(contains(app.ListBox_toDoIP.Items,x)),Selected);
            app.ListBox_toDoIP.Items(SelectIdx) = [];
            app.ListBox_ProcessIP.Items = sort([app.ListBox_ProcessIP.Items, Selected]);
        end

        % Value changed function: UsingNetworkButton
        function UsingNetworkButtonValueChanged(app, event)
            value = app.UsingNetworkButton.Value;
            if(value)
                app.CallingApp.GroupProcessIsNet = 1;
                app.NetworkPanel.Visible = 1;
                app.UsingNetworkButton.BackgroundColor = [0.64,0.99,0.51];
            else
                app.UsingNetworkButton.BackgroundColor = [0.96,0.96,0.96];
                app.CallingApp.GroupProcessIsNet = 0;
                app.NetworkPanel.Visible = 0;                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.StudyPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.StudyPanel);
            app.AddButton = uibutton(app.GridLayout2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '-->';
            app.RemoveButton = uibutton(app.GridLayout2, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '<--';
            app.toDoListBox = uilistbox(app.GridLayout2);
            app.toDoListBox.Items = {'None'};
            app.toDoListBox.Multiselect = 'on';
            app.toDoListBox.Value = {'None'};
            app.ProcessListBox = uilistbox(app.GridLayout2);
            app.ProcessListBox.Items = {'None'};
            app.ProcessListBox.Multiselect = 'on';
            app.ProcessListBox.Tooltip = {''};
            app.ProcessListBox.Value = {'None'};
            app.StudyFileFilterEditFieldLabel = uilabel(app.GridLayout2);
            app.StudyFileFilterEditFieldLabel.Text = 'Study File Filter';
            app.StudyFileFilterEditField = uieditfield(app.GridLayout2, 'text');
            app.StudyFileFilterEditField.ValueChangedFcn = createCallbackFcn(app, @StudyFileFilterEditFieldValueChanged, true);
            app.StudyFileFilterEditField.Value = '@raw*_tsss';
            app.DisProcessListBox = uilistbox(app.GridLayout2);
            app.DisProcessListBox.Items = {'None'};
            app.DisProcessListBox.Multiselect = 'on';
            app.DisProcessListBox.Value = {'None'};
            app.ProceseePanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.ProceseePanel);
            app.SetParametersofProcessButton = uibutton(app.GridLayout3, 'push');
            app.SetParametersofProcessButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersofProcessButtonPushed, true);
            app.SetParametersofProcessButton.Text = 'Set Parameters of Process';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.NetworkPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.NetworkPanel);
            app.ListBox_ProcessIP = uilistbox(app.GridLayout4);
            app.ListBox_ProcessIP.Items = {'None'};
            app.ListBox_ProcessIP.Multiselect = 'on';
            app.ListBox_ProcessIP.Value = {'None'};
            app.ListBox_toDoIP = uilistbox(app.GridLayout4);
            app.ListBox_toDoIP.Items = {'None'};
            app.ListBox_toDoIP.Multiselect = 'on';
            app.ListBox_toDoIP.Value = {'None'};
            app.ListBox_UnProcessIP = uilistbox(app.GridLayout4);
            app.ListBox_UnProcessIP.Items = {'None'};
            app.ListBox_UnProcessIP.Multiselect = 'on';
            app.ListBox_UnProcessIP.Value = {'None'};
            app.Button_Add = uibutton(app.GridLayout4, 'push');
            app.Button_Add.ButtonPushedFcn = createCallbackFcn(app, @Button_AddPushed, true);
            app.Button_Add.Text = 'Add';
            app.Button_Remove = uibutton(app.GridLayout4, 'push');
            app.Button_Remove.ButtonPushedFcn = createCallbackFcn(app, @Button_RemovePushed, true);
            app.Button_Remove.Text = 'Del';
            app.AddButton_IP = uibutton(app.GridLayout4, 'push');
            app.AddButton_IP.ButtonPushedFcn = createCallbackFcn(app, @AddButton_IPPushed, true);
            app.AddButton_IP.Text = '+';
            app.IPEditFieldLabel = uilabel(app.GridLayout4);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPTextArea = uieditfield(app.GridLayout4, 'text');
            app.TestButton = uibutton(app.GridLayout4, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.isNetworkPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.isNetworkPanel);
            app.UsingNetworkButton = uibutton(app.GridLayout5, 'state');
            app.UsingNetworkButton.ValueChangedFcn = createCallbackFcn(app, @UsingNetworkButtonValueChanged, true);
            app.UsingNetworkButton.Text = 'Using Network';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogGroup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end