classdef DialogLoadProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        OKButton                       matlab.ui.control.Button
        ProjectPanel                   matlab.ui.container.Panel
        GridLayout_Project             matlab.ui.container.GridLayout
        StudyFileFilterEditField       matlab.ui.control.EditField
        StudyFileFilterEditFieldLabel  matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        SujectPanel                    matlab.ui.container.Panel
        GridLayout_Subject             matlab.ui.container.GridLayout
        SubjectsLabel_Subject          matlab.ui.control.Label
        ListBox_Subject                matlab.ui.control.ListBox
        AddButton_Subject              matlab.ui.control.Button
        DeleteButton_Subject           matlab.ui.control.Button
        StudyPanel                     matlab.ui.container.Panel
        GridLayout_Study               matlab.ui.container.GridLayout
        AddButton_Study                matlab.ui.control.Button
        DeleteButton_Study             matlab.ui.control.Button
        ListBox_Study                  matlab.ui.control.ListBox
        MEGFilesLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
        FileFilter;
        Study;
        Idx;
        StudyState;    %[study,subject,protocol]
        StudySelected;
        
        DialogProcessAll;
        
        ProjectDir
        State
        SubjectName
        SubjectNameToShow
        AnatomyType
        StudyName
        StudyNameToShow
        StudyTSSS
        BrainstormDbDir % Description
    end
    
    properties (Access = public)
        Mode; % Description
        SpikedetectionOpt;
        VirtualSensorOpt;
        HFOdetectionOpt;
        PreprocessOpt; % Description
        Interupt;
    end
    
    methods (Access = private)
        function GetProtocol(app)
            Protocols = load(bst_get('BrainstormDbFile'));
            ProtocolFiles = cellfun(@(x)load(fullfile(Protocols.ProtocolsListInfo(x).STUDIES,'protocol.mat')),num2cell(1:length(Protocols.ProtocolsListInfo)));
            app.SubjectName = cell(length(ProtocolFiles), 1);
            app.SubjectNameToShow = cell(length(ProtocolFiles), 1);
            app.AnatomyType = zeros(length(ProtocolFiles), 1);
            app.StudyName = cell(length(ProtocolFiles), 1);
            app.StudyNameToShow = cell(length(ProtocolFiles), 1);
            app.StudyTSSS = cell(length(ProtocolFiles), 1);
            for i=1:length(ProtocolFiles)
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Loading data','Message',['Loading data: ',num2str(i),'/',num2str(length(ProtocolFiles))],'value',i/length(ProtocolFiles));
                % Get Subject Name and Anatomy Type
                app.SubjectName{i} = ProtocolFiles(i).ProtocolSubjects.Subject.Name;
                if(any(contains({ProtocolFiles(1).ProtocolSubjects.Subject.Surface.Comment}, 'white')|...
                       contains({ProtocolFiles(1).ProtocolSubjects.Subject.Surface.Comment}, 'mid')|...
                       contains({ProtocolFiles(1).ProtocolSubjects.Subject.Surface.Comment}, 'cortex')))
                   app.AnatomyType(i) = 1;
                elseif(~isempty(ProtocolFiles(1).ProtocolSubjects.Subject.Anatomy))
                   app.AnatomyType(i) = 0;
                end
                if app.AnatomyType(i) == 1
                    app.SubjectNameToShow{i} = cat(2, 'FS:  ', app.SubjectName{i});
                else
                    app.SubjectNameToShow{i} = cat(2, 'MRI: ', app.SubjectName{i});
                end
                % Get Study Name (4k MEG)
                MEG_PATH = fullfile(app.BrainstormDbDir, ProtocolFiles(i).ProtocolInfo.Comment, 'RAW');
                MEG_PATH = fullfile('/home/cuiwei/sanbo_dataset/4k_Project/Data_Full/MEG_ICA', ProtocolFiles(i).ProtocolInfo.Comment);
                StudyRaw = dir(MEG_PATH);StudyRaw = StudyRaw(contains({StudyRaw.name},'.fif')&~contains({StudyRaw.name},'-1.fif'));
                if(~isempty(StudyRaw))
                    StudyRawTemp = {StudyRaw.name};StudyRawTemp = sort(cellfun(@(x)strrep(x, '.fif', ''),StudyRawTemp, 'UniformOutput', false));
                    % Get Study Name (In Protocol)
                    StudyProtocolTemp = {ProtocolFiles(i).ProtocolStudies.Study.Name};
                    StudyProtocolTemp = StudyProtocolTemp(contains(StudyProtocolTemp, '@raw')&contains(StudyProtocolTemp, '_tsss'));
                    StudyProtocolTemp = sort(cellfun(@(x)strrep(strrep(strrep(x, '@raw', ''), '_tsss', ''), '.fif', ''),StudyProtocolTemp, 'UniformOutput', false));
                    % Get Study Name (Only in RAW data)
                    Temp = find(cellfun(@(x)~contains(x, StudyProtocolTemp),StudyRawTemp));
                    app.StudyName{i} = StudyRawTemp;
                    SubjName = StudyRawTemp{1}(StudyRawTemp{1}(1:min(length(StudyRawTemp{1}), length(StudyRawTemp{2}))) == StudyRawTemp{2}(1:min(length(StudyRawTemp{1}), length(StudyRawTemp{2}))));
                    app.StudyTSSS{i} = ones(length(StudyRawTemp), 1);app.StudyTSSS{i}(find(Temp)) = 0;
                    app.StudyNameToShow{i}(find(app.StudyTSSS{i}==1)) = cellfun(@(x)cat(2, 'TSSS:  ', x), app.StudyName{i}(find(app.StudyTSSS{i}==1)), 'UniformOutput', false);
                    app.StudyNameToShow{i}(find(app.StudyTSSS{i}==0)) = cellfun(@(x)cat(2, 'Raw:   ', x), app.StudyName{i}(find(app.StudyTSSS{i}==0)), 'UniformOutput', false);
                else
                    app.StudyName{i} = [];app.StudyTSSS{i} = [];app.StudyNameToShow{i} = {'None'};
                end
                close(WaitBar);
            end
            app.ListBox_Subject.Items = app.SubjectNameToShow;app.ListBox_Subject.Value = app.SubjectNameToShow{1};
            app.ListBox_Study.Items = app.StudyNameToShow{1};app.ListBox_Study.Value = app.StudyNameToShow{1}{1};
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.UIFigure.Name = 'Load Project';
            app.CallingApp = mainapp;  
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.CallingApp.State = app.State;
            app.CallingApp.ProjectDir = app.ProjectDir;
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.Interupt = 1;
            app.CallingApp.Process_isOK = 1;
            app.CallingApp.BrainstormDbDir = app.BrainstormDbDir;
            Output = app.CallingApp;
            delete(app);
            if(~isempty(Output.ProjectDir))
                ProcessLoading(Output);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Interupt = 1;
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.Process_isOK = 0;
            delete(app);
        end

        % Value changed function: ListBox_Subject
        function ListBox_SubjectValueChanged(app, event)
            value = find(contains(app.ListBox_Subject.Items, app.ListBox_Subject.Value));
            app.ListBox_Study.Items = app.StudyNameToShow{value};app.ListBox_Study.Value = app.StudyNameToShow{value}{1};
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            commandwindow();
            app.ProjectDir = uigetdir('','Select Project Directory');
            figure(app.UIFigure)
            if(ischar(app.ProjectDir)&&exist(app.ProjectDir, 'dir'))
                app.State = 0;
                app.StudyFileFilterEditField.Value = app.ProjectDir;
                % Get Protocol Dir
                app.BrainstormDbDir = fullfile(app.ProjectDir, 'bstProtocol');
                if ~exist(app.BrainstormDbDir, 'dir')
                    warndlg('\bf \fontname{Time} \fontsize{20} Brainstorm Project do not exist', 'Warning', struct('WindowStyle','modal', 'Interpreter','tex'));
                    mkdir(app.BrainstormDbDir);
                end
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Loading','Message','Loading BST Protocol.....','Indeterminate','on');
                global GlobalData;
                try
                    % Select new database folder
                    bst_set('BrainstormDbDir', app.BrainstormDbDir);
                    % Get the current protocol: if not empty, there are existing protocols to unload
                    iiProtocol = bst_get('iProtocol');
                    if ~isempty(iiProtocol) && (iiProtocol >= 1)
                        % Reset all the structures
                        GlobalData.DataBase.ProtocolInfo(:)     = [];
                        GlobalData.DataBase.ProtocolSubjects(:) = [];
                        GlobalData.DataBase.ProtocolStudies(:)  = [];
                        GlobalData.DataBase.isProtocolLoaded    = [];
                        GlobalData.DataBase.isProtocolModified  = [];
                        % Select current protocol in combo list
                        gui_brainstorm('SetCurrentProtocol', 0);
                        % Update interface
                        gui_brainstorm('UpdateProtocolsList');
                        panel_protocols('UpdateTree');
                    end
                    % Import new database
                    db_import(app.BrainstormDbDir);
                catch
                    bst_set('BrainstormDbDir', app.BrainstormDbDir);
                    % Get the current protocol: if not empty, there are existing protocols to unload
                    iiProtocol = bst_get('iProtocol');
                    if ~isempty(iiProtocol) && (iiProtocol >= 1)
                        % Reset all the structures
                        GlobalData.DataBase.ProtocolInfo(:)     = [];
                        GlobalData.DataBase.ProtocolSubjects(:) = [];
                        GlobalData.DataBase.ProtocolStudies(:)  = [];
                        GlobalData.DataBase.isProtocolLoaded    = [];
                        GlobalData.DataBase.isProtocolModified  = [];
                        % Select current protocol in combo list
                        gui_brainstorm('SetCurrentProtocol', 0);
                        % Update interface
                        gui_brainstorm('UpdateProtocolsList');
                        panel_protocols('UpdateTree');
                    end
                    % Import new database
                    db_import(app.BrainstormDbDir);
                end
                db_save(1);
                close(WaitBar);
                GetProtocol(app);
            else
                app.State = -1;
            end
        end

        % Button pushed function: AddButton_Subject
        function AddButton_SubjectPushed(app, event)
            
        end

        % Button pushed function: DeleteButton_Subject
        function DeleteButton_SubjectPushed(app, event)
            
        end

        % Button pushed function: AddButton_Study
        function AddButton_StudyPushed(app, event)
            
        end

        % Button pushed function: DeleteButton_Study
        function DeleteButton_StudyPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ProjectPanel = uipanel(app.GridLayout);
            app.GridLayout_Project = uigridlayout(app.ProjectPanel);
            app.StudyFileFilterEditField = uieditfield(app.GridLayout_Project, 'text');
            app.StudyFileFilterEditFieldLabel = uilabel(app.GridLayout_Project);
            app.StudyFileFilterEditFieldLabel.Text = 'Project Path:';
            app.LoadButton = uibutton(app.GridLayout_Project, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SujectPanel = uipanel(app.GridLayout_Project);
            app.GridLayout_Subject = uigridlayout(app.SujectPanel);
            app.SubjectsLabel_Subject = uilabel(app.GridLayout_Subject);
            app.SubjectsLabel_Subject.Text = 'Subjects';
            app.ListBox_Subject = uilistbox(app.GridLayout_Subject);
            app.ListBox_Subject.Items = {};
            app.ListBox_Subject.Multiselect = 'on';
            app.ListBox_Subject.ValueChangedFcn = createCallbackFcn(app, @ListBox_SubjectValueChanged, true);
            app.ListBox_Subject.Value = {};
            app.AddButton_Subject = uibutton(app.GridLayout_Subject, 'push');
            app.AddButton_Subject.ButtonPushedFcn = createCallbackFcn(app, @AddButton_SubjectPushed, true);
            app.AddButton_Subject.Text = 'Add';
            app.DeleteButton_Subject = uibutton(app.GridLayout_Subject, 'push');
            app.DeleteButton_Subject.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_SubjectPushed, true);
            app.DeleteButton_Subject.Text = 'Delete';
            app.StudyPanel = uipanel(app.GridLayout_Project);
            app.GridLayout_Study = uigridlayout(app.StudyPanel);
            app.AddButton_Study = uibutton(app.GridLayout_Study, 'push');
            app.AddButton_Study.ButtonPushedFcn = createCallbackFcn(app, @AddButton_StudyPushed, true);
            app.AddButton_Study.Text = 'Add';
            app.DeleteButton_Study = uibutton(app.GridLayout_Study, 'push');
            app.DeleteButton_Study.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_StudyPushed, true);
            app.DeleteButton_Study.Text = 'Delete';
            app.ListBox_Study = uilistbox(app.GridLayout_Study);
            app.ListBox_Study.Items = {};
            app.ListBox_Study.Value = {};
            app.MEGFilesLabel = uilabel(app.GridLayout_Study);
            app.MEGFilesLabel.Text = 'MEG Files';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogLoadProject(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end