classdef DialogSelectStudy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        OkButton                  matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        ListBox                   matlab.ui.control.ListBox
        FileFilterEditFieldLabel  matlab.ui.control.Label
        FileFilterEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        CallingApp   % Main app object
        StudySelect;
        FileFilter;
        SpikeHFO_State;
    end
    
    methods (Access = private)
        
        function StudyStateName = GetStudyState(app)
            WaitBar = uiprogressdlg(app.UIFigure,'Title','Geting Study State','Message','Geting State......','Indeterminate','on');
            app.FileFilter = app.FileFilterEditField.Value;
            Protocols = load(bst_get('BrainstormDbFile'));
            ProtocolName = Protocols.ProtocolsListInfo(app.CallingApp.iProtocol).Comment;
            ProtocolFile = load(fullfile(Protocols.ProtocolsListInfo(app.CallingApp.iProtocol).STUDIES,'protocol.mat'));
            Studies = ProtocolFile.ProtocolStudies.Study;
            Filter = cat(2,'^',strrep(app.FileFilter,'*','.*'),'$');
            StudyIdx = find(cellfun(@(x)~isempty(x),regexp(cat(1,Studies.Condition),Filter)));
            StudyStateName = cell(length(StudyIdx),1);
            app.SpikeHFO_State = ones(length(StudyIdx),1);
            for i=1:size(StudyIdx,1)
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Geting Study State','Message',['Geting State: ',num2str(i),'/',num2str(size(StudyIdx,1))],'value',i/size(StudyIdx,1));
                SubjName = ProtocolFile.ProtocolSubjects.Subject(app.CallingApp.iSubject).Name;
                StudyName = ProtocolFile.ProtocolStudies.Study(StudyIdx(i)).Condition{:};
                % Load SaveHFOdetectionResults
                StudyState = [0,0,0,0];isHFO=1;isSpike=1;
                if(exist(fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SaveHFOViewResults.mat'),'file'))
                    if(isempty(whos('HFOIdx','-file',fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SaveHFOViewResults.mat'))))
                        StudyState = [1,1,1,0]; 
                        isHFO=0;
                    else
                        app.SpikeHFO_State(i) = 3;
                        StudyState(4) = 1;
                    end
                else
                    StudyState = [1,1,1,0];
                end 
                % Load SaveVirtualSensorResults
                if(~exist(fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SaveVirtualSensorResults.mat'),'file')||...
                   isempty(whos('ReconParam','-file',fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SaveVirtualSensorResults.mat'))))
                    StudyState = [1,1,0,0]; 
                else
                    StudyState(3) = 1;
                end
                % Load SaveSpikeViewResults
                if(~exist(fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SaveSpikeViewResults.mat'),'file'))
%                     StudyState = [1,0,0,0];
                    app.SpikeHFO_State(i) = 3;
                    StudyState(2) = 0;
                else
                    StudyState(2) = 1;
                end
                % Load SaveVirtualSensorResults
                if(~exist(fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SavePreProcessResults.mat'),'file')||...
                   isempty(whos('bad_seg','-file',fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SavePreProcessResults.mat'))))
%                     StudyState = [0,0,0,0]; 
                    app.SpikeHFO_State(i) = 3;
                    StudyState(1) = 0;
                else
                    StudyState(1) = 1;
                end             
                StudyStateName{i} = ['[',char('PSVH'.*StudyState+'____'.*~StudyState),']: ',Studies(StudyIdx(i)).Condition{:}];
                try
                    SpikeEvent = load(fullfile(app.CallingApp.ProjectDir,ProtocolName,SubjName,StudyName,'SaveSpikeDetectionResults.mat'), 'SpikeEvent');SpikeEvent=SpikeEvent.SpikeEvent;
                    if(isempty(SpikeEvent))
                        isSpike=0;
                        app.SpikeHFO_State(i) = 1;
                    else
                        app.SpikeHFO_State(i) = max(app.SpikeHFO_State(i), 2);
                    end
                catch
                    StudyState = [1,0,0,0];
                end
                if(isSpike==0)
                    StudyStateName{i} = ['No Spike: ',Studies(StudyIdx(i)).Condition{:}];
                end
                if(isHFO==0)
                    StudyStateName{i} = ['No HFO: ',Studies(StudyIdx(i)).Condition{:}];
                end
            end
            close(WaitBar);
        end 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;  
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            
            app.FileFilter = app.FileFilterEditField.Value;
            StudyStateName = GetStudyState(app);
            app.ListBox.Items = cat(2,{'None'},StudyStateName');
            if(isempty(app.CallingApp.SelectStudy))
                app.ListBox.Value = 'None';
                app.StudySelect = app.ListBox.Value;
            else
                app.ListBox.Value = app.CallingApp.SelectStudy(cellfun(@(x)any(cellfun(@(y)strcmp(x,y),app.ListBox.Items)),app.CallingApp.SelectStudy));
                app.StudySelect = app.ListBox.Value;
            end
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            Idx = cellfun(@(x)ismember(x,app.StudySelect),app.ListBox.Items);
            Items = app.ListBox.Items;
            Items(~Idx) = {[]};
            Items(cellfun(@(x)strcmp(x,'None'),Items)) = {[]};
            Items = Items(cellfun(@(x)~isempty(x),Items));
            Items = cellfun(@(x)x(strfind(x, ': ')+2:end),Items,'UniformOutput',false);
            app.CallingApp.SelectStudy = Items;
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.SpikeHFO_State = max(app.SpikeHFO_State(find(Idx(2:end))));
            Output = app.CallingApp; 
            % Delete the dialog box 
            delete(app);
            applySelectStudy(Output);           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(length(app.CallingApp.SelectStudy)==1&&strcmp(app.CallingApp.SelectStudy{1},'None'))
                app.CallingApp.SelectStudy = [];
            end
            app.CallingApp.GridLayout.Visible = 1;
            Output = app.CallingApp; 
            % Delete the dialog box 
            delete(app);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.StudySelect = app.ListBox.Value;
        end

        % Value changed function: FileFilterEditField
        function FileFilterEditFieldValueChanged(app, event)
            app.FileFilter = app.FileFilterEditField.Value;
            Protocols = load(bst_get('BrainstormDbFile'));
            ProtocolFile = load(fullfile(Protocols.ProtocolsListInfo(app.CallingApp.iProtocol).STUDIES,'protocol.mat'));
            Studies = ProtocolFile.ProtocolStudies.Study;
            Filter = cat(2,'^',strrep(app.FileFilter,'*','.*'),'$');
            StudyIdx = cellfun(@(x)~isempty(x),regexp(cat(1,Studies.Condition),Filter));
            app.ListBox.Items = cat(2,{'None'},Studies(StudyIdx).Condition);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OkButton = uibutton(app.GridLayout, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {'None'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'None'};
            app.FileFilterEditFieldLabel = uilabel(app.GridLayout2);
            app.FileFilterEditFieldLabel.Text = 'File Filter';
            app.FileFilterEditField = uieditfield(app.GridLayout2, 'text');
            app.FileFilterEditField.ValueChangedFcn = createCallbackFcn(app, @FileFilterEditFieldValueChanged, true);
            app.FileFilterEditField.Value = '@raw*_tsss';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogSelectStudy(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end