classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        PanelTop                        matlab.ui.container.Panel
        GridLayoutTop                   matlab.ui.container.GridLayout
        ViewHFOButton                   matlab.ui.control.Button
        LoadProject                     matlab.ui.control.Button
        Arrow                           matlab.ui.control.Label
        SubjectDropDownLabel            matlab.ui.control.Label
        SubjectDown                     matlab.ui.control.DropDown
        SaveProject                     matlab.ui.control.Button
        ShowAllSoucesButton             matlab.ui.control.Button
        Process                         matlab.ui.control.Button
        MEGFilesButton                  matlab.ui.control.Button
        ShowSoucesButton                matlab.ui.control.Button
        ViewSpikeButton                 matlab.ui.control.Button
        ProcessStatus                   matlab.ui.control.Button
        PanelRight                      matlab.ui.container.Panel
        GridLayoutRight                 matlab.ui.container.GridLayout
        ListBox                         matlab.ui.control.ListBox
        CheckBox_HighPass               matlab.ui.control.CheckBox
        HighPassEditFieldLabel          matlab.ui.control.Label
        HighPassEditField               matlab.ui.control.NumericEditField
        LowPassEditFieldLabel           matlab.ui.control.Label
        LowPassEditField                matlab.ui.control.NumericEditField
        RejectCheckBox                  matlab.ui.control.CheckBox
        CheckBox_LowPass                matlab.ui.control.CheckBox
        Spike_AccpetButton              matlab.ui.control.Button
        HFO_AccpetButton                matlab.ui.control.Button
        Spike_RejectButton              matlab.ui.control.Button
        HFO_RejectButton                matlab.ui.control.Button
        UnDoCheckBox                    matlab.ui.control.CheckBox
        OL_CheckBox                     matlab.ui.control.CheckBox
        AC_CheckBox                     matlab.ui.control.CheckBox
        InfoButton                      matlab.ui.control.StateButton
        PanelLeft                       matlab.ui.container.Panel
        GridLayoutLeft                  matlab.ui.container.GridLayout
        Button_UP                       matlab.ui.control.Button
        Button_Down                     matlab.ui.control.Button
        HFO_CheckBox_EntropySpike       matlab.ui.control.CheckBox
        HFO_EntropySpikeText            matlab.ui.control.TextArea
        HFO_CheckBox_LLSpike            matlab.ui.control.CheckBox
        HFO_LLSpikeEditField            matlab.ui.control.NumericEditField
        HFO_LLSpikeText                 matlab.ui.control.TextArea
        HFO_CheckBox_EntropyHFO         matlab.ui.control.CheckBox
        HFO_EntropyHFOEditField         matlab.ui.control.NumericEditField
        HFO_EntropyHFOText              matlab.ui.control.TextArea
        HFO_CheckBox_AmpHFO             matlab.ui.control.CheckBox
        HFO_AmpHFOEditField             matlab.ui.control.NumericEditField
        HFO_AmpHFOext                   matlab.ui.control.TextArea
        HFO_TimeFeaturesLabel           matlab.ui.control.Label
        HFO_EntropySpikeEditField       matlab.ui.control.NumericEditField
        HFO_ShowSpikeCheckBox           matlab.ui.control.CheckBox
        HFO_AmpSpikeText                matlab.ui.control.TextArea
        HFO_AmpSpikeEditField           matlab.ui.control.NumericEditField
        HFO_CheckBox_AmpSpike           matlab.ui.control.CheckBox
        HFO_TimeFeequencyFeaturesLabel  matlab.ui.control.Label
        HFO_CheckBox_LLHFO              matlab.ui.control.CheckBox
        HFO_LLHFOEditField              matlab.ui.control.NumericEditField
        HFO_LLHFOText                   matlab.ui.control.TextArea
        PanelView                       matlab.ui.container.Panel
        GridLayoutView                  matlab.ui.container.GridLayout
        VSButton                        matlab.ui.control.StateButton
        MEGButton                       matlab.ui.control.StateButton
        TFButton                        matlab.ui.control.StateButton
        TemplateButton                  matlab.ui.control.StateButton
        UIAxesVS                        matlab.ui.control.UIAxes
        UIAxesMEG                       matlab.ui.control.UIAxes
        UIAxesTF                        matlab.ui.control.UIAxes
        UIAxesTemplate                  matlab.ui.control.UIAxes
        UIAxesTopo                      matlab.ui.control.UIAxes
        View_Row                        matlab.ui.control.Label
        View_DropDown_Rows              matlab.ui.control.DropDown
        View_Col                        matlab.ui.control.Label
        View_DropDown_Columns           matlab.ui.control.DropDown
        Spike_SlopeEditField            matlab.ui.control.NumericEditField
        Spike_CheckBox_Slope            matlab.ui.control.CheckBox
        Spike_SlopeText                 matlab.ui.control.TextArea
        Spike_AmpEditField              matlab.ui.control.NumericEditField
        Spike_CheckBox_Amp              matlab.ui.control.CheckBox
        Spike_AmpText                   matlab.ui.control.TextArea
        Spike_SharpEditField            matlab.ui.control.NumericEditField
        Spike_CheckBox_Sharp            matlab.ui.control.CheckBox
        Spike_SharpText                 matlab.ui.control.TextArea
        Spike_TimeFeaturesLabel         matlab.ui.control.Label
        Spike_SimilarityFeaturesLabel   matlab.ui.control.Label
        Spike_CorrText                  matlab.ui.control.TextArea
        Spike_CheckBox_Corr             matlab.ui.control.CheckBox
        Spike_CorrEditField             matlab.ui.control.NumericEditField
        Spike_DistEditField             matlab.ui.control.NumericEditField
        Spike_CheckBox_Dist             matlab.ui.control.CheckBox
        Spike_DistText                  matlab.ui.control.TextArea
        Spike_MinChanEditField          matlab.ui.control.NumericEditField
        Spike_CheckBox_MinChan          matlab.ui.control.CheckBox
        Spike_MinChanText               matlab.ui.control.TextArea
        Spike_ShowTopo                  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)        
        Mode;
        iState;  % 0: not view; 1: spike view; 2: hfo view
        iStateView = 0; % 0: VS view; 1: MEG view; 2: TF view; 3: Template view
        
        % GUI Dialog
        Load_Project;
        ProcessStatusShow;
        GroupProcess;
        SelectStudyFile;
        
        % View Axes
        AxView; 
        AxViewNum;
        WExtrap;
        WExtrapMag;
        WExtrapGrad;
        Vertices;
        Faces; 
        SpikeToPlot 
        VS_Cluster_HFO
        VS_Cluster_Spike
        AXES 
        HFIG
        ClusterImage 
        UseImage = 1;
    end
    
    properties (Access = public)
        % File/path param
        ProjectDir;
        State;
        BrainstormDbDir;
        
        % GUI Dialog
        Interupt = 0;
        Process_isOK;

        % View Axes
        SelectStudy;
        SpikeHFO_State;
        AllSpikeView;
        AllHFOView;
        PlotShow;
        isShift=0;
        
        % Data Base
        db_subject_data;
        db_process;
        db_remote;
        db_subject_select;
        
        % remote process
        sshpass_cmd; % Description
    end
    
    methods (Access = private)
        
        % Save All Study
        function saveStudyAll(app)
            WaitBar = uiprogressdlg(app.UIFigure,'Title','Saving data','Message','Saving data','Indeterminate','on');
            if(~isempty(app.AllHFOView))
                Idx = unique(app.AllHFOView.Idx);
                for i=1:length(Idx)
                    WaitBar = uiprogressdlg(app.UIFigure,'Title','Saving data','Message',['Saving HFO data: ',num2str(i),'/',num2str(length(Idx))],'value',i/length(Idx));
                    fileName = app.AllHFOView.FileName(app.AllHFOView.Idx==Idx(i));matObj = matfile(fileName{1},'Writable',true);
                    Event = matObj.Event;
                    Event.EventName = app.AllHFOView.Event.EventName(app.AllHFOView.Idx==Idx(i));
                    toShow = matObj.toShow;
                    toShow.Best_Cls = app.AllHFOView.toShow.Best_Cls;
                    toShow.ClusterSelect = app.AllHFOView.toShow.ClusterSelect;
                    toShow.Bic = app.AllHFOView.toShow.Bic;
                    toShow.Cls = app.AllHFOView.toShow.Cls(app.AllHFOView.Idx==Idx(i));
                    toShow.Cls = app.AllHFOView.toShow.Cls(app.AllHFOView.Idx==Idx(i));
                    toShow.ClsIdx = app.AllHFOView.toShow.ClsIdx(app.AllHFOView.Idx==Idx(i));
                    matObj.Event = Event;matObj.toShow = toShow;
                end
                close(WaitBar);
            end
            if(~isempty(app.AllSpikeView))
                Idx = unique(app.AllSpikeView.Idx);
                for i=1:length(Idx)
                    WaitBar = uiprogressdlg(app.UIFigure,'Title','Saving data','Message',['Saving Spike data: ',num2str(i),'/',num2str(length(Idx))],'value',i/length(Idx));
                    fileName = app.AllSpikeView.FileName(app.AllSpikeView.Idx==Idx(i),:);
                    matObj = matfile(fileName{1},'Writable',true);
                    Event = matObj.Event;
                    Event.EventName = app.AllSpikeView.Event.EventName(app.AllSpikeView.Idx==Idx(i));
                    matObj.Event = Event;
                end
                close(WaitBar);
            end
        end
        
        function LoadSpikeAll(app)
            Protocols = load(bst_get('BrainstormDbFile'));
            app.AllSpikeView.toShow = struct('ReSample',[],'BaseLine',[],'RegionChannel',[],'SpikeChannel',[],'EventPeaks',[],...
                                             'EventSharp',[],'EventSlope',[],'EventAmp',[],...
                                             'RegionChannelName',[],'EventTime',[],'EventTimeOffset',[],'sFile',[],'ChannelMat',[],...
                                             'Template',[],'TemplateSelect',[],'Dist',[],'Corr',[]);
            app.AllSpikeView.Event = struct('EventName',[],'EventValue',[],'EventToDisp',[]);
            app.AllSpikeView.HFOinSpike = [];
            app.AllSpikeView.HFOinSpikeSignal = [];
            app.AllSpikeView.Idx = [];
            app.AllSpikeView.FileName = [];
            app.AllSpikeView.EventName = [];
            app.AllSpikeView.SpikeIdxToShow = [];
            SelectIndex = find(app.SelectStudy);
            for i=1:length(SelectIndex)
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Load All Spike For View','Message',['Loading Study: ',num2str(i),'/',num2str(length(SelectIndex))],'value',i/length(SelectIndex));
                % Read sFile and ChannelMat.
                meg_file = fullfile(app.ProjectDir, app.db_subject_select.meg_file{SelectIndex(i)});
                ImportOptions = db_template('ImportOptions');ImportOptions.EventsMode = 'no';
                [sFile, ~] = in_fopen_fif(fullfile(app.ProjectDir, app.db_subject_select.meg_file{SelectIndex(i)}), ImportOptions);
                ChannelMat = app.db_subject_select.channel_mat(SelectIndex(i));
                if(~exist(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveSpikeViewResults.mat'),'file'))
                    continue;
                end
                SpikeViewTemp = load(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveSpikeViewResults.mat'));
                % For Spike view
                app.AllSpikeView.toShow.ReSample = SpikeViewTemp.toShow.ReSample;
                app.AllSpikeView.toShow.BaseLine = cat(1,app.AllSpikeView.toShow.BaseLine,SpikeViewTemp.toShow.BaseLine);
                app.AllSpikeView.toShow.RegionChannel = cat(1,app.AllSpikeView.toShow.RegionChannel,SpikeViewTemp.toShow.RegionChannel);
                app.AllSpikeView.toShow.SpikeChannel = cat(1,app.AllSpikeView.toShow.SpikeChannel,SpikeViewTemp.toShow.SpikeChannel);
                app.AllSpikeView.toShow.RegionChannelName = cat(1,app.AllSpikeView.toShow.RegionChannelName,SpikeViewTemp.toShow.RegionChannelName);
                app.AllSpikeView.toShow.EventTime = cat(2,app.AllSpikeView.toShow.EventTime,SpikeViewTemp.toShow.EventTime);
                app.AllSpikeView.toShow.EventTimeOffset = cat(1,app.AllSpikeView.toShow.EventTimeOffset,SpikeViewTemp.toShow.EventTimeOffset);
                app.AllSpikeView.SpikeIdxToShow = cat(1,app.AllSpikeView.SpikeIdxToShow,SpikeViewTemp.SpikeIdxToShow);
                % For Similarity
                app.AllSpikeView.toShow.Dist = cat(1,app.AllSpikeView.toShow.Dist,SpikeViewTemp.toShow.Dist);
                app.AllSpikeView.toShow.Corr = cat(1,app.AllSpikeView.toShow.Corr,SpikeViewTemp.toShow.Corr);
                app.AllSpikeView.toShow.TemplateSelect = ones(length(SpikeViewTemp.toShow.Template), 1);
                app.AllSpikeView.toShow.Template = SpikeViewTemp.toShow.Template;
                % For Spike Feature
                app.AllSpikeView.toShow.EventSharp = cat(1,app.AllSpikeView.toShow.EventSharp,SpikeViewTemp.toShow.EventSharp);
                app.AllSpikeView.toShow.EventSlope = cat(1,app.AllSpikeView.toShow.EventSlope,SpikeViewTemp.toShow.EventSlope);
                app.AllSpikeView.toShow.EventAmp = cat(1,app.AllSpikeView.toShow.EventAmp,SpikeViewTemp.toShow.EventAmp);
                % For Read MEG Data
                app.AllSpikeView.toShow.sFile = cat(1,app.AllSpikeView.toShow.sFile, repmat(sFile,length(SpikeViewTemp.toShow.BaseLine),1));
                app.AllSpikeView.toShow.ChannelMat = cat(1,app.AllSpikeView.toShow.ChannelMat, repmat(ChannelMat,length(SpikeViewTemp.toShow.BaseLine),1));
                % For Spike Event
                app.AllSpikeView.Event.EventName = cellfun(@(x)cat(2,'Event_',num2str(x)),num2cell(1:length(app.AllSpikeView.toShow.BaseLine)),'UniformOutput',false);
                app.AllSpikeView.Event.EventValue = app.AllSpikeView.Event.EventName{1};
                app.AllSpikeView.Event.EventToDisp = cat(1,app.AllSpikeView.Event.EventToDisp,SpikeViewTemp.Event.EventToDisp);
                % For save SaveSpikeViewResults
                app.AllSpikeView.FileName = cat(1,app.AllSpikeView.FileName,...
                                                  repmat({strrep(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveSpikeViewResults.mat'),'/System/Volumes/Data','')},length(SpikeViewTemp.toShow.BaseLine),1));
                app.AllSpikeView.EventName = cat(2,app.AllSpikeView.EventName,SpikeViewTemp.Event.EventName);
                app.AllSpikeView.Idx = cat(1,app.AllSpikeView.Idx,repmat(i,length(SpikeViewTemp.toShow.BaseLine),1));
                % For HFO in Spike
                if(~exist(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveHFOViewResults.mat'),'file'))
                    continue;
                end
                % Get HFO View Data
                HFOIdx = load(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveHFOViewResults.mat'), 'HFOIdx');HFOIdx=HFOIdx.HFOIdx;
                if(isempty(HFOIdx))
                    continue;
                end
                HFOViewEvent = load(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveHFOViewResults.mat'),'Event');HFOViewEvent=HFOViewEvent.Event;
                % Get HFO Time in Spike (not Reject HFO)
                Temp = cat(1,HFOIdx{~contains(HFOViewEvent.EventName,'Reject: ')&~contains(HFOViewEvent.EventName,'OL_Accept: ')});
                if(~isempty(Temp))
                    HFOinSpikeIdx = unique(Temp(:, 1));
                else
                    HFOinSpikeIdx = [];
                end
                HFOinSpike = cell(length(SpikeViewTemp.toShow.BaseLine),1);HFOinSpike(HFOinSpikeIdx) = cellfun(@(x)round([mean(Temp(find(Temp(:, 1)==x), 3)), mean(Temp(find(Temp(:, 1)==x), 4))]), num2cell(HFOinSpikeIdx), 'UniformOutput', false);
                app.AllSpikeView.HFOinSpike = cat(1,app.AllSpikeView.HFOinSpike,HFOinSpike);
                close(WaitBar);
            end
            app.AllSpikeView.HFOinSpikeSignal = cell(length(app.AllSpikeView.Event.EventName),1);
            if(~isempty(app.AllSpikeView.HFOinSpike))
                % Modify Spike Name (Keep previous results, AC_Spike + AC_HFO -> AC_Spike, AC_Spike + Reject_HFO -> OL_Spike, AC_Spike + OL_HFO -> OL_Spike)
                app.AllSpikeView.Event.EventName = cellfun(@(x,y)[x(1:strfind(x,'Event_')-1),y],app.AllSpikeView.EventName,app.AllSpikeView.Event.EventName,'UniformOutput',false);
                Idx = cellfun(@(x)~(contains(x,'Reject: ')||contains(x,'Accept: ')),app.AllSpikeView.Event.EventName)&cellfun(@(x)~isempty(x),app.AllSpikeView.HFOinSpike)';
                app.AllSpikeView.Event.EventName(Idx) = cellfun(@(x)['AC_Accept: ',x], app.AllSpikeView.Event.EventName(Idx), 'UniformOutput', false);
                % Loading Spike Signal
                Idx = find(contains(app.AllSpikeView.Event.EventName,'AC_Accept: '));
                app.AllSpikeView.HFOinSpikeSignal = cell(length(app.AllSpikeView.Event.EventName),1);
                ReSampleRatio = round(app.AllSpikeView.toShow.sFile(1).prop.sfreq / app.AllSpikeView.toShow.ReSample);
                for i=1:length(Idx)
                    WaitBar = uiprogressdlg(app.UIFigure,'Title','Load Spike Signal','Message',['Loading Signal: ',num2str(i),'/',num2str(length(Idx))],'value',i/length(Idx));
                    sFileTemp = app.AllSpikeView.toShow.sFile(Idx(i));
                    ChannelMatTemp = app.AllSpikeView.toShow.ChannelMat(Idx(i));
                    % Re-sample back to Raw MEG and ReSample data.
                    BStime = app.AllSpikeView.toShow.BaseLine{Idx(i)};BStime = BStime(1, :);
                    BStimeTemp = round([(BStime(1) - 1)*ReSampleRatio + 1, BStime(end)*ReSampleRatio] + sFileTemp.prop.times(1)*sFileTemp.prop.sfreq);
                    BStimeTemp = [BStimeTemp(1), min(BStimeTemp(2),round(sFileTemp.prop.times(2)*sFileTemp.prop.sfreq))];
                    Sig = in_fread(sFileTemp,ChannelMatTemp,1,BStimeTemp, find(contains({ChannelMatTemp.Channel.Type},'MEG')));
                    if(app.AllSpikeView.toShow.sFile(1).prop.sfreq ~= app.AllSpikeView.toShow.ReSample)
                        Sig = ft_preproc_resample(Sig, app.AllSpikeView.toShow.sFile(1).prop.sfreq, app.AllSpikeView.toShow.ReSample, 'fft');
                    end
                    % Find the Data not in the BStime
                    Sig = cat(2, Sig, zeros(size(Sig,1), length(BStime)-size(Sig,2)));
                    Sig = Sig(:, cellfun(@(x)ismember(x, BStime),num2cell(BStime(1):BStime(end))));
                    app.SpikeToPlot{Idx(i)} = process_bandpass('Compute', Sig, app.AllSpikeView.toShow.ReSample, 3, 80, 'bst-hfilter-2019', 1);
                    % Segment MEG to 8 brain Region
                    app.AllSpikeView.HFOinSpikeSignal{Idx(i)} = cellfun(@(x)Sig(x, :), app.AllSpikeView.toShow.RegionChannel{Idx(i)}, 'UniformOutput', false);
                    close(WaitBar);
                end
            else
                app.AllSpikeView.HFOinSpike = cell(length(app.AllSpikeView.Event.EventName),1);
            end
        end
        
        function LoadHFOAll(app)
            Protocols = load(bst_get('BrainstormDbFile'));
            app.VS_Cluster_HFO = [];
            app.VS_Cluster_Spike = [];
            app.AllHFOView.toShow = struct('ReSample',[],'BsTime',[],'ArdBsTime',[],'Weight',[],'ArdWeight',[],'Weight_S',[],'ArdWeight_S',[],'Channel',[],'ArdChannel',[],'EventTime',[],...
                                           'LL_HFO',[],'HilAmp_HFO',[],'TFEntropy_HFO',[],'LL_Spike',[],'PeakAmp_Spike',[],'TFEntropy_Spike',[],'Cls',[],'Best_Cls',[],'ClsIdx',[],...
                                           'ChannelMat',[],'sFile',[],'ChannelMat_VS',[],'SpikeView_toShow',[], 'Maps', []);
            app.AllHFOView.Event = struct('EventName',[],'EventValue',[],'EventToDisp',[]);
            app.AllHFOView.HFOIdx = [];
            app.AllHFOView.ArdHFOIdx = [];
            app.AllHFOView.SpikeSignal = [];
            app.AllHFOView.SpikeInHFOSignal = [];
            app.AllHFOView.ArdSpikeInHFOSignal = [];
            app.AllHFOView.SpikeInHFOSignal_S = [];
            app.AllHFOView.ArdSpikeInHFOSignal_S = [];
            app.AllHFOView.HFOIdxToShow = [];
            app.AllHFOView.SpikeInHFO = [];
            app.AllHFOView.Idx = [];
            app.AllHFOView.FileName = [];
            app.AllHFOView.EventName = [];
            app.AllHFOView.ReconParam = [];
            SelectIndex = find(app.SelectStudy);
            for i=1:length(SelectIndex)
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Load All HFO For View','Message',['Loading Study: ',num2str(i),'/',num2str(length(SelectIndex))],'value',i/length(SelectIndex));
                % Read sFile and ChannelMat.
                meg_file = fullfile(app.ProjectDir, app.db_subject_select.meg_file{SelectIndex(i)});
                ImportOptions = db_template('ImportOptions');ImportOptions.EventsMode = 'no';
                [sFile, ~] = in_fopen_fif(fullfile(app.ProjectDir, app.db_subject_select.meg_file{SelectIndex(i)}), ImportOptions);
                ChannelMat = app.db_subject_select.channel_mat(SelectIndex(i));
                if(~exist(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveHFODetectionResults.mat'),'file')||...
                   ~exist(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveHFOViewResults.mat'),'file')||...
                   ~exist(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveVirtualSensorResults.mat'),'file'))
                    continue;
                end
                HFOView_matObj = matfile(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveHFOViewResults.mat'));
                HFOView_toShow = HFOView_matObj.toShow;HFOView_Event = HFOView_matObj.Event;HFOView_SourceMaps = HFOView_matObj.SourceMaps;
                if(isempty(HFOView_matObj.HFOIdx))
                    continue;
                end
                % Get VS channels and Recon Param
                ReconParam = load(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveVirtualSensorResults.mat'), 'ReconParam');ReconParam = ReconParam.ReconParam;
                app.AllHFOView.ReconParam = cat(1, app.AllHFOView.ReconParam, ReconParam);
                ChannelMat_VS = load(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveVirtualSensorResults.mat'), 'bstChannel');ChannelMat_VS = ChannelMat_VS.bstChannel;
                % For HFO view
                app.AllHFOView.toShow.ReSample = HFOView_toShow.ReSample;
                app.AllHFOView.toShow.BsTime = cat(2,app.AllHFOView.toShow.BsTime,HFOView_toShow.BsTime);
                app.AllHFOView.toShow.ArdBsTime = cat(2,app.AllHFOView.toShow.ArdBsTime,HFOView_toShow.ArdBsTime);
                app.AllHFOView.toShow.Weight = cat(2,app.AllHFOView.toShow.Weight,HFOView_toShow.Weight);
                app.AllHFOView.toShow.ArdWeight = cat(2,app.AllHFOView.toShow.ArdWeight,HFOView_toShow.ArdWeight);
                app.AllHFOView.toShow.Weight_S = cat(2,app.AllHFOView.toShow.Weight_S,HFOView_toShow.Weight_S);
                app.AllHFOView.toShow.ArdWeight_S = cat(2,app.AllHFOView.toShow.ArdWeight_S,HFOView_toShow.ArdWeight_S);
                app.AllHFOView.toShow.Channel = cat(2,app.AllHFOView.toShow.Channel,HFOView_toShow.Channel);
                app.AllHFOView.toShow.ArdChannel = cat(2,app.AllHFOView.toShow.ArdChannel,HFOView_toShow.ArdChannel);
                app.AllHFOView.toShow.EventTime = cat(2,app.AllHFOView.toShow.EventTime,HFOView_toShow.EventTime);
                app.AllHFOView.toShow.ChannelMat_VS = cat(1,app.AllHFOView.toShow.ChannelMat_VS,repmat(ChannelMat_VS,length(HFOView_toShow.BsTime),1));
                app.AllHFOView.HFOIdx = cat(2,app.AllHFOView.HFOIdx,HFOView_matObj.HFOIdx);
                app.AllHFOView.ArdHFOIdx = cat(2,app.AllHFOView.ArdHFOIdx,HFOView_matObj.ArdHFOIdx);
                % For HFO Feature
                app.AllHFOView.toShow.LL_HFO = cat(2,app.AllHFOView.toShow.LL_HFO,HFOView_toShow.LL_HFO);
                app.AllHFOView.toShow.HilAmp_HFO = cat(2,app.AllHFOView.toShow.HilAmp_HFO,HFOView_toShow.HilAmp_HFO);
                app.AllHFOView.toShow.TFEntropy_HFO = cat(2,app.AllHFOView.toShow.TFEntropy_HFO, HFOView_toShow.TFEntropy_HFO);
                app.AllHFOView.toShow.LL_Spike = cat(2,app.AllHFOView.toShow.LL_Spike,HFOView_toShow.LL_Spike);
                app.AllHFOView.toShow.PeakAmp_Spike = cat(2,app.AllHFOView.toShow.PeakAmp_Spike,HFOView_toShow.PeakAmp_Spike);
                app.AllHFOView.toShow.TFEntropy_Spike = cat(2,app.AllHFOView.toShow.TFEntropy_Spike,HFOView_toShow.TFEntropy_Spike);
                % For HFO Cluster
                app.AllHFOView.toShow.Bic = HFOView_toShow.Bic;
                app.AllHFOView.toShow.ClsIdx = cat(2,app.AllHFOView.toShow.ClsIdx,HFOView_toShow.ClsIdx);
                app.AllHFOView.toShow.Cls = cat(2,app.AllHFOView.toShow.Cls,HFOView_toShow.Cls);
                app.AllHFOView.toShow.Best_Cls = HFOView_toShow.Best_Cls;
                try
                    app.AllHFOView.toShow.ClusterSelect = HFOView_toShow.ClusterSelect;
                catch ME
                    app.AllHFOView.toShow.ClusterSelect = ones(app.AllHFOView.toShow.Best_Cls, 1);
                end
                % For HFO Source
                app.AllHFOView.toShow.Maps = cat(1, app.AllHFOView.toShow.Maps, HFOView_SourceMaps);
                % For Read MEG Data
                app.AllHFOView.toShow.sFile = cat(1,app.AllHFOView.toShow.sFile, repmat(sFile,length(HFOView_toShow.BsTime),1));
                app.AllHFOView.toShow.ChannelMat = cat(1,app.AllHFOView.toShow.ChannelMat,repmat(ChannelMat,length(HFOView_toShow.BsTime),1));
                % For HFO Event
                app.AllHFOView.Event.EventName = cellfun(@(x)cat(2,'Event_',num2str(x)),num2cell(1:length(app.AllHFOView.toShow.BsTime)),'UniformOutput',false);
                app.AllHFOView.Event.EventValue = app.AllHFOView.Event.EventName{1};
                app.AllHFOView.Event.EventToDisp = cat(1,app.AllHFOView.Event.EventToDisp,HFOView_Event.EventToDisp);
                % For HFO Index to Show
                app.AllHFOView.HFOIdxToShow = cat(2,app.AllHFOView.HFOIdxToShow,HFOView_matObj.HFOIdxToShow);
                % For save SaveSpikeViewResults
                app.AllHFOView.EventName = cat(2,app.AllHFOView.EventName,HFOView_Event.EventName);
                app.AllHFOView.FileName = cat(1,app.AllHFOView.FileName,repmat({fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveHFOViewResults.mat')},length(HFOView_toShow.BsTime),1));
                app.AllHFOView.Idx = cat(1,app.AllHFOView.Idx,repmat(i,length(HFOView_toShow.BsTime),1));
                % For Spike in HFO
                SpikeInHFO = num2cell(ones(length(HFOView_matObj.HFOIdx), 1) * app.AllHFOView.toShow.ReSample);
                if(exist(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveSpikeViewResults.mat'),'file'))
                    SpikeView_Evnet = load(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveSpikeViewResults.mat'), 'Event');SpikeView_Evnet=SpikeView_Evnet.Event;
                    SpikeView_toShow = load(fullfile(app.ProjectDir, app.db_subject_select.results{SelectIndex(i)},'SaveSpikeViewResults.mat'), 'toShow');SpikeView_toShow=SpikeView_toShow.toShow;
                    app.AllHFOView.toShow.SpikeView_toShow = cat(1, app.AllHFOView.toShow.SpikeView_toShow, SpikeView_toShow);
                end
                app.AllHFOView.SpikeInHFO = cat(1,app.AllHFOView.SpikeInHFO,SpikeInHFO);
                close(WaitBar);
            end
            % Modify HFO Name (Keep previous results, AC_HFO + AC_Spike -> AC_HFO, HFO + Reject Spike -> OL_HFO, AC_HFO + OL Spike -> OL_HFO)
            app.AllHFOView.Event.EventName = cellfun(@(x,y)[x(1:strfind(x,'Event_')-1),y],app.AllHFOView.EventName,app.AllHFOView.Event.EventName,'UniformOutput',false);
%             Idx = cellfun(@(x)~(contains(x,'Reject: ')||contains(x,'Accept: ')),app.AllHFOView.Event.EventName)&cellfun(@(x)~isempty(x),app.AllHFOView.SpikeInHFO)';
%             app.AllHFOView.Event.EventName(Idx) = cellfun(@(x)['AC_Accept: ',x], app.AllHFOView.Event.EventName(Idx), 'UniformOutput', false);
            Idx = cellfun(@(x)isempty(x),app.AllHFOView.SpikeInHFO)';
            app.AllHFOView.Event.EventName(Idx) = cellfun(@(x)['OL_Accept: ',x], app.AllHFOView.Event.EventName(Idx), 'UniformOutput', false);
            % Loading HFO Signal and Around HFO Signal
            Idx = 1:length(app.AllHFOView.Event.EventName);
            app.AllHFOView.SpikeInHFOSignal = cell(length(app.AllHFOView.Event.EventName),1);
            app.AllHFOView.SpikeInHFOSignal_S = cell(length(app.AllHFOView.Event.EventName),1);
            app.AllHFOView.ArdSpikeInHFOSignal = cell(length(app.AllHFOView.Event.EventName),1);
            app.AllHFOView.ArdSpikeInHFOSignal_S = cell(length(app.AllHFOView.Event.EventName),1);
            ReSampleRatio = round(app.AllHFOView.toShow.sFile(1).prop.sfreq / app.AllHFOView.toShow.ReSample);
            
            for i=1:length(Idx)
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Load All HFO Signal','Message',['Loading Signal: ',num2str(i),'/',num2str(length(Idx))],'value',i/length(Idx));
                if(isempty(app.AllHFOView.ArdSpikeInHFOSignal_S{Idx(i)}))
                    sFileTemp = app.AllHFOView.toShow.sFile(Idx(i));
                    ChannelMatTemp = app.AllHFOView.toShow.ChannelMat(Idx(i));
                    % Re-sample back to Raw MEG and ReSample data
                    BStime = app.AllHFOView.toShow.BsTime{Idx(i)};BStime = BStime(1, :);
                    BStimeTemp = round([(BStime(1) - 1)*ReSampleRatio + 1, BStime(end)*ReSampleRatio] + sFileTemp.prop.times(1)*sFileTemp.prop.sfreq);
                    BStimeTemp = [BStimeTemp(1), min(BStimeTemp(2),round(sFileTemp.prop.times(2)*sFileTemp.prop.sfreq))];
                    Sig = in_fread(sFileTemp,ChannelMatTemp,1,double(BStimeTemp),find(contains({ChannelMatTemp.Channel.Type},'MEG')));
                    if(app.AllHFOView.toShow.sFile(1).prop.sfreq ~= app.AllHFOView.toShow.ReSample)
                        Sig = ft_preproc_resample(Sig, app.AllHFOView.toShow.sFile(1).prop.sfreq, app.AllHFOView.toShow.ReSample, 'fft');
                    end
                    % Find the Data not in the BStime
                    Sig = cat(2, Sig, zeros(size(Sig,1), length(BStime)-size(Sig,2)));
                    Sig = Sig(:, cellfun(@(x)ismember(x, BStime),num2cell(BStime(1):BStime(end))));
                    app.AllHFOView.SpikeSignal{Idx(i)} = Sig;
                    % Get VS Signal (HFO)
                    app.AllHFOView.SpikeInHFOSignal{Idx(i)} = app.AllHFOView.toShow.Weight{Idx(i)} * Sig;
                    app.AllHFOView.ArdSpikeInHFOSignal{Idx(i)} = app.AllHFOView.toShow.ArdWeight{Idx(i)} * Sig;
                    % Get VS Signal (Spike)
                    app.AllHFOView.SpikeInHFOSignal_S{Idx(i)} = app.AllHFOView.toShow.Weight_S{Idx(i)} * Sig;
                    app.AllHFOView.ArdSpikeInHFOSignal_S{Idx(i)} = app.AllHFOView.toShow.ArdWeight_S{Idx(i)} * Sig;
                end
                close(WaitBar);
            end
        end
        
        function UpdatePlot(app, ACTION)
            if(ACTION==0)
                % Plot Signal
                if(app.iState==1 && app.iStateView==1)
                    [SampleFreq, Sig, EventTime, Channel, SpikeInChannel, HFOInSpike, SpikeIdxToShow] = Get_MEG(app);
                    Plot_MEG(app, SampleFreq, Sig, EventTime, Channel, SpikeInChannel, HFOInSpike, SpikeIdxToShow, app.PlotShow.Lpass, app.PlotShow.Hpass);
                elseif(app.iState==2 && app.iStateView==0)
                    if(~isempty(app.AllHFOView.Event.EventValue))
                        [SampleFreq, Sig, Sig_S, EventTime, Channel, SpikeInHFO, HFOChannel] = Get_VS(app);
                        Plot_VS(app, SampleFreq, Sig, Sig_S, EventTime, Channel, SpikeInHFO, HFOChannel, app.PlotShow.Lpass, app.PlotShow.Hpass);
                    end
                elseif(app.iState==2 && app.iStateView==1)
                    % Load SaveSpikeViewResults Data
                    EventIdx = cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName);
                    % Read the data for the MEG plot %#ok<*HIST> 
                    SpikeIndex = double(app.AllHFOView.HFOIdx{EventIdx}(:, 1));SpikeIndex=cat(1,SpikeIndex,SpikeIndex);
                    [m, n] = hist(SpikeIndex, unique(SpikeIndex)); %#ok<*HIST> 
                    SpikeIndex = n(find(m==max(m)));SpikeIndex = SpikeIndex(1);
                    SampleFreq =  app.AllHFOView.toShow.SpikeView_toShow(unique(app.AllHFOView.Idx) == app.AllHFOView.Idx(EventIdx)).ReSample;
                    EventTime =  app.AllHFOView.toShow.SpikeView_toShow(unique(app.AllHFOView.Idx) == app.AllHFOView.Idx(EventIdx)).EventTime(SpikeIndex);
                    Channel =  app.AllHFOView.toShow.SpikeView_toShow(unique(app.AllHFOView.Idx) == app.AllHFOView.Idx(EventIdx)).RegionChannelName{SpikeIndex};Channel = Channel(1:app.AxViewNum);
                    HFOInSpike = [mean(app.AllHFOView.HFOIdx{EventIdx}(:,3)), mean(app.AllHFOView.HFOIdx{EventIdx}(:,4))];
                    SpikeInChannel =  app.AllHFOView.toShow.SpikeView_toShow(unique(app.AllHFOView.Idx) == app.AllHFOView.Idx(EventIdx)).SpikeChannel{SpikeIndex};SpikeInChannel = cat(2,SpikeInChannel, [{[]}, {[]}, {[]}, {[]}, {[]}, {[]}, {[]}, {[]}]);SpikeInChannel = SpikeInChannel(1:app.AxViewNum);
                    % Read MEG Signal
                    Sig = app.AllHFOView.SpikeSignal{EventIdx};
                    if(~isempty(Sig))
                        sFileTemp = app.AllHFOView.toShow.sFile(EventIdx);ChannelMatTemp = app.AllHFOView.toShow.ChannelMat(EventIdx);
                        % Re-sample back to Raw MEG and ReSample data.
                        ReSampleRatio = round(sFileTemp.prop.sfreq / SampleFreq);
                        BStime =  app.AllHFOView.toShow.SpikeView_toShow(unique(app.AllHFOView.Idx) == app.AllHFOView.Idx(EventIdx)).BaseLine{SpikeIndex};
                        BStimeTemp = round([(BStime(1) - 1)*ReSampleRatio + 1, BStime(end)*ReSampleRatio] + sFileTemp.prop.times(1)*sFileTemp.prop.sfreq);
                        BStimeTemp = [BStimeTemp(1), min(BStimeTemp(2),round(sFileTemp.prop.times(2)*sFileTemp.prop.sfreq))];
                        Sig = in_fread(sFileTemp,ChannelMatTemp,1,BStimeTemp, find(contains({ChannelMatTemp.Channel.Type},'MEG')));
                        if(app.AllHFOView.toShow.sFile(1).prop.sfreq ~= app.AllHFOView.toShow.ReSample)
                            Sig = ft_preproc_resample(Sig, app.AllHFOView.toShow.sFile(1).prop.sfreq, app.AllHFOView.toShow.ReSample, 'fft');
                        end
                        app.AllHFOView.SpikeSignal{EventIdx} = Sig;
                    end
                    % Segment MEG to 8 brain Region
                    Sig = cellfun(@(x)Sig(x, :), app.AllHFOView.toShow.SpikeView_toShow(unique(app.AllHFOView.Idx) == app.AllHFOView.Idx(EventIdx)).RegionChannel{SpikeIndex(1)}, 'UniformOutput', false);
                    Sig = Sig(1:app.AxViewNum);
                    SpikeIdxToShow = cellfun(@(x)ones(size(x,2),1),Channel,'UniformOutput',false);
                    Plot_MEG(app, SampleFreq, Sig, EventTime, Channel, SpikeInChannel, HFOInSpike, SpikeIdxToShow, 3, 200);
                elseif(app.iState==2 && app.iStateView==2)
                    [Signal, Signal_S, TF] = Get_TF(app);
                    Plot_TF(app, Signal, Signal_S, TF);
                end
            elseif(ACTION==1)
                % Updata the point line
                if(app.iStateView==1)
                    PL_Plot = app.UIAxesMEG.Children(contains(app.UIAxesMEG.Children.get('Tag'), 'PL'));
                    if(length(PL_Plot)==1)
                        PL_Plot.XData = [app.PlotShow.PointLineNew,app.PlotShow.PointLineNew];
                    else
                        Sample = PL_Plot(1).XData(1) - PL_Plot(2).XData(1);
                        for ax=1:length(PL_Plot)
                            PL_Plot(length(PL_Plot)-ax+1).XData = [app.PlotShow.PointLineNew + Sample*(ax-1),app.PlotShow.PointLineNew + Sample*(ax-1)];
                        end
                    end
                elseif(app.iStateView==0)
                    PL_Plot = app.UIAxesVS.Children(contains(app.UIAxesVS.Children.get('Tag'), 'PL'));
                    if(length(PL_Plot)==1)
                        PL_Plot.XData = [app.PlotShow.PointLineNew,app.PlotShow.PointLineNew];
                    else
                        Sample = PL_Plot(1).XData(1) - PL_Plot(2).XData(1);
                        for ax=1:length(PL_Plot)
                            PL_Plot(length(PL_Plot)-ax+1).XData = [app.PlotShow.PointLineNew + Sample*(ax-1),app.PlotShow.PointLineNew + Sample*(ax-1)];
                        end
                    end
                end
            elseif(ACTION==2)
                % Updata the Signal AMP
                if(app.iStateView==1)
                    Dat_Plot = app.UIAxesMEG.Children(contains(app.UIAxesMEG.Children.get('Tag'), 'Sig'));
                    for i=1:length(Dat_Plot)
                        SigToDispTemp = Dat_Plot(i).YData;
                        SigToDispTemp = (SigToDispTemp-round(mean(SigToDispTemp)))./app.PlotShow.AmpNumLast.*app.PlotShow.AmpNum + round(mean(SigToDispTemp));
                        Dat_Plot(i).YData = SigToDispTemp;
                    end
                    Dat_Plot = app.UIAxesMEG.Children(contains(app.UIAxesMEG.Children.get('Tag'), 'Marker'));
                    if(~isempty(Dat_Plot))
                        for i=1:length(Dat_Plot)
                            SigToDispTemp = Dat_Plot(i).YData;
                            SigToDispTemp = (SigToDispTemp-round(mean(SigToDispTemp)))./app.PlotShow.AmpNumLast.*app.PlotShow.AmpNum + round(mean(SigToDispTemp));
                            Dat_Plot(i).YData = SigToDispTemp;
                        end
                    end
                elseif(app.iStateView==0)
                    Dat_Plot = app.UIAxesVS.Children(contains(app.UIAxesVS.Children.get('Tag'), 'Sig'));
                    for i=1:length(Dat_Plot)
                        SigToDispTemp = Dat_Plot(i).YData;
                        SigToDispTemp = (SigToDispTemp-round(mean(SigToDispTemp)))./app.PlotShow.AmpNumLast.*app.PlotShow.AmpNum + round(mean(SigToDispTemp));
                        Dat_Plot(i).YData = SigToDispTemp;
                    end
                    Dat_Plot = app.UIAxesVS.Children(contains(app.UIAxesVS.Children.get('Tag'), 'Marker'));
                    if(~isempty(Dat_Plot))
                        for i=1:length(Dat_Plot)
                            SigToDispTemp = Dat_Plot(i).YData;
                            SigToDispTemp = (SigToDispTemp-round(mean(SigToDispTemp)))./app.PlotShow.AmpNumLast.*app.PlotShow.AmpNum + round(mean(SigToDispTemp));
                            Dat_Plot(i).YData = SigToDispTemp;
                        end
                    end
                end
            elseif(ACTION==3)
                % Updata the Signal time Scale
                if(app.iStateView==0 || app.iStateView==1)
                    Dat_Plot = app.UIAxesMEG.Children(contains(app.UIAxesMEG.Children.get('Tag'), 'Sig'));
                end
            end
        end
        
        function [Signal, Signal_S, TF] = Get_TF(app)
            % Get VS Signal
            EventIdx = cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName);
            [SampleFreq, Sig, Sig_S, EventTime, ~, ~, HFOChannel] = Get_VS(app);
            Signal = cat(1,Sig{:});Chan = cat(1,HFOChannel{:});Signal = Signal(find(Chan), :);
            Signal = Signal(1:min(size(Signal, 1), app.AxViewNum), :);
            Signal_S = cat(1,Sig_S{:});Signal_S = Signal_S(find(Chan), :);
            Signal_S = Signal_S(1:min(size(Signal_S, 1), app.AxViewNum), :);
            % Get VS TF
            TF = morlet_transform(Signal - mean(Signal,2), (0:size(Signal,2)-1)./SampleFreq, 3:app.PlotShow.Hpass,1, 3, 'y');
            TF = TF(:,round(mean(EventTime)-round(SampleFreq*0.15))+1:round(mean(EventTime)+round(SampleFreq*0.15)),:);
            TF = mat2cell(TF, ones(size(TF,1),1),size(TF,2),size(TF,3));
            TF = cellfun(@(x)(x-min(x(:)))./(max(x(:))-min(x(:))),TF,'UniformOutput',false);
            % Filter VS Signal
            Signal = process_bandpass('Compute', Signal, SampleFreq, 80, 200, 'bst-hfilter-2019', 1);
            Signal = Signal(:, round(mean(EventTime)-round(SampleFreq*0.15))+1:round(mean(EventTime)+round(SampleFreq*0.15)));
            Signal = (Signal-min(Signal,[],2))./(max(Signal,[],2)-min(Signal,[],2));
            Signal = mat2cell(Signal, ones(size(Signal,1),1),size(Signal,2));
            % Filter Spike Signal
            Signal_S = process_bandpass('Compute', Signal_S, SampleFreq, 3, 80, 'bst-hfilter-2019', 1);
            Signal_S = Signal_S(:, round(mean(EventTime)-round(SampleFreq*0.15))+1:round(mean(EventTime)+round(SampleFreq*0.15)));
            Signal_S = (Signal_S-min(Signal_S,[],2))./(max(Signal_S,[],2)-min(Signal_S,[],2));
            Signal_S = mat2cell(Signal_S, ones(size(Signal_S,1),1),size(Signal_S,2));
        end
        
        function Plot_TF(app, Signal, Signal_S, TF)
            app.UIAxesTF.clo;
            % Plot Grid
            app.UIAxesTF.YLim = [0, 4];app.UIAxesTF.XLim = [0, round(app.AxViewNum / 2)];
            hold(app.UIAxesTF,'on');
            plot(app.UIAxesTF,[0, round(app.AxViewNum / 2)], [2, 2], 'k','LineWidth',1);
            for ax=1:app.AxViewNum-1
                plot(app.UIAxesTF, [ax, ax],app.UIAxesTF.YLim, 'k','LineWidth',1);
            end
            for ax=1:min(length(Signal), app.AxViewNum)
                Temp = mod(ax, 2) * 2;
                % Plot HFO Signal
                XTick = (1:size(Signal{ax},2))/size(Signal{ax},2);
                plot(app.UIAxesTF,XTick * 0.825 + 0.15 + ceil(ax/2) - 1, Signal{ax} * 0.45 + Temp + 1.5, 'color',[0.25,0.25,0.25],'LineWidth',2);
                % Plot Spike Signal
                XTick = (1:size(Signal_S{ax},2))/size(Signal_S{ax},2);
                plot(app.UIAxesTF,XTick * 0.825 + 0.15 + ceil(ax/2) - 1, Signal_S{ax} * 0.5 + Temp + 1, 'color',[0.25,0.25,0.25],'LineWidth',2);
                % Plot TF
                XTick = (1:size(squeeze(TF{ax}),1))/size(squeeze(TF{ax}),1);
                YTick = (1:size(squeeze(TF{ax}),2))/size(squeeze(TF{ax}),2);
                imagesc('Parent', app.UIAxesTF, 'XData', XTick * 0.825 + 0.15 + ceil(ax/2) - 1, 'YData',YTick*0.85 + Temp + 0.125, 'CData', squeeze(squeeze(TF{ax}) .* (3:200) .* (3:200).^(0.5))');h=colormap(app.UIAxesTF, 'jet');
                text([0.15+ceil(ax/2)-1, ceil(ax/2) - 0.475, 0.75+0.15+ceil(ax/2)-1], [Temp + 0.095 Temp + 0.095 Temp + 0.095], {'-150', '0', '150'}, 'FontSize',12,'Color',[0 0 0],'Parent',app.UIAxesTF);
                text(ceil(ax/2) - 0.5 - 0.04, Temp + 0.0375, 'Time (ms)', 'FontSize',12,'Color',[0 0 0],'Parent',app.UIAxesTF);
                text([ceil(ax/2)-1+0.055, ceil(ax/2)-1+0.055, ceil(ax/2)-1+0.055, ceil(ax/2)-1+0.055, ceil(ax/2)-1+0.055], [Temp + 0.125, Temp + 0.337, Temp+ 0.550, Temp + 0.762, Temp + 0.975] , {' 3', '50', '100', '150', '200'},'FontSize',12,'Color',[0 0 0],'Parent',app.UIAxesTF);
                text(ceil(ax/2)-1+0.025, Temp+ 0.40, 'Frequency (Hz)', 'FontSize', 12, 'Color',[0 0 0],'Parent',app.UIAxesTF, 'Rotation', 90);
            end
            hold(app.UIAxesTF,'off');
        end

        function [SampleFreq, Sig, EventTime, Channel, SpikeInChannel, HFOInSpike, SpikeIdxToShow] = Get_MEG(app)
            EventIdx = cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName);
            % Read Signal
            Sig = app.AllSpikeView.HFOinSpikeSignal{EventIdx};
            if(isempty(Sig))
                sFileTemp = app.AllSpikeView.toShow.sFile(EventIdx);
                ChannelMatTemp = app.AllSpikeView.toShow.ChannelMat(EventIdx);
                % Re-sample back to Raw MEG and ReSample data.
                ReSampleRatio = round(sFileTemp.prop.sfreq / app.AllSpikeView.toShow.ReSample);
                BStime = app.AllSpikeView.toShow.BaseLine{EventIdx};BStime = BStime(1, :);
                BStimeTemp = round([(BStime(1) - 1)*ReSampleRatio + 1, BStime(end)*ReSampleRatio] + sFileTemp.prop.times(1)*sFileTemp.prop.sfreq);
                BStimeTemp = [BStimeTemp(1), min(BStimeTemp(2),round(sFileTemp.prop.times(2)*sFileTemp.prop.sfreq))];
                Sig = in_fread(sFileTemp,ChannelMatTemp,1,BStimeTemp, find(contains({ChannelMatTemp.Channel.Type},'MEG')));
                if(app.AllSpikeView.toShow.sFile(1).prop.sfreq ~= app.AllSpikeView.toShow.ReSample)
                    Sig = ft_preproc_resample(Sig, app.AllSpikeView.toShow.sFile(1).prop.sfreq, app.AllSpikeView.toShow.ReSample, 'fft');
                end
                % Find the Data not in the BStime
                Sig = cat(2, Sig, zeros(size(Sig,1), length(BStime)-size(Sig,2)));
                Sig = Sig(:, cellfun(@(x)ismember(x, BStime),num2cell(BStime(1):BStime(end))));
                app.SpikeToPlot{EventIdx} = process_bandpass('Compute', Sig, app.AllSpikeView.toShow.ReSample, 3, 80, 'bst-hfilter-2019', 1);
                % Segment MEG to 8 brain Region
                app.AllSpikeView.HFOinSpikeSignal{EventIdx} = cellfun(@(x)Sig(x, :), app.AllSpikeView.toShow.RegionChannel{EventIdx}, 'UniformOutput', false);
                Sig = app.AllSpikeView.HFOinSpikeSignal{EventIdx};
            end
            % Get Spike Event Index and Channel Name
            SampleFreq = app.AllSpikeView.toShow.ReSample;
            EventTime = app.AllSpikeView.toShow.EventTime(EventIdx)+app.AllSpikeView.toShow.EventTimeOffset(EventIdx);
            Channel = app.AllSpikeView.toShow.RegionChannelName{EventIdx};
            SpikeInChannel = app.AllSpikeView.toShow.SpikeChannel{EventIdx};
            HFOInSpike = app.AllSpikeView.HFOinSpike{EventIdx};
            SpikeIdxToShow = app.AllSpikeView.SpikeIdxToShow{EventIdx};
            % Get the Brain Regions according to Column
            Sig = Sig(1:app.AxViewNum);Channel = Channel(1:app.AxViewNum);
            SpikeInChannel = cat(2,SpikeInChannel, [{[]}, {[]}, {[]}, {[]}, {[]}, {[]}, {[]}, {[]}]);SpikeInChannel = SpikeInChannel(1:app.AxViewNum);
        end
        
        function Plot_MEG(app, SampleFreq, Sig, EventTime, Channel, SpikeInChannel, HFOInSpike, SpikeIdxToShow, Lpass, Hpass)% Plot VS data.
            % Input: SampleFreq, Sig(cell), EventTime, Channel(cell), SpikeInChannel(cell), HFOInSpike, SpikePeaks(cell)
            for ax=1:app.AxViewNum
                AxSig = -Sig{ax};AxEventTime = EventTime;AxChannel = Channel{ax};AxSpikeInChannel = SpikeInChannel{ax};AxHFOInSpike = HFOInSpike;AxSpikeIdxToShow = SpikeIdxToShow{ax};
                % Filter data
                if(app.PlotShow.HpassOn&&app.PlotShow.LpassOn)
                    SigToDispTemp = process_bandpass('Compute', AxSig, SampleFreq, Lpass, Hpass, 'bst-hfilter-2019', 1);
                elseif(~app.PlotShow.LpassOn&&app.PlotShow.HpassOn)
                    SigToDispTemp = process_bandpass('Compute', AxSig, SampleFreq, [], Hpass, 'bst-hfilter-2019', 1);    
                elseif(~app.PlotShow.HpassOn&&app.PlotShow.LpassOn)
                    SigToDispTemp = process_bandpass('Compute', AxSig, SampleFreq, Lpass, [], 'bst-hfilter-2019', 1);                
                else
                    SigToDispTemp = AxSig;
                end  
                % Get MAG and GRAD data 
                SigToDispTempMag = SigToDispTemp(end - size(SigToDispTemp, 1)/3 + 1:end,:); 
                SigToDispTempGrad = SigToDispTemp(1:size(SigToDispTemp, 1)/3*2,:); 
                % Get GFP
                GFP = sum(cat(1,SigToDispTempGrad/100*5.6,SigToDispTempMag).^2,1)';GFP = (GFP-min(GFP))/(max(GFP)-min(GFP));GFP = (GFP-0.5)*0.75*app.PlotShow.AmpNum;
                % Normalize MAG and GRAD data
                SigToDispTempMag = (SigToDispTempMag-min(SigToDispTempMag(:)))/(max(SigToDispTempMag(:))-min(SigToDispTempMag(:)));
                SigToDispTempGrad = (SigToDispTempGrad-min(SigToDispTempGrad(:)))/(max(SigToDispTempGrad(:))-min(SigToDispTempGrad(:)));
                SigToDispTemp = cat(1,SigToDispTempGrad,SigToDispTempMag);SigToDispTemp(isnan(SigToDispTemp)) = 0.5;
                SigToDispTemp = (SigToDispTemp-0.5)*0.75*app.PlotShow.AmpNum+repmat((1:size(SigToDispTemp,1))',1,size(SigToDispTemp,2)); 
                SamplePoint = size(SigToDispTemp,2);
                SampleBeg = max(1,round(app.PlotShow.PointLine-SamplePoint*app.PlotShow.TimeScale/2));SampleEnd = min(SamplePoint,round(app.PlotShow.PointLine+SamplePoint*app.PlotShow.TimeScale/2));
                SigToDisp = SigToDispTemp(:,SampleBeg:SampleEnd);
                GFP = GFP(SampleBeg:SampleEnd);
                % For the different Column plot
                Sample = SampleEnd - SampleBeg + 1;
                SampleBeg = SampleBeg + Sample*(ax-1);SampleEnd = SampleEnd + Sample*(ax-1);
                % Add data to 39 channels for occipital
                SigToDisp = cat(1, SigToDisp, repmat((37:39)', 1, size(SigToDisp, 2)));SigToDisp = SigToDisp(1:39, :);
                plot(app.UIAxesMEG,SampleBeg:SampleEnd,SigToDisp','k', 'Tag', 'Sig');hold(app.UIAxesMEG,'on');
                plot(app.UIAxesMEG,SampleBeg:SampleEnd,GFP+size(SigToDisp,1)+1,'b','LineWidth', 0.8, 'Tag', 'Sig');
                if(~isempty(AxSpikeInChannel(AxSpikeIdxToShow(AxSpikeInChannel))))
                    plot(app.UIAxesMEG,SampleBeg:SampleEnd,SigToDisp(AxSpikeInChannel(AxSpikeIdxToShow(AxSpikeInChannel)), :)','r','LineWidth',1, 'Tag', 'Sig');
                end
                % Modify YLim
                app.UIAxesMEG.YLim = [0,size(SigToDisp,1)+2];
                % Plot Channel
                app.UIAxesMEG.YTickLabel =[];
                text(SampleBeg,40+0.15,'GFP','FontSize',15,'Color',[0 0 0.75],'Parent',app.UIAxesMEG, 'Tag', 'Info');
                if(app.PlotShow.ShowInfo)
                    ChannelName = cat(2,{''},AxChannel(1:2:end),{''});
                    GroupName = cat(2,{''},AxChannel(2:2:end),{''});
                    text(SampleBeg*ones(size(SigToDispTemp,1)+2,1),(0:size(SigToDispTemp,1)+1)-0.15,ChannelName,'FontSize',12,'Color',[0.75 0 0],'Parent',app.UIAxesMEG, 'Tag', 'Info');
                    text(SampleBeg*ones(2,1),(0:1)-0.75,GroupName(1:2),'FontSize',15,'Color',[1 0 0],'Parent',app.UIAxesMEG, 'Tag', 'Info');
                else
                    YTickLabel = cat(2,{' '},cellfun(@(x)cat(2,'Channel',num2str(x)),num2cell(1:size(SigToDisp,1)),'UniformOutput',false),{' '});
                    text(SampleBeg*ones(size(SigToDisp,1)+2,1),(0:size(SigToDisp,1)+1)-0.25,YTickLabel,'FontSize',12,'Color',[1 0 0],'Parent',app.UIAxesMEG, 'Tag', 'Info');                
                end
                % Plot Point Line
                plot(app.UIAxesMEG,[app.PlotShow.PointLineNew + Sample*(ax-1),app.PlotShow.PointLineNew + Sample*(ax-1)],[0,max(cellfun(@(x)size(x,1),Sig))+2],'R','LineWidth',0.01, 'Tag', 'PL');
                % Show Marker
                plot(app.UIAxesMEG,AxEventTime + Sample*(ax-1),[0.3,0.3],'.-g','LineWidth',2,'MarkerSize',15);
                % Show HFO in spike
                for i=1:size(AxHFOInSpike,1)
                    plot(app.UIAxesMEG,AxHFOInSpike(i,:) + Sample*(ax-1),[0.3,0.3],'.-r','LineWidth',1,'MarkerSize',10);
                end
            end
            % Add Segment Line
            SampleBeg = max(1,round(app.PlotShow.PointLine-SamplePoint*app.PlotShow.TimeScale/2));
            for ax=1:app.AxViewNum-1
                plot(app.UIAxesMEG,[SampleBeg + Sample*ax,SampleBeg + Sample*ax],[0,size(SigToDisp,1)+2],'k','LineWidth',1);
            end
            % Modify XTick 
            app.UIAxesMEG.XLim = [SampleBeg,SampleBeg+Sample*app.AxViewNum];
            app.UIAxesMEG.XTickLabel =[];
            xLabel = {num2str((SampleBeg-1)/SampleFreq, 2), num2str((SampleBeg+Sample)/SampleFreq,2)};
            xTickValues = [SampleBeg+round(Sample/100), SampleBeg+Sample-round(Sample/20)];
            text(xTickValues,ones(length(xTickValues), 1)*size(SigToDisp,1) + 1.7,xLabel,'FontSize',15,'Color',[0.75 0 0],'Parent',app.UIAxesMEG);
            hold(app.UIAxesMEG,'off');set(app.UIAxesMEG,'YDir','reverse');app.UIAxesMEG.XTick=0;            
        end
        
        function [SampleFreq, Sig, Sig_S, EventTime, Channel, SpikeInHFO, HFOChannel] = Get_VS(app)
            EventIdx = cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName);
            % Read Signal
            Sig = app.AllHFOView.SpikeInHFOSignal{EventIdx};
            ArdSig = app.AllHFOView.ArdSpikeInHFOSignal{EventIdx};
            Sig_S = app.AllHFOView.SpikeInHFOSignal_S{EventIdx};
            ArdSig_S = app.AllHFOView.ArdSpikeInHFOSignal_S{EventIdx};
            if(isempty(ArdSig_S))
                sFileTemp = app.AllHFOView.toShow.sFile(EventIdx);
                ChannelMatTemp = app.AllHFOView.toShow.ChannelMat(EventIdx);
                % Re-sample back to Raw MEG and ReSample data
                ReSampleRatio = round(app.AllHFOView.toShow.sFile(1).prop.sfreq / app.AllHFOView.toShow.ReSample);
                BStime = app.AllHFOView.toShow.BsTime{EventIdx};BStime = BStime(1, :);
                BStimeTemp = round([(BStime(1) - 1)*ReSampleRatio + 1, BStime(end)*ReSampleRatio] + sFileTemp.prop.times(1)*sFileTemp.prop.sfreq);
                BStimeTemp = [BStimeTemp(1), min(BStimeTemp(2),round(sFileTemp.prop.times(2)*sFileTemp.prop.sfreq))];
                Sig = in_fread(sFileTemp,ChannelMatTemp,1,double(BStimeTemp),find(contains({ChannelMatTemp.Channel.Type},'MEG')));
                if(app.AllHFOView.toShow.sFile(1).prop.sfreq ~= app.AllHFOView.toShow.ReSample)
                    Sig = ft_preproc_resample(Sig, app.AllHFOView.toShow.sFile(1).prop.sfreq, app.AllHFOView.toShow.ReSample, 'fft');
                end
                % Find the Data not in the BStime
                Sig = cat(2, Sig, zeros(size(Sig,1), length(BStime)-size(Sig,2)));
                Sig = Sig(:, cellfun(@(x)ismember(x, BStime),num2cell(BStime(1):BStime(end))));
                % Get VS Signal
                app.AllHFOView.SpikeInHFOSignal{EventIdx} = app.AllHFOView.toShow.Weight{EventIdx} * Sig;
                app.AllHFOView.ArdSpikeInHFOSignal{EventIdx} = app.AllHFOView.toShow.ArdWeight{EventIdx} * Sig;
                % Get VS Signal (Spike)
                app.AllHFOView.SpikeInHFOSignal_S{EventIdx} = app.AllHFOView.toShow.Weight_S{EventIdx} * Sig;
                app.AllHFOView.ArdSpikeInHFOSignal_S{EventIdx} = app.AllHFOView.toShow.ArdWeight_S{EventIdx} * Sig;
                Sig = app.AllHFOView.SpikeInHFOSignal{EventIdx};
                ArdSig = app.AllHFOView.ArdSpikeInHFOSignal{EventIdx};
                Sig_S = app.AllHFOView.SpikeInHFOSignal_S{EventIdx};
                ArdSig_S = app.AllHFOView.ArdSpikeInHFOSignal_S{EventIdx};
            end
            % Get HFO Event Index and Channel Name
            SampleFreq = app.AllHFOView.toShow.ReSample;
            EventTime = round(mean(app.AllHFOView.toShow.EventTime{EventIdx}));
            SpikeInHFO = app.AllHFOView.SpikeInHFO{EventIdx};
            Channel = app.AllHFOView.toShow.Channel{EventIdx};
            ArdChannel = app.AllHFOView.toShow.ArdChannel{EventIdx};
            % Sort Signals
            Signal = cat(1, Sig, ArdSig);Signal_S = cat(1, Sig_S, ArdSig_S);
            Signal = cat(1, Signal(app.AllHFOView.HFOIdxToShow{EventIdx},:), Signal(~ismember(1:size(Signal,1),app.AllHFOView.HFOIdxToShow{EventIdx}),:));
            Signal_S = cat(1, Signal_S(app.AllHFOView.HFOIdxToShow{EventIdx},:), Signal_S(~ismember(1:size(Signal_S,1),app.AllHFOView.HFOIdxToShow{EventIdx}),:));
            % Only highlightly show the channle with the feature value more than threshold
            HFOChannel = zeros(size(Signal,1),1);HFOChannel(1:length(app.AllHFOView.HFOIdxToShow{EventIdx})) = 1;
            Channel = cat(2, Channel, ArdChannel);
            Sig = cellfun(@(x)Signal((x-1)*app.AxView+1:min(x*app.AxView,  size(Signal, 1)),:), num2cell(1:ceil(size(Signal, 1) / app.AxView)), 'UniformOutput', false);
            Sig = Sig(1:min(app.AxViewNum, length(Sig)));
            Sig_S = cellfun(@(x)Signal_S((x-1)*app.AxView+1:min(x*app.AxView,  size(Signal_S, 1)),:), num2cell(1:ceil(size(Signal_S, 1) / app.AxView)), 'UniformOutput', false);
            Sig_S = Sig_S(1:min(app.AxViewNum, length(Sig_S)));
            HFOChannel = cellfun(@(x)HFOChannel((x-1)*app.AxView+1:min(x*app.AxView,  size(HFOChannel, 1)),:), num2cell(1:ceil(size(HFOChannel, 1) / app.AxView)), 'UniformOutput', false);
            HFOChannel = HFOChannel(1:min(app.AxViewNum, length(HFOChannel)));
            Channel = cellfun(@(x)Channel((x-1)*app.AxView*2+1:min(x*app.AxView*2,  length(Channel))), num2cell(1:ceil(length(Channel) / app.AxView / 2)), 'UniformOutput', false);
            Channel = Channel(1:min(app.AxViewNum, length(Sig)));
        end
        
        function Plot_VS(app, SampleFreq, Sig, Sig_S, EventTime, Channel, SpikeInHFO, HFOChannel, Lpass, Hpass)
            % Plot VS data.
            % Input: SampleFreq, Sig(cell), EventTime, Channel(dell), SpikeInHFO
            for ax=1:app.AxViewNum
                if(ax>length(Sig))
                    break;
                end
                AxSig = Sig{ax};AxSig_S = Sig_S{ax};AxEventTime = EventTime;AxChannel = Channel{ax};AxSpikeInHFO = SpikeInHFO;AxHFOChannel = HFOChannel{ax};
                % Filter data
                if(app.PlotShow.HpassOn&&app.PlotShow.LpassOn)
                    SigToDispTemp = process_bandpass('Compute', AxSig, SampleFreq, Lpass, Hpass, 'bst-hfilter-2019', 1);
                elseif(~app.PlotShow.LpassOn&&app.PlotShow.HpassOn)
                    SigToDispTemp = process_bandpass('Compute', AxSig, SampleFreq, [], Hpass, 'bst-hfilter-2019', 1);    
                elseif(~app.PlotShow.HpassOn&&app.PlotShow.LpassOn)
                    SigToDispTemp = process_bandpass('Compute', AxSig, SampleFreq, Lpass, [], 'bst-hfilter-2019', 1);                
                else
                    SigToDispTemp = AxSig;
                end  
                SigToDispTemp(:, [1:50,size(SigToDispTemp,2)-50:size(SigToDispTemp,2)]) = repmat(mean(SigToDispTemp, 2), [1, 101]);
                % normalize data 
                SigToDispTemp = reshape(cell2mat(cellfun(@(x)(x-min(x(:)))./(max(x(:))-min(x(:)))',mat2cell(SigToDispTemp,ones(size(SigToDispTemp,1),1),size(SigToDispTemp,2)),'UniformOutput',false)),size(SigToDispTemp,1),size(SigToDispTemp,2));SigToDispTemp(isnan(SigToDispTemp)) = 0.5;
                if(app.PlotShow.ShowSpike)
                    % Plot HFO Band
                    SigToDispTemp = -0.465 + SigToDispTemp*0.5*0.9*app.PlotShow.AmpNum+repmat((1:size(SigToDispTemp,1))',1,size(SigToDispTemp,2)); 
                    SamplePoint = size(SigToDispTemp,2);
                    SampleBeg = max(1,round(app.PlotShow.PointLine-SamplePoint*app.PlotShow.TimeScale/2));SampleEnd = min(SamplePoint,round(app.PlotShow.PointLine+SamplePoint*app.PlotShow.TimeScale/2));
                    SigToDisp = SigToDispTemp(:,SampleBeg:SampleEnd);
                    % For the different Column plot
                    Sample = SampleEnd - SampleBeg + 1;
                    SampleBeg = SampleBeg + Sample*(ax-1);SampleEnd = SampleEnd + Sample*(ax-1);
                    % Plot data
                    plot(app.UIAxesVS,SampleBeg:SampleEnd, SigToDisp','k', 'Tag', 'Sig');hold(app.UIAxesVS,'on');
                    if(~isempty(find(AxHFOChannel, 1)))
                        plot(app.UIAxesVS,SampleBeg:SampleEnd, SigToDisp(find(AxHFOChannel),:)','r','LineWidth',1, 'Tag', 'Sig');hold(app.UIAxesVS,'on');
                    end
                    % Plot Spike Band
                    SigToDisp = process_bandpass('Compute', AxSig_S, SampleFreq, 3, 80, 'bst-hfilter-2019', 1);
                    SigToDisp = reshape(cell2mat(cellfun(@(x)(x-min(x(:)))./(max(x(:))-min(x(:)))',mat2cell(SigToDisp,ones(size(SigToDisp,1),1),size(SigToDisp,2)),'UniformOutput',false)),size(SigToDisp,1),size(SigToDisp,2));SigToDisp(isnan(SigToDisp)) = 0.5;
                    SigToDisp = 0.015 + SigToDisp*0.5*0.9*app.PlotShow.AmpNum+repmat((1:size(SigToDisp,1))',1,size(SigToDisp,2)); 
                    SigToDisp = SigToDisp(:,SampleBeg-Sample*(ax-1):SampleEnd-Sample*(ax-1));
                    plot(app.UIAxesVS,SampleBeg:SampleEnd,SigToDisp','color',[0  0 .8], 'Tag', 'Sig');
                    if(~isempty(find(AxHFOChannel, 1)))
                        plot(app.UIAxesVS,SampleBeg:SampleEnd, SigToDisp(find(AxHFOChannel),:)','color',[0  0 .5],'LineWidth',1, 'Tag', 'Sig');hold(app.UIAxesVS,'on');
                    end
                else
                    % Only Plot HFO Band
                    SigToDispTemp = (SigToDispTemp-0.5)*0.75*app.PlotShow.AmpNum+repmat((1:size(SigToDispTemp,1))',1,size(SigToDispTemp,2)); 
                    SamplePoint = size(SigToDispTemp,2);
                    SampleBeg = max(1,round(app.PlotShow.PointLine-SamplePoint*app.PlotShow.TimeScale/2));SampleEnd = min(SamplePoint,round(app.PlotShow.PointLine+SamplePoint*app.PlotShow.TimeScale/2));
                    SigToDisp = SigToDispTemp(:,SampleBeg:SampleEnd);
                    % For the different Column plot
                    Sample = SampleEnd - SampleBeg + 1;
                    SampleBeg = SampleBeg + Sample*(ax-1);SampleEnd = SampleEnd + Sample*(ax-1);
                    % Plot data
                    plot(app.UIAxesVS,SampleBeg:SampleEnd, SigToDisp','k', 'Tag', 'Sig');hold(app.UIAxesVS,'on');
                    if(~isempty(find(AxHFOChannel, 1)))
                        plot(app.UIAxesVS,SampleBeg:SampleEnd, SigToDisp(find(AxHFOChannel),:)','r','LineWidth',1, 'Tag', 'Sig');hold(app.UIAxesVS,'on');
                    end
                end
                % Modify XTick
                app.UIAxesVS.YLim = [0,max(cellfun(@(x)size(x,1),Sig))+1];
                % Plot Channel
                app.UIAxesVS.YTickLabel =[];
                if(app.PlotShow.ShowInfo)
                    ChannelName = cat(2,{''},AxChannel(1:2:end),{''});
                    GroupName = cat(2,{''},AxChannel(2:2:end),{''});
                    text(SampleBeg*ones(size(SigToDispTemp,1)+2,1),(0:size(SigToDispTemp,1)+1)-0.15,ChannelName,'FontSize',12,'Color',[0 0.5 0],'Parent',app.UIAxesVS, 'Tag', 'Info');
                    text(SampleBeg*ones(size(SigToDispTemp,1)+2,1),(0:size(SigToDispTemp,1)+1)+0.15,GroupName,'FontSize',12,'Color',[0 0.5 0],'Parent',app.UIAxesVS, 'Tag', 'Info');
                else
                    YTickLabel = cat(2,{' '},cellfun(@(x)cat(2,'Channel',num2str(x)),num2cell(1:size(SigToDisp,1)),'UniformOutput',false),{' '});
                    text(SampleBeg*ones(size(SigToDisp,1)+2,1),(0:size(SigToDisp,1)+1)-0.25,YTickLabel,'FontSize',12,'Color',[0 0.5 0],'Parent',app.UIAxesVS, 'Tag', 'Info');                
                end
                % Plot Point Line
                plot(app.UIAxesVS,[app.PlotShow.PointLineNew + Sample*(ax-1),app.PlotShow.PointLineNew + Sample*(ax-1)],[0,max(cellfun(@(x)size(x,1),Sig))+1],'R','LineWidth',0.01, 'Tag', 'PL');
                % Show Marker
                plot(app.UIAxesVS,AxEventTime + Sample*(ax-1),[0.3,0.3],'.-r','LineWidth',2,'MarkerSize',15);
                % Show spike In HFO
                plot(app.UIAxesVS,AxSpikeInHFO + Sample*(ax-1),0.3,'.g','LineWidth',2,'MarkerSize',15);
            end
            % Add Segment Line
            SampleBeg = max(1,round(app.PlotShow.PointLine-SamplePoint*app.PlotShow.TimeScale/2));
            for ax=1:app.AxViewNum-1
                plot(app.UIAxesVS,[SampleBeg + Sample*ax,SampleBeg + Sample*ax],[0,max(cellfun(@(x)size(x,1),Sig))+1],'k','LineWidth',1);
            end
            % Modify XTick 
            app.UIAxesVS.XLim = [SampleBeg,SampleBeg+Sample*app.AxViewNum];
            app.UIAxesVS.XTickLabel =[];
            xLabel = {num2str((SampleBeg-1)/SampleFreq, 2), num2str((SampleBeg+Sample)/SampleFreq,2)};
            xTickValues = [SampleBeg+round(Sample/100), SampleBeg+Sample-round(Sample/20)];
            text(xTickValues,ones(length(xTickValues), 1)*(max(cellfun(@(x)size(x,1),Sig))) + 0.7,xLabel,'FontSize',15,'Color',[0.75 0 0],'Parent',app.UIAxesVS);
            hold(app.UIAxesVS,'off');set(app.UIAxesVS,'YDir','reverse');app.UIAxesVS.XTick=0;            
        end
        
        function PlotCurrentTopo(app)
            app.UIAxesTopo.clo; 
            % Get Current Data
            EventIdx = cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName);
            DataToPlot = app.SpikeToPlot{EventIdx}(:,round(app.PlotShow.PointLineNew));
            % Get Topo
            selChanAll = find(contains({app.AllSpikeView.toShow.ChannelMat(1).Channel.Type}, 'MEG'));
            [~, I, J] = intersect(selChanAll, selChanAll);
            DataToPlotAll  = full(app.WExtrap(:, J) * DataToPlot(I));
            selChan = find(contains({app.AllSpikeView.toShow.ChannelMat(1).Channel.Type}, 'GRAD'));
            [~, I, J] = intersect(selChanAll, selChan);
            DataToPlotGrad = full(app.WExtrapGrad(:, J) * DataToPlot(I));
            selChan = find(contains({app.AllSpikeView.toShow.ChannelMat(1).Channel.Type}, 'MAG'));
            [~, I, J] = intersect(selChanAll, selChan);
            DataToPlotMag = full(app.WExtrapMag(:, J) * DataToPlot(I));
            % Plot
            X = app.UIAxesTopo.Position(3); Y = app.UIAxesTopo.Position(4);
            app.UIAxesTopo.YLim = [0 3];
            VerticesTemp = app.Vertices;VerticesTemp = [VerticesTemp(:,2),VerticesTemp(:,1),VerticesTemp(:,3)];
            VerticesTemp = (VerticesTemp - min(VerticesTemp(:,2))) / (max(VerticesTemp(:,2)) - min(VerticesTemp(:,2)));
            VerticesTemp(:, 2) = VerticesTemp(:, 2) * min(0.9, X/Y*3);
            VerticesTemp(:, 2) = VerticesTemp(:, 2) + (1-X/Y*3)/2;
            patch(app.UIAxesTopo, 'Faces', app.Faces, 'Vertices', VerticesTemp, 'FaceVertexCData', DataToPlotAll, 'FaceColor', 'interp', 'EdgeColor', 'none');
            hold(app.UIAxesTopo, 'on');
            VerticesTemp(:, 2) = VerticesTemp(:, 2) + 1;
            patch(app.UIAxesTopo, 'Faces', app.Faces, 'Vertices', VerticesTemp, 'FaceVertexCData', DataToPlotGrad, 'FaceColor', 'interp', 'EdgeColor', 'none');
            VerticesTemp(:, 2) = VerticesTemp(:, 2) + 1;
            patch(app.UIAxesTopo, 'Faces', app.Faces, 'Vertices', VerticesTemp, 'FaceVertexCData', DataToPlotMag, 'FaceColor', 'interp', 'EdgeColor', 'none');
            hold(app.UIAxesTopo, 'off');colormap(app.UIAxesTopo, cmap_rbw(256));
        end
        
        function Plot_Cluster(app, Cls, Bic, TimeWin)
            app.UseImage = 0;
            ToShowWin = app.AllHFOView.toShow.ReSample * TimeWin;
            % Get Clusterr Signal
            ClsTemp = cell2mat(cellfun(@(x)x(:, Cls)',app.AllHFOView.toShow.Cls,'UniformOutput',false));
            if(isempty(app.VS_Cluster_HFO))
                % Move HFO to Center
                HFOcenter = cellfun(@(x)round(mean(x(:, 3:4), 'all')), app.AllHFOView.HFOIdx);
                HFOcenter = cell2mat(cellfun(@(x, y)repmat(x, length(y),1)',num2cell(HFOcenter),app.AllHFOView.toShow.ClsIdx,'UniformOutput',false));
                TemplateSig = cell2mat(cellfun(@(x, y)x(y, :)', reshape(app.AllHFOView.SpikeInHFOSignal, 1, []), reshape(app.AllHFOView.toShow.ClsIdx, 1, []), 'UniformOutput', false));
                TemplateSig = (TemplateSig - mean(TemplateSig)) ./ std(TemplateSig);
                app.VS_Cluster_HFO = process_bandpass('Compute', TemplateSig(round(size(TemplateSig,1) / 2 + (-ToShowWin/2-100:ToShowWin/2-1+100)), :)', app.AllHFOView.toShow.ReSample, 80, 200, 'bst-hfilter-2019', 1);
                app.VS_Cluster_HFO = app.VS_Cluster_HFO(:, 101:size(app.VS_Cluster_HFO,2)-100);
                app.VS_Cluster_HFO = cell2mat(cellfun(@(x, y)process_bandpass('Compute', y(round(x + (-ToShowWin/2-100:ToShowWin/2-1+100)), :)',...
                                                                              app.AllHFOView.toShow.ReSample, 80, 200, 'bst-hfilter-2019', 1)', ...
                                              num2cell(HFOcenter), mat2cell(TemplateSig, size(TemplateSig,1), ones(size(TemplateSig,2), 1)), 'UniformOutput', false))';
%                 app.VS_Cluster_HFO = cell2mat(cellfun(@(x, y)process_bandpass('Compute', y(round(round(size(y, 1) / 2) + (-ToShowWin/2-100:ToShowWin/2-1+100)), :)',...
%                                                                               app.AllHFOView.toShow.ReSample, 80, 200, 'bst-hfilter-2019', 1)', ...
%                                               num2cell(HFOcenter), mat2cell(TemplateSig, size(TemplateSig,1), ones(size(TemplateSig,2), 1)), 'UniformOutput', false))';
                app.VS_Cluster_HFO = app.VS_Cluster_HFO(:, 101:size(app.VS_Cluster_HFO,2)-100);
            end
            TemplateSig_HFO = cellfun(@(x)app.VS_Cluster_HFO(ClsTemp==x, :), num2cell(unique(ClsTemp)),'UniformOutput',false);
            if(isempty(app.VS_Cluster_Spike))
                TemplateSig = cell2mat(cellfun(@(x, y)x(y, :)', reshape(app.AllHFOView.SpikeInHFOSignal_S, 1, []), reshape(app.AllHFOView.toShow.ClsIdx, 1, []), 'UniformOutput', false));
                TemplateSig = (TemplateSig - mean(TemplateSig)) ./ std(TemplateSig);
                app.VS_Cluster_Spike = process_bandpass('Compute', TemplateSig(round(size(TemplateSig,1) / 2 + (-ToShowWin/2-100:ToShowWin/2-1+100)), :)', app.AllHFOView.toShow.ReSample, 3, 80, 'bst-hfilter-2019', 1);
                app.VS_Cluster_Spike = app.VS_Cluster_Spike(:, 101:size(app.VS_Cluster_Spike,2)-100);
            end
            TemplateSig_Spike = cellfun(@(x)app.VS_Cluster_Spike(ClsTemp==x, :), num2cell(unique(ClsTemp)),'UniformOutput',false);
            % Plot Clusterr Signal
            if(app.UseImage==0)
                app.AXES = app.UIAxesTemplate;
            else
                app.HFIG = figure('Visible','off','Position',app.UIAxesTemplate.Position);app.AXES = axes(app.HFIG, 'Position', [0 0 1 1]);
            end
            hold(app.AXES,'on');
            TemplatePlotRow = 4;TemplatePlotCol = max(5, ceil((Cls + 1)/TemplatePlotRow));
            for i=2:double(Cls + 1)
                Temp = floor(i/(TemplatePlotRow + 0.05));
                TempX = int64(round(ToShowWin*0.05:ToShowWin*(1+0.05)-1)) + Temp*round(ToShowWin*(1+0.05*2));
                TempY = TemplateSig_Spike{i-1};TempY = (TempY - mean(TempY,2))./std(TempY,[],2);TempY = (TempY - min(TempY,[],'all'))./(max(TempY,[],'all') - min(TempY,[],'all'));
                TempY = TempY*(1-0.05*2)*0.5 + (i-Temp*TemplatePlotRow-1) + 0.05;
                LineAlpha = min(1, max(0.05, 2 / size(TempY, 1)));
                plot(app.AXES, TempX, TempY, 'color', [1, 0, 0, LineAlpha], 'linewidth', 1, 'Tag', ['Spike_Template_',num2str(i-1)]);
                TempY = TemplateSig_HFO{i-1};TempY = (TempY - mean(TempY,2))./std(TempY,[],2);TempY = (TempY - min(TempY,[],'all'))./(max(TempY,[],'all') - min(TempY,[],'all'));
                TempY = TempY*(1-0.05*2)*0.5+0.5 + (i-Temp*TemplatePlotRow-1) + 0.05;
                plot(app.AXES, TempX, TempY, 'color', [0, 0, 1, LineAlpha], 'linewidth', 1, 'Tag', ['HFO_Template_',num2str(i-1)]);
            end
            app.AXES.XLim = [0, (1+0.05*2) * ToShowWin * TemplatePlotCol];
            % Plot Cluster Number
            TempX = round((1:(length(Bic)+1)) / (length(Bic)+1) * ToShowWin);TempX = TempX(1:end-1);
            TempY = Bic;TempY = (TempY - mean(TempY))./std(TempY);TempY = (TempY - min(TempY))./(max(TempY) - min(TempY));
            TempY = TempY*(1-0.2) + 0.1;
            plot(app.AXES, TempX, TempY, '-b', 'linewidth', 3);
            plot(app.AXES, TempX, TempY, '.g', 'markersize', 35, 'Tag', 'MarkerAll');
            plot(app.AXES, TempX(Cls), TempY(Cls), '.r', 'markersize', 25, 'Tag', 'MarkerBest');
             % Plot Grad
            for i=0:TemplatePlotRow
                plot(app.AXES, app.AXES.XLim, [i, i], 'k', 'linewidth',1.5);
            end
            for i=0:TemplatePlotCol
                plot(app.AXES, [i, i]*(ToShowWin *(1+0.05*2)), app.AXES.YLim, 'k', 'linewidth',1.5);
            end
            hold(app.AXES,'off');
            set(app.AXES,'YDir','reverse');
            % Plot Selected Cluster
            if(app.UseImage==0)
                Idx = find(app.AllHFOView.toShow.ClusterSelect==0);
                TempSpike = find(ismember(app.AXES.Children.get('Tag'),['Spike_Template_',num2str(Idx)]));
                TempHFO = find(ismember(app.AXES.Children.get('Tag'),['HFO_Template_',num2str(Idx)]));
                LineAlpha = min(1, max(0.035, 1 / length(TempHFO)));
                if(app.AllHFOView.toShow.ClusterSelect(Idx)==1)
                    app.AXES.Children(TempSpike).set('Color', [1, 0, 0, LineAlpha]);
                    app.AXES.Children(TempHFO).set('Color', [0, 0, 1, LineAlpha]);
                else
                    app.AXES.Children(TempSpike).set('Color', [0.5, 0.5, 0.5, LineAlpha]);
                    app.AXES.Children(TempHFO).set('Color', [0.5, 0.5, 0.5, LineAlpha]);
                end
            end
            % Plot Selected Cluster
            if(app.UseImage==1)
                Idx = find(app.AllHFOView.toShow.ClusterSelect==0);
                app.HFIG.Position(3) = diff(app.AXES.XLim);app.HFIG.Position(4) = app.UIAxesTemplate.Position(4) / app.UIAxesTemplate.Position(3) * app.HFIG.Position(3);
                app.ClusterImage = print(app.HFIG, '-opengl', '-r72', '-RGBImage');
                [S_X, S_Y, ~] = size(app.ClusterImage);
                X = round(S_X / 4);Y = round(S_Y / 5);
                Mask = uint8(zeros(S_X, S_Y, 3));
                for i=1:length(Idx)
                    Idx_Y = floor((Idx(i)) / 4) + 1;
                    Idx_X = mod((Idx(i)),  4) + 1;
                    Mask((Idx_X - 1) * X + 1: X * Idx_X, (Idx_Y - 1) * Y + 1 : Idx_Y * Y, :) = 128;
                end
                Temp = ones(size(app.ClusterImage, 1), size(app.ClusterImage, 2));
                Temp((sum(double(app.ClusterImage), 3) < 250 * 3) & (app.ClusterImage(:, :, 1) ~= 127) & (app.ClusterImage(:, :, 2) ~= 127) & (app.ClusterImage(:, :, 3) ~= 127)) = 0;
                Mask = Mask(1:S_X, 1:S_Y, :);Mask = uint8(double(Mask) .* repmat(Temp, 1, 1, 3));
                app.ClusterImage = uint8(mod(double(app.ClusterImage)+ double(Mask), 256));
                imshow(app.ClusterImage, 'Parent', app.UIAxesTemplate);
            end
        end
        
        function PlotShowInit(app)
            app.ListBox.Items = {};
            app.ListBox.Value = {};
        end
                
        function SpikeViewInit(app)
            app.AllSpikeView.Event.Slope = 2;app.Spike_SlopeEditField.Value = app.AllSpikeView.Event.Slope;
            app.AllSpikeView.Event.SlopeOn = 0;app.Spike_CheckBox_Slope.Value = app.AllSpikeView.Event.SlopeOn;
            app.AllSpikeView.Event.Amp = 2;app.Spike_AmpEditField.Value = app.AllSpikeView.Event.Amp;
            app.AllSpikeView.Event.AmpOn = 0;app.Spike_CheckBox_Amp.Value = app.AllSpikeView.Event.AmpOn;
            app.AllSpikeView.Event.Sharp = 2;app.Spike_SharpEditField.Value = app.AllSpikeView.Event.Sharp;
            app.AllSpikeView.Event.SharpOn = 0;app.Spike_CheckBox_Sharp.Value = app.AllSpikeView.Event.SharpOn;
            app.AllSpikeView.Event.Corr = 0.75;app.Spike_CorrEditField.Value = app.AllSpikeView.Event.Corr;
            app.AllSpikeView.Event.CorrOn = 0;app.Spike_CheckBox_Corr.Value = app.AllSpikeView.Event.CorrOn;
            app.AllSpikeView.Event.Dist = 1.5;app.Spike_DistEditField.Value = app.AllSpikeView.Event.Dist;
            app.AllSpikeView.Event.DistOn = 0;app.Spike_CheckBox_Dist.Value = app.AllSpikeView.Event.DistOn;
            app.AllSpikeView.Event.MinChan = 5;app.Spike_MinChanEditField.Value = app.AllSpikeView.Event.MinChan;
            app.AllSpikeView.Event.MinChanOn = 0;app.Spike_CheckBox_MinChan.Value = app.AllSpikeView.Event.MinChanOn;
            app.UIAxesTopo.Layout.Column = 4;app.UIAxesTopo.Layout.Row = 2;app.UIAxesTF.Visible = 'off';
            app.Spike_ShowTopo.Value = 0;
        end       
        
        function SpikeViewPlotInit(app)
            app.PlotShow.HpassOn = 1;app.CheckBox_HighPass.Value = app.PlotShow.HpassOn;
            app.PlotShow.LpassOn = 1;app.CheckBox_LowPass.Value = app.PlotShow.LpassOn;
            app.PlotShow.Lpass = 3;app.LowPassEditField.Value = app.PlotShow.Lpass;
            app.PlotShow.Hpass = 80;app.HighPassEditField.Value = app.PlotShow.Hpass;
            app.PlotShow.ShowInfo = 0;app.InfoButton.Value = app.PlotShow.ShowInfo;
            app.PlotShow.HideRejcet = 0;app.RejectCheckBox.Value = app.PlotShow.HideRejcet;
            app.PlotShow.HideUnDo = 0;app.UnDoCheckBox.Value = app.PlotShow.HideUnDo;
            app.PlotShow.HideAC = 0;app.AC_CheckBox.Value = app.PlotShow.HideAC;
            app.PlotShow.HideOL = 0;app.OL_CheckBox.Value = app.PlotShow.HideOL;
            app.PlotShow.TimeScale = 1;
            app.PlotShow.AmpNum = 1;
            app.PlotShow.AmpNumLast = 1;
            app.PlotShow.PointLine = round(median(app.AllSpikeView.toShow.EventTime));
            app.PlotShow.PointLineNew = round(median(app.AllSpikeView.toShow.EventTime));
        end
        
        function HFOViewInit(app)
            app.AllHFOView.Event.AmpSpike = 3;app.HFO_AmpSpikeEditField.Value = app.AllHFOView.Event.AmpSpike;
            app.AllHFOView.Event.AmpSpikeOn = 0;app.HFO_CheckBox_AmpSpike.Value = app.AllHFOView.Event.AmpSpikeOn;
            app.AllHFOView.Event.LLSpike = 0.5;app.HFO_LLSpikeEditField.Value = app.AllHFOView.Event.LLSpike;
            app.AllHFOView.Event.LLSpikeOn = 0;app.HFO_CheckBox_LLSpike.Value = app.AllHFOView.Event.LLSpikeOn;
            app.AllHFOView.Event.AmpHFO = 3;app.HFO_AmpHFOEditField.Value = app.AllHFOView.Event.AmpHFO;
            app.AllHFOView.Event.AmpHFOOn = 0;app.HFO_CheckBox_AmpHFO.Value = app.AllHFOView.Event.AmpHFOOn;
            app.AllHFOView.Event.LLHFO = 1.25;app.HFO_LLHFOEditField.Value = app.AllHFOView.Event.LLHFO;
            app.AllHFOView.Event.LLHFOOn = 0;app.HFO_CheckBox_LLHFO.Value = app.AllHFOView.Event.LLHFOOn;
            app.AllHFOView.Event.EntropySpike = 0.4;app.HFO_EntropySpikeEditField.Value = app.AllHFOView.Event.EntropySpike;
            app.AllHFOView.Event.EntropySpikeOn = 0;app.HFO_CheckBox_EntropySpike.Value = app.AllHFOView.Event.EntropySpikeOn;
            app.AllHFOView.Event.EntropyHFO = 0.4;app.HFO_EntropyHFOEditField.Value = app.AllHFOView.Event.EntropyHFO;
            app.AllHFOView.Event.EntropyHFOOn = 0;app.HFO_CheckBox_EntropyHFO.Value = app.AllHFOView.Event.EntropyHFOOn;

        end  
        
        function HFOViewPlotInit(app)
            app.PlotShow.HpassOn = 1;app.CheckBox_HighPass.Value = app.PlotShow.HpassOn;
            app.PlotShow.LpassOn = 1;app.CheckBox_LowPass.Value = app.PlotShow.LpassOn;
            app.PlotShow.Lpass = 80;app.LowPassEditField.Value = app.PlotShow.Lpass;
            app.PlotShow.Hpass = 200;app.HighPassEditField.Value = app.PlotShow.Hpass;
            app.PlotShow.ShowSpike = 0;app.HFO_ShowSpikeCheckBox.Value = app.PlotShow.ShowSpike;
            app.PlotShow.ShowInfo = 0;app.InfoButton.Value = app.PlotShow.ShowInfo;
            app.PlotShow.HideRejcet = 0;app.RejectCheckBox.Value = app.PlotShow.HideRejcet;
            app.PlotShow.HideUnDo = 0;app.UnDoCheckBox.Value = app.PlotShow.HideUnDo;
            app.PlotShow.HideAC = 0;app.AC_CheckBox.Value = app.PlotShow.HideAC;
            app.PlotShow.HideOL = 0;app.OL_CheckBox.Value = app.PlotShow.HideOL;
            app.PlotShow.TimeScale = 1;
            app.PlotShow.AmpNum = 1;
            app.PlotShow.AmpNumLast = 1;
            app.PlotShow.PointLine = round(mean(app.AllHFOView.toShow.EventTime{1}(1,:)));
            app.PlotShow.PointLineNew = round(mean(app.AllHFOView.toShow.EventTime{1}(1,:)));
        end
        
        function UpdateEvent(app)
            if(app.iState==1)
                app.ListBox.Items= app.AllSpikeView.Event.EventName(app.AllSpikeView.Event.EventToDisp);
                EventIdx = find(cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName));
                % Updata Features value
                if(~isempty(app.AllSpikeView.Event.EventValue))
                    Temp = cat(1,app.AllSpikeView.toShow.EventAmp{EventIdx}{:});
                    app.Spike_AmpText.Value = num2str(mean(Temp(Temp~=0)),2);
                    Temp = cat(1,app.AllSpikeView.toShow.EventSlope{EventIdx}{:});
                    app.Spike_SlopeText.Value = num2str(mean(Temp(Temp~=0)),2);
                    Temp = cat(1,app.AllSpikeView.toShow.EventSharp{EventIdx}{:});
                    app.Spike_SharpText.Value = num2str(mean(Temp(Temp~=0)),2);
                    Temp = cat(1,app.AllSpikeView.toShow.Corr{EventIdx}{:});Temp = max(Temp(:, app.AllSpikeView.toShow.TemplateSelect>0),[],2);
                    app.Spike_CorrText.Value = num2str(mean(Temp(Temp~=0)),2);
                    Temp = cat(1,app.AllSpikeView.toShow.Dist{EventIdx}{:});Temp = min(Temp(:, app.AllSpikeView.toShow.TemplateSelect>0),[],2);
                    app.Spike_DistText.Value = num2str(mean(Temp(Temp~=100)),2);
                    Temp = length(cat(2,app.AllSpikeView.toShow.SpikeChannel{EventIdx}{:}));
                    app.Spike_MinChanText.Value = num2str(Temp,2);
                    app.ListBox.Value = app.AllSpikeView.Event.EventValue;
                    app.ListBox.scroll(app.ListBox.Items{cellfun(@(x)strcmp(x,app.ListBox.Value),app.ListBox.Items)});  
                end
            elseif(app.iState==2)
                app.ListBox.Items= app.AllHFOView.Event.EventName(find(app.AllHFOView.Event.EventToDisp));
                EventIdx = find(cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName));
                % Updata Features value
                if(~isempty(app.AllHFOView.Event.EventValue))
                    app.HFO_AmpSpikeText.Value = num2str(round(mean(app.AllHFOView.toShow.PeakAmp_Spike{EventIdx})*100)/100);
                    app.HFO_LLSpikeText.Value = num2str(round(mean(app.AllHFOView.toShow.LL_Spike{EventIdx})*100)/100);
                    app.HFO_LLHFOText.Value = num2str(round(mean(app.AllHFOView.toShow.LL_HFO{EventIdx})*100)/100);
                    app.HFO_AmpHFOext.Value = num2str(round(mean(app.AllHFOView.toShow.HilAmp_HFO{EventIdx})*100)/100);
                    app.HFO_EntropySpikeText.Value = num2str(round(mean(app.AllHFOView.toShow.TFEntropy_Spike{EventIdx})*100)/100);
                    app.HFO_EntropyHFOText.Value = num2str(round(mean(app.AllHFOView.toShow.TFEntropy_HFO{EventIdx})*100)/100);
                    app.ListBox.Value = app.AllHFOView.Event.EventValue;
                    app.ListBox.scroll(app.ListBox.Items{cellfun(@(x)strcmp(x,app.ListBox.Value),app.ListBox.Items)});  
                end
            end      
        end
        
        function EventSelected(app)
            if(app.iState==1)
                app.AllSpikeView.Event.EventToDisp = true(length(app.AllSpikeView.Event.EventName),1);
                if(isempty(app.AllSpikeView.Event.EventValue))
                   app.AllSpikeView.Event.EventValue = app.AllSpikeView.Event.EventName(app.AllSpikeView.Event.EventToDisp);
                   app.AllSpikeView.Event.EventValue  = app.AllSpikeView.Event.EventValue{1};
                end
                % Hide Reject
                if(app.PlotShow.HideRejcet)
                    EventNameTemp = app.AllSpikeView.Event.EventName;EventNameTemp(~app.AllSpikeView.Event.EventToDisp) = {'Reject'};
                    app.AllSpikeView.Event.EventToDisp = ~contains(EventNameTemp,'Reject');
                    temp = find(app.AllSpikeView.Event.EventToDisp);
                    if(isempty(temp))
                        app.AllSpikeView.Event.EventValue = [];
                    else
                       [~,x]=min(abs(find(cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName))-temp));
                       app.AllSpikeView.Event.EventValue = app.AllSpikeView.Event.EventName{temp(x)};                  
                    end
                end 
                % Hide UnDo
                if(app.PlotShow.HideUnDo)
                    EventNameTemp = app.AllSpikeView.Event.EventName;EventNameTemp(~app.AllSpikeView.Event.EventToDisp) = {'Temp'};
                    app.AllSpikeView.Event.EventToDisp = contains(EventNameTemp,'Accept')|contains(EventNameTemp,'Reject');
                    temp = find(app.AllSpikeView.Event.EventToDisp);
                    if(isempty(temp))
                        app.AllSpikeView.Event.EventValue = [];
                    else
                       [~,x]=min(abs(find(cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName))-temp));
                       app.AllSpikeView.Event.EventValue = app.AllSpikeView.Event.EventName{temp(x)};                  
                    end
                end 
                % Hide AC
                if(app.PlotShow.HideAC)
                    EventNameTemp = app.AllSpikeView.Event.EventName;EventNameTemp(~app.AllSpikeView.Event.EventToDisp) = {'AC_'};
                    app.AllSpikeView.Event.EventToDisp = ~contains(EventNameTemp,'AC_');
                    temp = find(app.AllSpikeView.Event.EventToDisp);
                    if(isempty(temp))
                        app.AllSpikeView.Event.EventValue = [];
                    else
                       [~,x]=min(abs(find(cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName))-temp));
                       app.AllSpikeView.Event.EventValue = app.AllSpikeView.Event.EventName{temp(x)};                  
                    end
                end
                % Hide OL
                if(app.PlotShow.HideOL)
                    EventNameTemp = app.AllSpikeView.Event.EventName;EventNameTemp(~app.AllSpikeView.Event.EventToDisp) = {'OL_'};
                    app.AllSpikeView.Event.EventToDisp = ~contains(EventNameTemp,'OL_');
                    temp = find(app.AllSpikeView.Event.EventToDisp);
                    if(isempty(temp))
                        app.AllSpikeView.Event.EventValue = [];
                    else
                       [~,x]=min(abs(find(cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName))-temp));
                       app.AllSpikeView.Event.EventValue = app.AllSpikeView.Event.EventName{temp(x)};                  
                    end
                end
                % Using Features Threshold
                Idx = cellfun(@(x)cellfun(@(y)y>0,x,'UniformOutput', false),app.AllSpikeView.toShow.EventAmp, 'UniformOutput', false);
                % Amp
                if(app.AllSpikeView.Event.AmpOn)
                    Idx = cellfun(@(x,y)cellfun(@(xx,yy)yy&(xx>=app.AllSpikeView.Event.Amp),x,y, 'UniformOutput', false),app.AllSpikeView.toShow.EventAmp,Idx,'UniformOutput', false);
                end
                % Slope
                if(app.AllSpikeView.Event.SlopeOn)
                    Idx = cellfun(@(x,y)cellfun(@(xx,yy)yy&(xx>=app.AllSpikeView.Event.Slope),x,y,'UniformOutput', false),app.AllSpikeView.toShow.EventSlope,Idx,'UniformOutput', false);
                end  
                % Sharp
                if(app.AllSpikeView.Event.SharpOn)
                    Idx = cellfun(@(x,y)cellfun(@(xx,yy)yy&(xx>=app.AllSpikeView.Event.Sharp),x,y,'UniformOutput', false),app.AllSpikeView.toShow.EventSharp,Idx,'UniformOutput', false);
                end
                % Using Similarity
                % Corr
                if(app.AllSpikeView.Event.CorrOn)
                    Idx = cellfun(@(x,y)cellfun(@(xx,yy)yy&(max(xx(:,app.AllSpikeView.toShow.TemplateSelect>0), [], 2)>=app.AllSpikeView.Event.Corr),x,y,'UniformOutput', false),app.AllSpikeView.toShow.Corr,Idx,'UniformOutput', false);
                else
                    Idx = cellfun(@(x,y)cellfun(@(xx,yy)yy&(max(xx(:,app.AllSpikeView.toShow.TemplateSelect>0), [], 2)>=0.75),x,y,'UniformOutput', false),app.AllSpikeView.toShow.Corr,Idx,'UniformOutput', false);
                end
                % Dist
                if(app.AllSpikeView.Event.DistOn)
                    Idx = cellfun(@(x,y)cellfun(@(xx,yy)yy&(min(xx(:,app.AllSpikeView.toShow.TemplateSelect>0), [], 2)<=app.AllSpikeView.Event.Dist),x,y,'UniformOutput', false),app.AllSpikeView.toShow.Dist,Idx,'UniformOutput', false);
                else
                    Idx = cellfun(@(x,y)cellfun(@(xx,yy)yy&(min(xx(:,app.AllSpikeView.toShow.TemplateSelect>0), [], 2)<=2.5),x,y,'UniformOutput', false),app.AllSpikeView.toShow.Dist,Idx,'UniformOutput', false);
                end
                % Min Channel
                app.AllSpikeView.SpikeIdxToShow = Idx;
                if(app.AllSpikeView.Event.MinChanOn)
                    app.AllSpikeView.Event.EventToDisp = reshape(app.AllSpikeView.Event.EventToDisp, [], 1)&reshape(cellfun(@(x)any(cellfun(@(xx)length(find(xx))>=ceil(app.AllSpikeView.Event.MinChan/2),x))&length(find(cat(1, x{:})))>=app.AllSpikeView.Event.MinChan,Idx), [], 1);
                else
                    app.AllSpikeView.Event.EventToDisp = reshape(app.AllSpikeView.Event.EventToDisp, [], 1)&reshape(cellfun(@(x)any(cellfun(@(y)any(y),x)),Idx), [], 1);
                end
                temp = find(app.AllSpikeView.Event.EventToDisp);
                if(isempty(temp))
                    app.AllSpikeView.Event.EventValue = [];
                else
                   [~,x]=min(abs(find(cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName))-temp));
                   app.AllSpikeView.Event.EventValue = app.AllSpikeView.Event.EventName{temp(x)};                  
                end
            elseif(app.iState==2)
                app.AllHFOView.Event.EventToDisp = true(length(app.AllHFOView.Event.EventName),1);
                if(isempty(app.AllHFOView.Event.EventValue))
                   app.AllHFOView.Event.EventValue = app.AllHFOView.Event.EventName(app.AllHFOView.Event.EventToDisp);
                   app.AllHFOView.Event.EventValue  = app.AllHFOView.Event.EventValue{1};
                end
                % Hide Reject
                if(app.PlotShow.HideRejcet)
                    EventNameTemp = app.AllHFOView.Event.EventName;EventNameTemp(~app.AllHFOView.Event.EventToDisp) = {'Reject'};
                    app.AllHFOView.Event.EventToDisp = ~contains(EventNameTemp,'Reject');
                    temp = find(app.AllHFOView.Event.EventToDisp);
                    if(isempty(temp))
                        app.AllHFOView.Event.EventValue = [];
                    else
                       [~,x]=min(abs(find(cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName))-temp));
                       app.AllHFOView.Event.EventValue = app.AllHFOView.Event.EventName{temp(x)};                  
                    end
                end 
                % Hide UnDo
                if(app.PlotShow.HideUnDo)
                    EventNameTemp = app.AllHFOView.Event.EventName;EventNameTemp(~app.AllHFOView.Event.EventToDisp) = {'Temp'};
                    app.AllHFOView.Event.EventToDisp = contains(EventNameTemp,'Accept')|contains(EventNameTemp,'Reject');
                    temp = find(app.AllHFOView.Event.EventToDisp);
                    if(isempty(temp))
                        app.AllHFOView.Event.EventValue = [];
                    else
                       [~,x]=min(abs(find(cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName))-temp));
                       app.AllHFOView.Event.EventValue = app.AllHFOView.Event.EventName{temp(x)};                  
                    end
                end 
                % Hide AC
                if(app.PlotShow.HideAC)
                    EventNameTemp = app.AllHFOView.Event.EventName;EventNameTemp(~app.AllHFOView.Event.EventToDisp) = {'AC_'};
                    app.AllHFOView.Event.EventToDisp = ~contains(EventNameTemp,'AC_');
                    temp = find(app.AllHFOView.Event.EventToDisp);
                    if(isempty(temp))
                        app.AllHFOView.Event.EventValue = [];
                    else
                       [~,x]=min(abs(find(cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName))-temp));
                       app.AllHFOView.Event.EventValue = app.AllHFOView.Event.EventName{temp(x)};                  
                    end
                end
                % Hide OL
                if(app.PlotShow.HideOL)
                    EventNameTemp = app.AllHFOView.Event.EventName;EventNameTemp(~app.AllHFOView.Event.EventToDisp) = {'OL_'};
                    app.AllHFOView.Event.EventToDisp = ~contains(EventNameTemp,'OL_');
                    temp = find(app.AllHFOView.Event.EventToDisp);
                    if(isempty(temp))
                        app.AllHFOView.Event.EventValue = [];
                    else
                       [~,x]=min(abs(find(cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName))-temp));
                       app.AllHFOView.Event.EventValue = app.AllHFOView.Event.EventName{temp(x)};                  
                    end
                end
                % Using Features Threshold
                TempFeature = cellfun(@(x)zeros(1, length(x)),app.AllHFOView.toShow.LL_HFO,'UniformOutput',false);
                Idx = cellfun(@(x)1:length(x),app.AllHFOView.toShow.LL_HFO,'UniformOutput',false);
                % HFO_LL
                if(app.AllHFOView.Event.LLHFOOn)
                    TempIdx = cellfun(@(x,y)x(y)>=app.AllHFOView.Event.LLHFO,app.AllHFOView.toShow.LL_HFO,Idx,'UniformOutput',false);
                    Idx=cellfun(@(x,y)y(x(y)>=app.AllHFOView.Event.LLHFO),app.AllHFOView.toShow.LL_HFO,Idx,'UniformOutput',false);
                    Temp = cellfun(@(x)(x-mean(x)+1e-12)/(std(x)+1e-12),app.AllHFOView.toShow.LL_HFO,'UniformOutput',false);
                    TempFeature = cellfun(@(x,y,z,z0)x(z)+y(z0),Temp,TempFeature,Idx,TempIdx,'UniformOutput',false);
                end
                % HFO HIL Amp
                if(app.AllHFOView.Event.AmpHFOOn)
                    TempIdx = cellfun(@(x,y)x(y)>=app.AllHFOView.Event.AmpHFO,app.AllHFOView.toShow.HilAmp_HFO,Idx,'UniformOutput',false);
                    Idx=cellfun(@(x,y)y(x(y)>=app.AllHFOView.Event.AmpHFO),app.AllHFOView.toShow.HilAmp_HFO,Idx,'UniformOutput',false);
                    Temp = cellfun(@(x)(x-mean(x)+1e-12)/(std(x)+1e-12),app.AllHFOView.toShow.HilAmp_HFO,'UniformOutput',false);
                    TempFeature = cellfun(@(x,y,z,z0)x(z)+y(z0),Temp,TempFeature,Idx,TempIdx,'UniformOutput',false);
                end
                % HFO TF entropy
                if(app.AllHFOView.Event.EntropyHFOOn)
                    TempIdx = cellfun(@(x,y)x(y)>=app.AllHFOView.Event.EntropyHFO,app.AllHFOView.toShow.TFEntropy_HFO,Idx,'UniformOutput',false);
                    Idx=cellfun(@(x,y)y(x(y)>=app.AllHFOView.Event.EntropyHFO),app.AllHFOView.toShow.TFEntropy_HFO,Idx,'UniformOutput',false);
                    Temp = cellfun(@(x)(x-mean(x)+1e-12)/(std(x)+1e-12),app.AllHFOView.toShow.TFEntropy_HFO,'UniformOutput',false);
                    TempFeature = cellfun(@(x,y,z,z0)x(z)+y(z0),Temp,TempFeature,Idx,TempIdx,'UniformOutput',false);
                end
                % Spike_LL
                if(app.AllHFOView.Event.LLSpikeOn)
                    TempIdx = cellfun(@(x,y)x(y)>=app.AllHFOView.Event.LLSpike,app.AllHFOView.toShow.LL_Spike,Idx,'UniformOutput',false);
                    Idx=cellfun(@(x,y)y(x(y)>=app.AllHFOView.Event.LLSpike),app.AllHFOView.toShow.LL_Spike,Idx,'UniformOutput',false);
                    Temp = cellfun(@(x)(x-mean(x)+1e-12)/(std(x)+1e-12),app.AllHFOView.toShow.LL_Spike,'UniformOutput',false);
                    TempFeature = cellfun(@(x,y,z,z0)x(z)+y(z0),Temp,TempFeature,Idx,TempIdx,'UniformOutput',false);
                end
                % Spike Amp
                if(app.AllHFOView.Event.AmpSpikeOn)
                    TempIdx = cellfun(@(x,y)x(y)>=app.AllHFOView.Event.AmpSpike,app.AllHFOView.toShow.PeakAmp_Spike,Idx,'UniformOutput',false);
                    Idx=cellfun(@(x,y)y(x(y)>=app.AllHFOView.Event.AmpSpike),app.AllHFOView.toShow.PeakAmp_Spike,Idx,'UniformOutput',false);
                    Temp = cellfun(@(x)(x-mean(x)+1e-12)/(std(x)+1e-12),app.AllHFOView.toShow.PeakAmp_Spike,'UniformOutput',false);
                    TempFeature = cellfun(@(x,y,z,z0)x(z)+y(z0),Temp,TempFeature,Idx,TempIdx,'UniformOutput',false);
                end
                % Spike TF entropy
                if(app.AllHFOView.Event.EntropySpikeOn)
                    TempIdx = cellfun(@(x,y)x(y)>=app.AllHFOView.Event.EntropySpike,app.AllHFOView.toShow.TFEntropy_Spike,Idx,'UniformOutput',false);
                    Idx=cellfun(@(x,y)y(x(y)>=app.AllHFOView.Event.EntropySpike),app.AllHFOView.toShow.TFEntropy_Spike,Idx,'UniformOutput',false);
                    Temp = cellfun(@(x)(x-mean(x)+1e-12)/(std(x)+1e-12),app.AllHFOView.toShow.TFEntropy_Spike,'UniformOutput',false);
                    TempFeature = cellfun(@(x,y,z,z0)x(z)+y(z0),Temp,TempFeature,Idx,TempIdx,'UniformOutput',false);
                end
                [~, TempFeature] = cellfun(@(x)sort(x, 'descend'),TempFeature,'UniformOutput',false);
                app.AllHFOView.HFOIdxToShow = cellfun(@(x, y)x(y),Idx,TempFeature,'UniformOutput',false);
                app.AllHFOView.Event.EventToDisp = reshape(app.AllHFOView.Event.EventToDisp, [], 1) & reshape(cellfun(@(x)~isempty(x),Idx), [], 1) & ...
                                                   reshape(cellfun(@(x)length(find(ismember(x(:, app.AllHFOView.toShow.Best_Cls), find(app.AllHFOView.toShow.ClusterSelect==1)))) >= 1,app.AllHFOView.toShow.Cls), [], 1);
                temp = find(app.AllHFOView.Event.EventToDisp);
                if(isempty(temp))
                    app.AllHFOView.Event.EventValue = [];
                else
                   [~,x]=min(abs(find(cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName))-temp));
                   app.AllHFOView.Event.EventValue = app.AllHFOView.Event.EventName{temp(x)};                  
                end
            end 
        end
    end
    
    methods (Access = public)
        

        function ModeInit(app)
            switch app.Mode
                case 1
                    app.State = 5;
                    % Change the UIaxes
                    app.iStateView = 0;
                    app.UIAxesVS.clo;app.UIAxesMEG.clo;app.UIAxesTF.clo;app.UIAxesTemplate.clo;
                    app.UIAxesVS.Layout.Column = [1,5];app.UIAxesVS.Layout.Row = 1;app.UIAxesVS.Visible = 'on';
                    app.UIAxesMEG.Layout.Column = 5;app.UIAxesMEG.Layout.Row = 2;app.UIAxesMEG.Visible = 'off';
                    app.UIAxesTF.Layout.Column = 5;app.UIAxesTF.Layout.Row = 2;app.UIAxesTF.Visible = 'off';
                    app.UIAxesTemplate.Layout.Column = 5;app.UIAxesTemplate.Layout.Row = 2;app.UIAxesTemplate.Visible = 'off';
                    app.VSButton.Visible = 'on';app.TFButton.Visible = 'on';app.TemplateButton.Visible = 'on';app.MEGButton.Visible = 'on';
                    app.UIAxesTopo.Layout.Column = 5;app.UIAxesTopo.Layout.Row = 2;app.UIAxesTopo.Visible = 'off';
                    app.TemplateButton.Value = 0;app.MEGButton.Value = 0;app.VSButton.Value = 1;app.TemplateButton.Value = 0;app.TemplateButton.Text = 'Cluster';
                    app.VSButton.Layout.Column = 1;app.TFButton.Layout.Column = 2;app.MEGButton.Layout.Column = 3;app.TemplateButton.Layout.Column = 4;
                    % Change the Col and Row of UIaxes
                    app.AxViewNum = 2;app.View_DropDown_Columns.Value = '2';app.View_DropDown_Columns.Enable = 1;
                    app.AxView = 16;app.View_DropDown_Rows.Value = '16';app.View_DropDown_Rows.Enable = 1;
                    app.iState = 2;
                    % Change Features View
                    % For Spike
                    app.Spike_AccpetButton.Visible = 0;
                    app.Spike_RejectButton.Visible = 0;
                    app.Spike_ShowTopo.Visible = 0;
                    app.Spike_AmpEditField.Visible = 0;
                    app.Spike_AmpText.Visible = 0;
                    app.Spike_CheckBox_Amp.Visible = 0;
                    app.Spike_SlopeEditField.Visible = 0;
                    app.Spike_SlopeText.Visible = 0;
                    app.Spike_CheckBox_Slope.Visible = 0;
                    app.Spike_SharpEditField.Visible = 0;
                    app.Spike_SharpText.Visible = 0;
                    app.Spike_CheckBox_Sharp.Visible = 0;
                    app.Spike_CorrEditField.Visible = 0;
                    app.Spike_CorrText.Visible = 0;
                    app.Spike_CheckBox_Corr.Visible = 0;
                    app.Spike_DistEditField.Visible = 0;
                    app.Spike_DistText.Visible = 0;
                    app.Spike_CheckBox_Dist.Visible = 0;
                    app.Spike_MinChanEditField.Visible = 0;
                    app.Spike_MinChanText.Visible = 0;
                    app.Spike_CheckBox_MinChan.Visible = 0;
                    app.Spike_TimeFeaturesLabel.Visible = 0;
                    app.Spike_SimilarityFeaturesLabel.Visible = 0;
                    % For HFO
                    app.HFO_AccpetButton.Visible = 1;
                    app.HFO_RejectButton.Visible = 1;
                    app.HFO_ShowSpikeCheckBox.Visible = 1;
                    app.HFO_TimeFeaturesLabel.Visible = 1;
                    app.HFO_AmpSpikeEditField.Visible = 1;
                    app.HFO_AmpSpikeText.Visible = 1;
                    app.HFO_CheckBox_AmpSpike.Visible = 1;
                    app.HFO_LLSpikeEditField.Visible = 1;
                    app.HFO_LLSpikeText.Visible = 1;
                    app.HFO_CheckBox_LLSpike.Visible = 1;
                    app.HFO_LLHFOEditField.Visible = 1;
                    app.HFO_LLHFOText.Visible = 1;
                    app.HFO_CheckBox_LLHFO.Visible = 1;
                    app.HFO_AmpHFOEditField.Visible = 1;
                    app.HFO_AmpHFOext.Visible = 1;
                    app.HFO_CheckBox_AmpHFO.Visible = 1;
                    app.HFO_TimeFeequencyFeaturesLabel.Visible = 1;
                    app.HFO_EntropySpikeEditField.Visible = 1;
                    app.HFO_EntropySpikeText.Visible = 1;
                    app.HFO_CheckBox_EntropySpike.Visible = 1;
                    app.HFO_EntropyHFOEditField.Visible = 1;
                    app.HFO_EntropyHFOText.Visible = 1;
                    app.HFO_CheckBox_EntropyHFO.Visible = 1;
                    % Load HFO For View
                    LoadHFOAll(app);
                    HFOViewInit(app);
                    HFOViewPlotInit(app);
                    app.ListBox.Items = app.AllHFOView.Event.EventName;
                    app.AllHFOView.Event.EventValue = app.AllHFOView.Event.EventName{1};
                    app.AllHFOView.Event.EventToDisp = true(length(app.AllHFOView.Event.EventName),1);
                    app.ListBox.Value = app.AllHFOView.Event.EventValue;
                    app.AllHFOView.HFOIdxToShow = cellfun(@(x)1:length(x),app.AllHFOView.toShow.LL_HFO,'UniformOutput',false);
                    UpdatePlot(app, 0);
                    UpdateEvent(app);
                case 2 
                    app.State = 4;
                    % Change the UIaxes
                    app.iStateView = 1;
                    app.UIAxesVS.clo;app.UIAxesMEG.clo;app.UIAxesTF.clo;app.UIAxesTemplate.clo;
                    app.UIAxesMEG.Layout.Column = [1,5];app.UIAxesMEG.Layout.Row = 1;app.UIAxesMEG.Visible = 'on';
                    app.UIAxesVS.Layout.Column = 5;app.UIAxesVS.Layout.Row = 2;app.UIAxesVS.Visible = 'off';
                    app.UIAxesTF.Layout.Column = 5;app.UIAxesTF.Layout.Row = 2;app.UIAxesTF.Visible = 'off';
                    app.UIAxesTemplate.Layout.Column = 5;app.UIAxesTemplate.Layout.Row = 2;app.UIAxesTemplate.Visible = 'off';
                    app.VSButton.Visible = 'off';app.TFButton.Visible = 'off';app.TemplateButton.Visible = 'on';app.MEGButton.Visible = 'on';
                    app.TemplateButton.Value = 0;app.MEGButton.Value = 1;app.TemplateButton.Text = 'Template';
                    app.MEGButton.Layout.Column = 1;app.TemplateButton.Layout.Column = 2;app.VSButton.Layout.Column = 3;app.TFButton.Layout.Column = 4;
                    % Change the Col and Row of UIaxes
                    app.AxViewNum = 3;app.View_DropDown_Columns.Value = '3';app.View_DropDown_Columns.Enable = 1;
                    app.AxView = 39;app.View_DropDown_Rows.Value = '39';app.View_DropDown_Rows.Enable = 0;
                    app.iState = 1;
                    % Change Features View
                    % For Spike
                    app.Spike_AccpetButton.Visible = 1;
                    app.Spike_RejectButton.Visible = 1;
                    app.Spike_ShowTopo.Visible = 1;
                    app.Spike_AmpEditField.Visible = 1;
                    app.Spike_AmpText.Visible = 1;
                    app.Spike_CheckBox_Amp.Visible = 1;
                    app.Spike_SlopeEditField.Visible = 1;
                    app.Spike_SlopeText.Visible = 1;
                    app.Spike_CheckBox_Slope.Visible = 1;
                    app.Spike_SharpEditField.Visible = 1;
                    app.Spike_SharpText.Visible = 1;
                    app.Spike_CheckBox_Sharp.Visible = 1;
                    app.Spike_CorrEditField.Visible = 1;
                    app.Spike_CorrText.Visible = 1;
                    app.Spike_CheckBox_Corr.Visible = 1;
                    app.Spike_DistEditField.Visible = 1;
                    app.Spike_DistText.Visible = 1;
                    app.Spike_CheckBox_Dist.Visible = 1;
                    app.Spike_MinChanEditField.Visible = 1;
                    app.Spike_MinChanText.Visible = 1;
                    app.Spike_CheckBox_MinChan.Visible = 1;
                    app.Spike_TimeFeaturesLabel.Visible = 1;
                    app.Spike_SimilarityFeaturesLabel.Visible = 1;
                    % For HFO
                    app.HFO_AccpetButton.Visible = 0;
                    app.HFO_RejectButton.Visible = 0;
                    app.HFO_ShowSpikeCheckBox.Visible = 0;
                    app.HFO_TimeFeaturesLabel.Visible = 0;
                    app.HFO_AmpSpikeEditField.Visible = 0;
                    app.HFO_AmpSpikeText.Visible = 0;
                    app.HFO_CheckBox_AmpSpike.Visible = 0;
                    app.HFO_LLSpikeEditField.Visible = 0;
                    app.HFO_LLSpikeText.Visible = 0;
                    app.HFO_CheckBox_LLSpike.Visible = 0;
                    app.HFO_LLHFOEditField.Visible = 0;
                    app.HFO_LLHFOText.Visible = 0;
                    app.HFO_CheckBox_LLHFO.Visible = 0;
                    app.HFO_AmpHFOEditField.Visible = 0;
                    app.HFO_AmpHFOext.Visible = 0;
                    app.HFO_CheckBox_AmpHFO.Visible = 0;
                    app.HFO_TimeFeequencyFeaturesLabel.Visible = 0;
                    app.HFO_EntropySpikeEditField.Visible = 0;
                    app.HFO_EntropySpikeText.Visible = 0;
                    app.HFO_CheckBox_EntropySpike.Visible = 0;
                    app.HFO_EntropyHFOEditField.Visible = 0;
                    app.HFO_EntropyHFOText.Visible = 0;
                    app.HFO_CheckBox_EntropyHFO.Visible = 0;
                    % Load Spike For View
                    LoadSpikeAll(app);
                    SpikeViewPlotInit(app);
                    SpikeViewInit(app);
                    app.ListBox.Items = app.AllSpikeView.Event.EventName;
                    app.AllSpikeView.Event.EventValue = app.AllSpikeView.Event.EventName{1};
                    app.AllSpikeView.Event.EventToDisp = true(length(app.AllSpikeView.Event.EventName),1);
                    app.ListBox.Value = app.AllSpikeView.Event.EventValue;
                    UpdatePlot(app, 0);
                    UpdateEvent(app);
            end
            StateUpdata(app);
        end
        
        function applySelectStudy(app)
            if(~isempty(app.SelectStudy) && any(app.SelectStudy))
                app.State = app.SpikeHFO_State;
                PlotShowInit(app);
            else
                app.State = 0;
            end
            StateUpdata(app);
        end
        
        function GroupProcessReturn(app)
            % shell cmd before process (copy file to remote)
            for i=1:length(app.db_process.process_info)
                process_info = app.db_process.process_info(i);
                if(app.db_process.is_remote == 1)
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Remote processing','Message', ...
                                            ['Device: ',num2str(i),'/',num2str(length(app.db_process.process_info)),... 
                                             '; Status: Copy file to remote'],'value',i/length(app.db_process.process_info));
                    system(process_info.cmd.pre_cmd);
                end
                close(wait_bar);
            end
            
            % process
            for i=1:length(app.db_process.process_info)
                process_info = app.db_process.process_info(i);
                if(app.db_process.is_remote == 0)
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Local processing','Message', ...
                                            ['Device: ',num2str(i),'/',num2str(length(app.db_process.process_info)),... 
                                             '; Status: Upload process cmd'],'value',i/length(app.db_process.process_info));
                    system(process_info.cmd.process_cmd)
                else
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Remote processing','Message', ...
                                            ['Device: ',num2str(i),'/',num2str(length(app.db_process.process_info)),... 
                                             '; Status: Copy file to remote'],'value',i/length(app.db_process.process_info));
                    system(process_info.cmd.process_cmd);
                    
                    
                end
                close(wait_bar)
            end
        end
        
        % Update State
        function StateUpdata(app)
            switch app.State
                case -1
                    % No Project is loaded
                    app.Arrow.Enable = 0;
                    app.Process.Enable = 0;
                    app.ProcessStatus.Enable = 0;
                    app.SaveProject.Enable = 0;
                    app.SubjectDown.Enable = 0;
                    app.MEGFilesButton.Enable = 0;
                    app.ViewHFOButton.Enable = 0;
                    app.ViewSpikeButton.Enable = 0;
                    app.ShowAllSoucesButton.Enable = 0;
                    app.ShowSoucesButton.Enable = 0;
                    app.PanelLeft.Visible = 0;
                    app.PanelRight.Visible = 0;
                case 0
                    % Project is loaded
                    app.Arrow.Enable = 0;
                    app.Process.Enable = 1;
                    app.ProcessStatus.Enable = 1;
                    app.SaveProject.Enable = 1;
                    app.SubjectDown.Enable = 1;
                    app.ShowAllSoucesButton.Enable = 0;
                    app.ShowSoucesButton.Enable = 0;
                    app.ViewHFOButton.Enable = 0;
                    app.ViewSpikeButton.Enable = 0;
                    app.PanelLeft.Visible = 0;
                    app.PanelRight.Visible = 0;
                    app.ViewHFOButton.BackgroundColor = [0.96,0.96,0.96];
                    app.ViewSpikeButton.BackgroundColor = [0.96,0.96,0.96];
                case 1
                    % MEG files are selected (all HFO/Spike Could not to be viewed)
                    app.ShowAllSoucesButton.Enable = 0;
                    app.ShowSoucesButton.Enable = 0;
                    app.Process.Enable = 1;
                    app.ProcessStatus.Enable = 1;
                    app.ViewHFOButton.Enable = 0;
                    app.ViewSpikeButton.Enable = 0;
                    app.Arrow.Enable = 0;
                    app.PanelLeft.Visible = 0;
                    app.PanelRight.Visible = 0;
                    app.ViewHFOButton.BackgroundColor = [0.96,0.96,0.96];
                    app.ViewSpikeButton.BackgroundColor = [0.96,0.96,0.96];
                case 2
                    % MEG files are selected (all Spike Could to be viewed, all HFO Could not to be viewed)
                    app.Arrow.Enable = 0;
                    app.ShowAllSoucesButton.Enable = 0;
                    app.ShowSoucesButton.Enable = 0;
                    app.Process.Enable = 1;
                    app.ProcessStatus.Enable = 1;
                    app.ViewHFOButton.Enable = 0;
                    app.ViewSpikeButton.Enable = 1;
                    app.Arrow.Enable = 0;
                    app.PanelLeft.Visible = 0;
                    app.PanelRight.Visible = 0;
                    app.ViewHFOButton.BackgroundColor = [0.96,0.96,0.96];
                    app.ViewSpikeButton.BackgroundColor = [0.96,0.96,0.96];
                case 3
                    % MEG files are selected (all HFO/Spike Could to be viewed)
                    app.Arrow.Enable = 0;
                    app.ShowAllSoucesButton.Enable = 0;
                    app.ShowSoucesButton.Enable = 0;
                    app.Process.Enable = 1;
                    app.ProcessStatus.Enable = 1;
                    app.ViewHFOButton.Enable = 1;
                    app.ViewSpikeButton.Enable = 1;
                    app.Arrow.Enable = 0;
                    app.PanelLeft.Visible = 0;
                    app.PanelRight.Visible = 0;
                    app.ViewHFOButton.BackgroundColor = [0.96,0.96,0.96];
                    app.ViewSpikeButton.BackgroundColor = [0.96,0.96,0.96];
                case 4    
                    % Select Spike View
                    app.Arrow.Enable = 0;
                    app.ShowAllSoucesButton.Enable = 0;
                    app.ShowSoucesButton.Enable = 0;
                    app.PanelLeft.Visible = 1;
                    app.PanelRight.Visible = 1;
                    app.ViewHFOButton.BackgroundColor = [0.96,0.96,0.96];
                    app.ViewSpikeButton.BackgroundColor = [0.64,0.99,0.51];
                case 5   
                    % Select HFO View
                    app.Arrow.Enable = 1;
                    app.ShowAllSoucesButton.Enable = 1;
                    app.ShowSoucesButton.Enable = 1;
                    app.PanelLeft.Visible = 1;
                    app.PanelRight.Visible = 1;
                    app.ViewHFOButton.BackgroundColor = [0.64,0.99,0.51];
                    app.ViewSpikeButton.BackgroundColor = [0.96,0.96,0.96];
            end
        end
        
        function ProcessLoading(app)
            WaitBar = uiprogressdlg(app.UIFigure,'Title','Loading','Message','Loading.....','Indeterminate','on');
            
            % Gui Init
            Protocols = load(bst_get('BrainstormDbFile'));
            SubjectNames = {app.db_subject_data.subject_name};
            app.SubjectDown.Items = cat(2,'None',SubjectNames);
            app.SubjectDown.Value = 'None';
           
            StateUpdata(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sshpass_cmd)
            % bst Init
            app.sshpass_cmd = sshpass_cmd;
            app.UIFigure.Name = 'EMHapp';
            app.State = -1;StateUpdata(app);
            WaitBar = uiprogressdlg(app.UIFigure,'Title','Initializeing','Message','Initializeing.....','Indeterminate','on');
            brainstorm setpath
            if ~brainstorm('status')
                brainstorm nogui
            end
            ft_defaults;
            emhapp_path = strrep(which('EMHapp.m'), [filesep, 'EMHapp.m'], '');
            addpath(fullfile(emhapp_path, 'Fun'));
            addpath(fullfile(emhapp_path, 'GUI'));
            
            % Param Initplot()
            app.Mode = 0;
            app.Interupt = 0;
            app.iState = 0;
            
            close(WaitBar);
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if(app.iState==1&&app.iStateView==1)
                Position = get(app.UIAxesMEG,'CurrentPoint');
                Position = Position([1,3]);
                if(Position(1)<app.UIAxesMEG.XAxis.Limits(end)&&Position(1)>app.UIAxesMEG.XAxis.Limits(1)&&app.iState~=0)
                    % Get the Point Line Position
                    EventIdx = cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName);
                    Sig = app.AllSpikeView.HFOinSpikeSignal{EventIdx};
                    SamplePoint = size(Sig{1}, 2);
                    SampleBeg = max(1,round(app.PlotShow.PointLine-SamplePoint*app.PlotShow.TimeScale/2));
                    SampleEnd = min(SamplePoint,round(app.PlotShow.PointLine+SamplePoint*app.PlotShow.TimeScale/2));
                    app.PlotShow.PointLineNew=mod(Position(1), (SampleEnd-SampleBeg+1));
                    UpdatePlot(app, 1);
                end
                if(app.Spike_ShowTopo.Value)
                    PlotCurrentTopo(app);
                end
            elseif(app.iState==2&&app.iStateView==0)
                Position = get(app.UIAxesVS,'CurrentPoint');
                Position = Position([1,3]);
                if(Position(1)<app.UIAxesVS.XAxis.Limits(end)&&Position(1)>app.UIAxesVS.XAxis.Limits(1)&&app.iState~=0)
                    % Get the Point Line Position
                    EventIdx = cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName);
                    Sig = app.AllHFOView.SpikeInHFOSignal{EventIdx};
                    SamplePoint = size(Sig, 2);
                    SampleBeg = max(1,round(app.PlotShow.PointLine-SamplePoint*app.PlotShow.TimeScale/2));
                    SampleEnd = min(SamplePoint,round(app.PlotShow.PointLine+SamplePoint*app.PlotShow.TimeScale/2));
                    app.PlotShow.PointLineNew=mod(Position(1), (SampleEnd-SampleBeg+1));
                    UpdatePlot(app, 1);
                end
            elseif(app.iState==2&&app.iStateView==1)
                Position = get(app.UIAxesMEG,'CurrentPoint');
                Position = Position([1,3]);
                if(Position(1)<app.UIAxesMEG.XAxis.Limits(end)&&Position(1)>app.UIAxesMEG.XAxis.Limits(1)&&app.iState~=0)
                    % Get the Point Line Position
                    EventIdx = cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName);
                    Sig = app.AllHFOView.SpikeInHFOSignal{EventIdx};
                    SamplePoint = size(Sig, 2);
                    SampleBeg = max(1,round(app.PlotShow.PointLine-SamplePoint*app.PlotShow.TimeScale/2));
                    SampleEnd = min(SamplePoint,round(app.PlotShow.PointLine+SamplePoint*app.PlotShow.TimeScale/2));
                    app.PlotShow.PointLineNew=mod(Position(1), (SampleEnd-SampleBeg+1));
                    UpdatePlot(app, 1);
                end
            elseif(app.iState==1&&app.iStateView==3)
                Position = get(app.UIAxesTemplate,'CurrentPoint');
                Position = Position([1,3]);
                % Get Selected Templates Index
                TemplatePlotRow = 6;TemplatePlotCol = max(6, ceil(length(app.AllSpikeView.toShow.Template)/TemplatePlotRow));
                Idx = floor(Position(1)/(size(app.AllSpikeView.toShow.Template{1}, 2)*(1+2*0.05))) * TemplatePlotRow + ceil(Position(2));
                app.AllSpikeView.toShow.TemplateSelect(Idx) = mod(1+app.AllSpikeView.toShow.TemplateSelect(Idx),2);
                % Plot Selected Templates
                Temp = find(ismember(app.UIAxesTemplate.Children.get('Tag'),['Template_',num2str(Idx)]));
                if(app.AllSpikeView.toShow.TemplateSelect(Idx)==1)
                    app.UIAxesTemplate.Children(Temp).Color = [0, 1, 0];  
                else
                    app.UIAxesTemplate.Children(Temp).Color = [0.5, 0.5, 0.5]; 
                end
                EventSelected(app);
                UpdateEvent(app);
            elseif(app.iState==2&&app.iStateView==3)
                Position = get(app.UIAxesTemplate,'CurrentPoint');
                Position = Position([1,3]);
                % Get Cluster Location 
                Marker = app.AXES.Children(contains(app.AXES.Children.get('Tag'), 'MarkerAll'));
                Marker_XData = Marker.XData;Marker_YData = Marker.YData;
                if(Position(1)>0 && Position(1)<Marker_XData(end)+Marker_XData(1) && Position(2) > 0 && Position(2) < diff(app.UIAxesTemplate.YLim) / diff(app.AXES.YLim))
                    [~, app.AllHFOView.toShow.Best_Cls] = min(abs(Position(1) - Marker_XData));
                    % Set Best Cluster
                    app.AllHFOView.toShow.ClusterSelect = ones(app.AllHFOView.toShow.Best_Cls, 1);
                    WaitBar = uiprogressdlg(app.UIFigure,'Title','Prepare Clusters','Message','Preparing Clusters','Indeterminate','on');
                    app.UIAxesTemplate.clo;
                    Plot_Cluster(app, app.AllHFOView.toShow.Best_Cls, app.AllHFOView.toShow.Bic, 0.5);
                    close(WaitBar);
                else
                    % Get Selected Cluster Index
                    ClusterPlotRow = 4;
                    Idx = floor(Position(1)/(app.AllHFOView.toShow.ReSample * 0.5 * (1+2*0.05))) * ClusterPlotRow + ceil(Position(2) / (diff(app.UIAxesTemplate.YLim) / diff(app.AXES.YLim))) - 1;
                    if(Idx <= length(app.AllHFOView.toShow.ClusterSelect))
                        app.AllHFOView.toShow.ClusterSelect(Idx) = mod(1+app.AllHFOView.toShow.ClusterSelect(Idx),2);
                        % Plot Selected Cluster
                        if(app.UseImage==1)
                            [S_X, S_Y, ~] = size(app.ClusterImage);
                            X = round(S_X / 4);Y = round(S_Y / 5);
                            Mask = uint8(zeros(S_X, S_Y, 3));
                            for i=1:length(Idx)
                                Idx_Y = floor((Idx(i)) / 4) + 1;
                                Idx_X = mod((Idx(i)),  4) + 1;
                                Mask((Idx_X - 1) * X + 1: X * Idx_X, (Idx_Y - 1) * Y + 1 : Idx_Y * Y, :) = 128;
                            end
                            Temp = ones(size(app.ClusterImage, 1), size(app.ClusterImage, 2));
                            Temp((sum(double(app.ClusterImage), 3) < 250 * 3) & (app.ClusterImage(:, :, 1) ~= 127) & (app.ClusterImage(:, :, 2) ~= 127) & (app.ClusterImage(:, :, 3) ~= 127)) = 0;
                            Mask = Mask(1:S_X, 1:S_Y, :);Mask = uint8(double(Mask) .* repmat(Temp, 1, 1, 3));
                            app.ClusterImage = uint8(mod(double(app.ClusterImage)+ double(Mask), 256));
                            imshow(app.ClusterImage, 'Parent', app.UIAxesTemplate,'Border','tight');
                        else
                            TempSpike = find(ismember(app.AXES.Children.get('Tag'),['Spike_Template_',num2str(Idx)]));
                            TempHFO = find(ismember(app.AXES.Children.get('Tag'),['HFO_Template_',num2str(Idx)]));
                            LineAlpha = min(1, max(0.035, 1 / length(TempHFO)));
                            if(app.AllHFOView.toShow.ClusterSelect(Idx)==1)
                                app.AXES.Children(TempSpike).set('Color', [1, 0, 0, LineAlpha]);
                                app.AXES.Children(TempHFO).set('Color', [0, 0, 1, LineAlpha]);
                            else
                                app.AXES.Children(TempSpike).set('Color', [0.5, 0.5, 0.5, LineAlpha]);
                                app.AXES.Children(TempHFO).set('Color', [0.5, 0.5, 0.5, LineAlpha]);
                            end
                        end
                    end
                end
                EventSelected(app);
                UpdateEvent(app);
            end
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            if(app.iState==1 && app.iStateView==1)
                Position = get(app.UIAxesMEG,'CurrentPoint');
                Position = Position([1,3]);
                Temp = Position(1)<app.UIAxesMEG.XAxis.Limits(end)&&Position(1)>app.UIAxesMEG.XAxis.Limits(1);
                if(Temp)
                    app.PlotShow.TimeScale = max(0.1,min(1,app.PlotShow.TimeScale-verticalScrollCount*0.1));  % 0~1
                    app.PlotShow.PointLine = app.PlotShow.PointLineNew;
                    if(app.PlotShow.TimeScale==1)
                        EventIdx = cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName);
                        app.PlotShow.PointLine = round(app.AllSpikeView.toShow.EventTime(EventIdx));
                    end
                    UpdatePlot(app, 0);
                end
            elseif(app.iState==2 && (app.iStateView==0 || app.iStateView==1))
                Position = get(app.UIAxesVS,'CurrentPoint');
                Position = Position([1,3]);
                Temp = Position(1)<app.UIAxesVS.XAxis.Limits(end)&&Position(1)>app.UIAxesVS.XAxis.Limits(1);
                if(Temp)
                    app.PlotShow.TimeScale = max(0.1,min(1,app.PlotShow.TimeScale-verticalScrollCount*0.1));  % 0~1
                    app.PlotShow.PointLine = app.PlotShow.PointLineNew;
                    if(app.PlotShow.TimeScale==1)
                        EventIdx = cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName);
                        app.PlotShow.PointLine = round(mean(app.AllHFOView.toShow.EventTime{EventIdx}(1,:)));
                    end
                    UpdatePlot(app, 0);
                end
                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(~isempty(app.SelectStudy) && any(app.SelectStudy))
                saveStudyAll(app);
            end
            delete(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if(strcmp(event.Key, 'shift'))
                app.isShift = 1;
            end
            
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            % Short Keys
            key = event.Key;
            switch key
                % Short Keys for Larger Amp
                case 'w'
                    if((app.iStateView==0||app.iStateView==1))
                        app.PlotShow.AmpNumLast = app.PlotShow.AmpNum;
                        app.PlotShow.AmpNum = app.PlotShow.AmpNum+0.2;
                        UpdatePlot(app, 2);
                    end
                % Short Keys for Smaller Amp
                case 's'
                    if((app.iStateView==0||app.iStateView==1))
                        app.PlotShow.AmpNumLast = app.PlotShow.AmpNum;
                        app.PlotShow.AmpNum = app.PlotShow.AmpNum-0.2;
                        UpdatePlot(app, 2);
                    end
                % Short Keys for UnDo Event
                case 'd'
                    if(app.iState==1&&~isempty(app.AllSpikeView.Event.EventValue))
                        EventValueTemp = app.AllSpikeView.Event.EventValue;
                        EventValueTemp = strrep(EventValueTemp,'Reject: ','');EventValueTemp = strrep(EventValueTemp,'AC_Accept: ','');EventValueTemp = strrep(EventValueTemp,'OL_Accept: ','');       
                        app.AllSpikeView.Event.EventName{cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName)} = EventValueTemp;
                        EventNameTemp = app.AllSpikeView.Event.EventName(app.AllSpikeView.Event.EventToDisp);
                        EventIdx = find(cellfun(@(x)strcmp(x,EventValueTemp),EventNameTemp));
                        EventIdx = EventIdx+1;               
                        if(EventIdx>length(EventNameTemp))
                            app.AllSpikeView.Event.EventValue = EventNameTemp{1};
                        else
                            app.AllSpikeView.Event.EventValue = EventNameTemp{EventIdx};
                        end
                        UpdateEvent(app);
                        UpdatePlot(app, 0);
                        if(app.Spike_ShowTopo.Value)
                            PlotCurrentTopo(app);
                        end
                    elseif(app.iState==2)
                        EventValueTemp = app.AllHFOView.Event.EventValue;
                        EventValueTemp = strrep(EventValueTemp,'Reject: ','');EventValueTemp = strrep(EventValueTemp,'AC_Accept: ','');EventValueTemp = strrep(EventValueTemp,'OL_Accept: ','');         
                        app.AllHFOView.Event.EventName{cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName)} = EventValueTemp;
                        EventNameTemp = app.AllHFOView.Event.EventName(app.AllHFOView.Event.EventToDisp);
                        EventIdx = find(cellfun(@(x)strcmp(x,EventValueTemp),EventNameTemp));
                        EventIdx = EventIdx+1;               
                        if(EventIdx>length(EventNameTemp))
                            app.AllHFOView.Event.EventValue = EventNameTemp{1};
                        else
                            app.AllHFOView.Event.EventValue = EventNameTemp{EventIdx};
                        end
                        UpdateEvent(app);
                        UpdatePlot(app, 0);
                    end
                % Short Keys for Reject Event
                case 'leftarrow'
                    if(app.iState==1&&~isempty(app.AllSpikeView.Event.EventValue))
                        EventValueTemp = app.AllSpikeView.Event.EventValue;
                        EventValueTemp = strrep(EventValueTemp,'Reject: ','');EventValueTemp = strrep(EventValueTemp,'AC_Accept: ','');EventValueTemp = strrep(EventValueTemp,'OL_Accept: ','');
                        EventValueTemp = ['Reject: ',EventValueTemp];           
                        app.AllSpikeView.Event.EventName{cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName)} = EventValueTemp;
                        EventNameTemp = app.AllSpikeView.Event.EventName(app.AllSpikeView.Event.EventToDisp);
                        EventIdx = find(cellfun(@(x)strcmp(x,EventValueTemp),EventNameTemp));                
                        EventIdx = EventIdx+1;             
                        if(EventIdx>length(EventNameTemp))
                            app.AllSpikeView.Event.EventValue = EventNameTemp{1};
                        else
                            app.AllSpikeView.Event.EventValue = EventNameTemp{EventIdx};
                        end
                        
                        UpdateEvent(app);
                        UpdatePlot(app, 0);
                        if(app.Spike_ShowTopo.Value)
                            PlotCurrentTopo(app);
                        end
                    elseif(app.iState==2)
                        EventValueTemp = app.AllHFOView.Event.EventValue;
                        EventValueTemp = strrep(EventValueTemp,'Reject: ','');EventValueTemp = strrep(EventValueTemp,'AC_Accept: ','');EventValueTemp = strrep(EventValueTemp,'OL_Accept: ','');
                        EventValueTemp = ['Reject: ',EventValueTemp];            
                        app.AllHFOView.Event.EventName{cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName)} = EventValueTemp;
                        EventNameTemp = app.AllHFOView.Event.EventName(app.AllHFOView.Event.EventToDisp);
                        EventIdx = find(cellfun(@(x)strcmp(x,EventValueTemp),EventNameTemp));                
                        EventIdx = EventIdx+1;             
                        if(EventIdx>length(EventNameTemp))
                            app.AllHFOView.Event.EventValue = EventNameTemp{1};
                        else
                            app.AllHFOView.Event.EventValue = EventNameTemp{EventIdx};
                        end
                        
                        UpdateEvent(app);
                        UpdatePlot(app, 0);
                    end
                % Short Keys for Accept Event
                case 'rightarrow'
                    if(app.iState==1&&~isempty(app.AllSpikeView.Event.EventValue))
                        EventValueTemp = app.AllSpikeView.Event.EventValue;
                        EventValueTemp = strrep(EventValueTemp,'Reject: ','');EventValueTemp = strrep(EventValueTemp,'AC_Accept: ','');EventValueTemp = strrep(EventValueTemp,'OL_Accept: ','');
                        if(app.isShift==1)
                            EventValueTemp = ['AC_Accept: ',EventValueTemp];
                        elseif(app.isShift==0)
                            EventValueTemp = ['OL_Accept: ',EventValueTemp];   
                        end               
                        app.AllSpikeView.Event.EventName{cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName)} = EventValueTemp;
                        EventNameTemp = app.AllSpikeView.Event.EventName(app.AllSpikeView.Event.EventToDisp);
                        EventIdx = find(cellfun(@(x)strcmp(x,EventValueTemp),EventNameTemp));                
                        EventIdx = EventIdx+1;             
                        if(EventIdx>length(EventNameTemp))
                            app.AllSpikeView.Event.EventValue = EventNameTemp{1};
                        else
                            app.AllSpikeView.Event.EventValue = EventNameTemp{EventIdx};
                        end
                        
                        UpdateEvent(app);
                        UpdatePlot(app, 0);
                        if(app.Spike_ShowTopo.Value)
                            PlotCurrentTopo(app);
                        end
                    elseif(app.iState==2)
                        EventValueTemp = app.AllHFOView.Event.EventValue;
                        EventValueTemp = strrep(EventValueTemp,'Reject: ','');EventValueTemp = strrep(EventValueTemp,'AC_Accept: ','');EventValueTemp = strrep(EventValueTemp,'OL_Accept: ','');
                        if(app.isShift==1)
                            EventValueTemp = ['AC_Accept: ',EventValueTemp];
                        elseif(app.isShift==0)
                            EventValueTemp = ['OL_Accept: ',EventValueTemp];   
                        end           
                        app.AllHFOView.Event.EventName{cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName)} = EventValueTemp;
                        EventNameTemp = app.AllHFOView.Event.EventName(app.AllHFOView.Event.EventToDisp);
                        EventIdx = find(cellfun(@(x)strcmp(x,EventValueTemp),EventNameTemp));                
                        EventIdx = EventIdx+1;             
                        if(EventIdx>length(EventNameTemp))
                            app.AllHFOView.Event.EventValue = EventNameTemp{1};
                        else
                            app.AllHFOView.Event.EventValue = EventNameTemp{EventIdx};
                        end
                        
                        UpdateEvent(app);
                        UpdatePlot(app, 0);
                    end  
                % Short Keys for Up select Event
                case 'uparrow'
                    if(app.iState==1&&~isempty(app.AllSpikeView.Event.EventValue))
                        EventNameTemp = app.AllSpikeView.Event.EventName(app.AllSpikeView.Event.EventToDisp);
                        EventIdx = find(cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),EventNameTemp))-1;             
                        if(EventIdx<1)
                            app.AllSpikeView.Event.EventValue = EventNameTemp{length(EventNameTemp)};
                        else
                            app.AllSpikeView.Event.EventValue = EventNameTemp{EventIdx};
                        end
                        
                        UpdateEvent(app);
                        UpdatePlot(app, 0);
                        if(app.Spike_ShowTopo.Value)
                            PlotCurrentTopo(app);
                        end
                    elseif(app.iState==2)
                        EventNameTemp = app.AllHFOView.Event.EventName(app.AllHFOView.Event.EventToDisp);
                        EventIdx = find(cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),EventNameTemp))-1;             
                        if(EventIdx<1)
                            app.AllHFOView.Event.EventValue = EventNameTemp{length(EventNameTemp)};
                        else
                            app.AllHFOView.Event.EventValue = EventNameTemp{EventIdx};
                        end
                        
                        UpdateEvent(app);
                        UpdatePlot(app, 0);
                    end
                % Short Keys for Down select Event
                case 'downarrow'
                    if(app.iState==1&&~isempty(app.AllSpikeView.Event.EventValue))
                        EventNameTemp = app.AllSpikeView.Event.EventName(app.AllSpikeView.Event.EventToDisp);
                        EventIdx = find(cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),EventNameTemp))+1;             
                        if(EventIdx>length(EventNameTemp))
                            app.AllSpikeView.Event.EventValue = EventNameTemp{1};
                        else
                            app.AllSpikeView.Event.EventValue = EventNameTemp{EventIdx};
                        end
                        
                        UpdateEvent(app);
                        UpdatePlot(app, 0);
                        if(app.Spike_ShowTopo.Value)
                            PlotCurrentTopo(app);
                        end
                    elseif(app.iState==2)
                        EventNameTemp = app.AllHFOView.Event.EventName(app.AllHFOView.Event.EventToDisp);
                        EventIdx = find(cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),EventNameTemp))+1;             
                        if(EventIdx>length(EventNameTemp))
                            app.AllHFOView.Event.EventValue = EventNameTemp{1};
                        else
                            app.AllHFOView.Event.EventValue = EventNameTemp{EventIdx};
                        end
                        
                        UpdateEvent(app);
                        UpdatePlot(app, 0);
                    end
                case 'shift'
                    app.isShift = 0;
                case '1'
                    if(app.iState==2)
                        app.VSButton.Value = 1;
                        VSButtonValueChanged(app);
                    end
                case '2'
                    if(app.iState==2)
                        app.TFButton.Value = 1;
                        TFButtonValueChanged(app);
                    end
                case '3'
                    if(app.iState==2)
                        app.MEGButton.Value = 1;
                        MEGButtonValueChanged(app);
                    end
                case '4'
                    if(app.iState==2)
                        app.TemplateButton.Value = 1;
                        TemplateButtonValueChanged(app);
                    end
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            app.UIAxesVS.PlotBoxAspectRatioMode = 'auto';
            app.UIAxesMEG.PlotBoxAspectRatioMode = 'auto';
            app.UIAxesTF.PlotBoxAspectRatioMode = 'auto';
            app.UIAxesTemplate.PlotBoxAspectRatioMode = 'auto';
            app.UIAxesTopo.PlotBoxAspectRatioMode = 'auto';
        end

        % Button pushed function: ShowAllSoucesButton
        function ShowAllSoucesButtonPushed(app, event)
            % Get Total Accept Event
            AptIdx = find(contains(app.AllHFOView.Event.EventName,'AC_Accept'));
            Temp = cell2mat(cellfun(@(x,y,z)cat(1, z, x, y(1,1), min(y(:,3)), max(y(:,4))),num2cell(app.AllHFOView.Idx(AptIdx))', app.AllHFOView.HFOIdx(AptIdx),num2cell(AptIdx), 'UniformOutput', false))';
            % Deltet repeat Event
            [~,~,ic] = unique(Temp(:, 2:3),'row');
            HFO_ChanIdx = cellfun(@(x)cat(1,app.AllHFOView.HFOIdx{AptIdx(ic==x)}),num2cell(unique(ic))','UniformOutput',false);HFO_ChanIdx = cellfun(@(x)unique(x(:,2)),HFO_ChanIdx,'UniformOutput',false);
            HFOidx = cell2mat(cellfun(@(x)cat(1, min(Temp(ic==x,1)), mean(Temp(ic==x,2)), mean(Temp(ic==x,3)), min(Temp(ic==x,4)), max(Temp(ic==x,5))),num2cell(unique(ic))','UniformOutput',false))';
            % Get Source maps
            AmpMap = app.AllHFOView.toShow.Maps(AptIdx, :);AmpMap = mean(AmpMap, 1)';
            AmpMap = (AmpMap- min(AmpMap)) / (max(AmpMap) - min(AmpMap));
            % Get Channel Number Maps
            ChanNumMap = zeros(size(AmpMap, 1), 1);
            Chan = cat(1, app.AllHFOView.HFOIdx{AptIdx});Chan = Chan(:, 2);
            Chan = unique(Chan);ChanNum = cellfun(@(x)length(find(Chan==x)),num2cell(Chan));
            ChanNumMap(Chan) = ChanNum;ChanNumMap = uint16(ChanNumMap); 
            % Get MRI
            gui_brainstorm('SetCurrentProtocol', bst_get('Protocol', app.db_subject_select.subject_name));
            temp = bst_get('ProtocolSubjects');temp=temp.Subject;iSubject = find(contains({temp.Name}, app.db_subject_select.subject_name));
            sSubject = bst_get('Subject', iSubject);
            MRI = sSubject.Anatomy(sSubject.iAnatomy).FileName;
            % Get Surface File
            Surface = sSubject.Surface(sSubject.iCortex).FileName;
            % Get Show
            ShowResult.Loc = cat(2, app.AllHFOView.toShow.ChannelMat_VS(1).Channel.Loc)';ShowResult.AmpMap = AmpMap;ShowResult.ChanNumMap = ChanNumMap;
            % Figure Name
            FigureName = ['HFO Event Number: ', num2str(size(HFOidx, 1))];
            % Show
            Position = app.UIFigure.Position;
            ShowLocation(FigureName, ShowResult, MRI, Surface, Position);
        end

        % Button pushed function: ShowSoucesButton
        function ShowSoucesButtonPushed(app, event)
            % Get Event
            EventIdx = cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName);
            % Get Source maps
            AmpMap = app.AllHFOView.toShow.Maps(EventIdx, :)';
            AmpMap = (AmpMap- min(AmpMap)) / (max(AmpMap) - min(AmpMap));
            % Get Channel Number Maps
            ChanNumMap = zeros(size(AmpMap, 1), 1);
            Chan = cat(1, app.AllHFOView.HFOIdx{EventIdx});Chan = Chan(:, 2);
            Chan = unique(Chan);ChanNum = cellfun(@(x)length(find(Chan==x)),num2cell(Chan));
            ChanNumMap(Chan) = ChanNum;ChanNumMap = uint16(ChanNumMap); 
            % Get MRI
            gui_brainstorm('SetCurrentProtocol', bst_get('Protocol', app.db_subject_select.subject_name));
            temp = bst_get('ProtocolSubjects');temp=temp.Subject;iSubject = find(contains({temp.Name}, app.db_subject_select.subject_name));
            sSubject = bst_get('Subject', iSubject);
            MRI = sSubject.Anatomy(sSubject.iAnatomy).FileName;
            % Get Surface File
            Surface = sSubject.Surface(sSubject.iCortex).FileName;
            % Get Show
            ShowResult.Loc = cat(2, app.AllHFOView.toShow.ChannelMat_VS(1).Channel.Loc)';ShowResult.AmpMap = AmpMap;ShowResult.ChanNumMap = ChanNumMap;
            % Figure Name
            FigureName = app.AllHFOView.Event.EventValue;
            % Show
            Position = app.UIFigure.Position;
            ShowLocation(FigureName, ShowResult, MRI, Surface, Position);
        end

        % Button pushed function: ViewHFOButton
        function ViewHFOButtonPushed(app, event)
            app.Mode = 1;
            ModeInit(app);
        end

        % Button pushed function: ViewSpikeButton
        function ViewSpikeButtonPushed(app, event)
           app.Mode = 2;
           ModeInit(app);            
        end

        % Value changed function: SubjectDown
        function SubjectDownValueChanged(app, event)
            % Save Previous Project
            if(~isempty(app.db_subject_select))
                saveStudyAll(app);
                app.db_subject_select = [];
            end
            app.MEGFilesButton.Enable = 0;
            value = app.SubjectDown.Value;
            Protocols = load(bst_get('BrainstormDbFile'));
            if(strcmp(value, 'None'))
                app.db_subject_select = [];
                app.MEGFilesButton.Enable = 0;
            else
                app.db_subject_select = app.db_subject_data(cellfun(@(x)strcmp(x, value), {app.db_subject_data.subject_name}));
                gui_brainstorm('SetCurrentProtocol', bst_get('Protocol', app.db_subject_select.subject_name));
                app.MEGFilesButton.Enable = 1;     
                app.AllHFOView = [];
                app.AllSpikeView = [];        
            end
            app.State = 0;
            StateUpdata(app);
        end

        % Button pushed function: MEGFilesButton
        function MEGFilesButtonPushed(app, event)
            app.GridLayout.Visible = 0;
            app.SelectStudyFile = DialogSelectStudy(app);  
        end

        % Button pushed function: LoadProject
        function LoadProjectButtonPushed(app, event)
            app.SubjectDown.Value = 'None';
            app.MEGFilesButton.Enable = 0;
            app.State = -1;
            StateUpdata(app);
            app.GridLayout.Visible = 0;
            app.Load_Project = DialogLoadProject(app);  
        end

        % Button pushed function: SaveProject
        function SaveProjectButtonPushed(app, event)
            if(~isempty(app.SelectStudy) && any(app.SelectStudy))
                saveStudyAll(app);
            end
        end

        % Button pushed function: Process
        function GButtonPushed(app, event)
            app.GridLayout.Visible = 0;
            app.GroupProcess = DialogGroupProcess(app);  
        end

        % Button pushed function: ProcessStatus
        function ProcessStatusButtonPushed(app, event)
            app.GridLayout.Visible = 0;
            app.ProcessStatusShow = DialogProcessStatus(app);
        end

        % Button pushed function: Button_Down
        function Button_DownPushed(app, event)
            app.PlotShow.AmpNumLast = app.PlotShow.AmpNum;
            app.PlotShow.AmpNum = app.PlotShow.AmpNum-0.2;
            if(app.iState~=0)
                UpdatePlot(app, 2); 
            end
        end

        % Button pushed function: Button_UP
        function Button_UPPushed(app, event)
            app.PlotShow.AmpNumLast = app.PlotShow.AmpNum;
            app.PlotShow.AmpNum = app.PlotShow.AmpNum+0.2;
            if(app.iState~=0)
                UpdatePlot(app, 2); 
            end
        end

        % Value changed function: VSButton
        function VSButtonValueChanged(app, event)
            if(app.iStateView==0)
                app.VSButton.Value = 1;
            else
                app.AxViewNum = 3;app.View_DropDown_Columns.Value = '3';
                app.View_DropDown_Columns.Enable = 1;app.View_DropDown_Rows.Enable = 1;
                app.VSButton.Value = 1;app.TemplateButton.Value = 0;app.MEGButton.Value = 0;app.TFButton.Value = 0;
                app.iStateView = 0;
                % Change the UIaxes
                app.UIAxesVS.clo;app.UIAxesMEG.clo;app.UIAxesTF.clo;app.UIAxesTemplate.clo;app.UIAxesTopo.clo;
                app.UIAxesVS.Layout.Column = [1,5];app.UIAxesVS.Layout.Row = 1;app.UIAxesVS.Visible = 'on';
                app.UIAxesMEG.Layout.Column = 5;app.UIAxesMEG.Layout.Row = 2;app.UIAxesMEG.Visible = 'off';
                app.UIAxesTF.Layout.Column = 5;app.UIAxesTF.Layout.Row = 2;app.UIAxesTF.Visible = 'off';
                app.UIAxesTemplate.Layout.Column = 5;app.UIAxesTemplate.Layout.Row = 2;app.UIAxesTemplate.Visible = 'off';
                app.UIAxesTopo.Layout.Column = 5;app.UIAxesTopo.Layout.Row = 2;app.UIAxesTopo.Visible = 'off';
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Prepare VS','Message','Preparing VS','Indeterminate','on');
                UpdatePlot(app, 0);
                close(WaitBar);
            end   
        end

        % Value changed function: MEGButton
        function MEGButtonValueChanged(app, event)
            if(app.iStateView==1)
                app.MEGButton.Value = 1;
            else
                app.AxViewNum = 3;app.View_DropDown_Columns.Value = '3';
                app.View_DropDown_Columns.Enable = 1;app.View_DropDown_Rows.Enable = 0;
                app.VSButton.Value = 0;app.TemplateButton.Value = 0;app.MEGButton.Value = 1;app.TFButton.Value = 0;
                app.iStateView = 1;
                % Change the UIaxes
                app.UIAxesVS.clo;app.UIAxesTF.clo;app.UIAxesTemplate.clo;app.UIAxesMEG.clo;app.UIAxesTopo.clo;
                app.UIAxesVS.Layout.Column = 5;app.UIAxesVS.Layout.Row = 2;app.UIAxesVS.Visible = 'off';
                app.UIAxesTF.Layout.Column = 5;app.UIAxesTF.Layout.Row = 2;app.UIAxesTF.Visible = 'off';
                app.UIAxesTemplate.Layout.Column = 5;app.UIAxesTemplate.Layout.Row = 2;app.UIAxesTemplate.Visible = 'off';
                if(app.Spike_ShowTopo.Value&&app.iState==1)
                    app.UIAxesMEG.Layout.Column = 5;app.UIAxesMEG.Layout.Row = 1;app.UIAxesMEG.Visible = 'on';
                    app.UIAxesTopo.Layout.Column = [1,4];app.UIAxesTopo.Layout.Row = 1;app.UIAxesTopo.Visible = 'on';
                    PlotCurrentTopo(app);
                else
                    app.UIAxesTopo.Layout.Column = 5;app.UIAxesTopo.Layout.Row = 2;app.UIAxesTopo.Visible = 'off';
                    app.UIAxesMEG.Layout.Column = [1,5];app.UIAxesMEG.Layout.Row = 1;app.UIAxesMEG.Visible = 'on';
                end
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Prepare MEG','Message','Preparing MEG','Indeterminate','on');
                UpdatePlot(app, 0);
                close(WaitBar);
            end     
        end

        % Value changed function: TFButton
        function TFButtonValueChanged(app, event)
            if(app.iStateView==2)
                app.TFButton.Value = 1;
            else
                app.AxViewNum = 8;app.View_DropDown_Columns.Value = '8';
                app.View_DropDown_Columns.Enable = 1;app.View_DropDown_Rows.Enable = 0;
                app.VSButton.Value = 0;app.TemplateButton.Value = 0;app.MEGButton.Value = 0;app.TFButton.Value = 1;
                app.iStateView = 2;
                % Change the UIaxes
                app.UIAxesVS.clo;app.UIAxesMEG.clo;app.UIAxesTF.clo;app.UIAxesTemplate.clo;app.UIAxesTopo.clo;
                app.UIAxesVS.Layout.Column = 5;app.UIAxesVS.Layout.Row = 2;app.UIAxesVS.Visible = 'off';
                app.UIAxesMEG.Layout.Column = 5;app.UIAxesMEG.Layout.Row = 2;app.UIAxesMEG.Visible = 'off';
                app.UIAxesTF.Layout.Column = [1,5];app.UIAxesTF.Layout.Row = 1;app.UIAxesTF.Visible = 'on';
                app.UIAxesTemplate.Layout.Column = 5;app.UIAxesTemplate.Layout.Row = 2;app.UIAxesTemplate.Visible = 'off';
                app.UIAxesTopo.Layout.Column = 5;app.UIAxesTopo.Layout.Row = 2;app.UIAxesTopo.Visible = 'off';
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Prepare Time_frequency Maps','Message','Preparing Time_frequency Maps','Indeterminate','on');
                UpdatePlot(app, 0);
                close(WaitBar);
            end
        end

        % Value changed function: TemplateButton
        function TemplateButtonValueChanged(app, event)
            if(app.iStateView==3)
                app.TemplateButton.Value = 1;
            else
                app.View_DropDown_Columns.Enable = 0;app.View_DropDown_Rows.Enable = 0;
                app.VSButton.Value = 0;app.TemplateButton.Value = 1;app.MEGButton.Value = 0;app.TFButton.Value = 0;
                app.iStateView = 3;
                % Change the UIaxes
                app.UIAxesVS.clo;app.UIAxesTF.clo;app.UIAxesTemplate.clo;app.UIAxesMEG.clo;app.UIAxesTopo.clo;
                app.UIAxesVS.Layout.Column = 5;app.UIAxesVS.Layout.Row = 2;app.UIAxesVS.Visible = 'off';
                app.UIAxesMEG.Layout.Column = 5;app.UIAxesMEG.Layout.Row = 2;app.UIAxesMEG.Visible = 'off';
                app.UIAxesTF.Layout.Column = 5;app.UIAxesTF.Layout.Row = 2;app.UIAxesTF.Visible = 'off';
                app.UIAxesTemplate.Layout.Column = [1,5];app.UIAxesTemplate.Layout.Row = 1;app.UIAxesTemplate.Visible = 'on';
                app.UIAxesTopo.Layout.Column = 5;app.UIAxesTopo.Layout.Row = 2;app.UIAxesTopo.Visible = 'off';
                % Plot the Template
                if(app.iState==1)
                    WaitBar = uiprogressdlg(app.UIFigure,'Title','Prepare Template','Message','Preparing Template','Indeterminate','on');
                    TemplatePlotRow = 6;TemplatePlotCol = max(6, ceil(length(app.AllSpikeView.toShow.Template)/TemplatePlotRow));
                    hold(app.UIAxesTemplate,'on');
                    for i=1:length(app.AllSpikeView.toShow.Template)
                        Temp = floor(i/(TemplatePlotRow + 0.05));TempSize = size(app.AllSpikeView.toShow.Template{i}, 2);
                        TempX = round(TempSize*0.05:TempSize*(1+0.05)-1) + Temp*round(TempSize*(1+0.05*2));
                        TempY_all = app.AllSpikeView.toShow.Template{i};TempY_all = (TempY_all - min(TempY_all,[],2))./(max(TempY_all,[],2) - min(TempY_all,[],2));
                        TempY = mean(TempY_all*(1-0.05*2)) +  (i-Temp*TemplatePlotRow-1) + 0.05;TempY_all = TempY_all*(1-0.05*2) + (i-Temp*TemplatePlotRow-1) + 0.05;
                        plot(app.UIAxesTemplate, TempX, TempY_all, 'color', [0.5, 0.5, 0.5]);
                        if(app.AllSpikeView.toShow.TemplateSelect(i)==1)
                            plot(app.UIAxesTemplate, TempX, TempY, 'color', [0, 1, 0], 'linewidth',3, 'Tag', ['Template_',num2str(i)]);
                        else
                            plot(app.UIAxesTemplate, TempX, TempY, 'color', [0.5, 0.5, 0.5], 'linewidth',3, 'Tag', ['Template_',num2str(i)]);
                        end
                    end
                    app.UIAxesTemplate.XLim = [0, (1+0.05*2)*TempSize*TemplatePlotCol];
                    % Plot Grad
                    for i=0:TemplatePlotRow
                        plot(app.UIAxesTemplate, app.UIAxesTemplate.XLim, [i, i], 'k', 'linewidth',1.5);
                    end
                    for i=0:TemplatePlotCol
                        plot(app.UIAxesTemplate, [i, i]*(size(app.AllSpikeView.toShow.Template{1}, 2)*(1+0.05*2)), app.UIAxesTemplate.YLim, 'k', 'linewidth',1.5);
                    end
                    hold(app.UIAxesTemplate,'off');
                elseif(app.iState==2)
                    WaitBar = uiprogressdlg(app.UIFigure,'Title','Prepare Clusters','Message','Preparing Clusters','Indeterminate','on');
                    Plot_Cluster(app, app.AllHFOView.toShow.Best_Cls, app.AllHFOView.toShow.Bic, 0.5);
                end
                close(WaitBar)
            end
        end

        % Value changed function: CheckBox_LowPass
        function CheckBox_LowPassValueChanged(app, event)
            app.PlotShow.LpassOn = app.CheckBox_LowPass.Value;
            UpdatePlot(app, 0);
        end

        % Value changed function: HighPassEditField
        function HighPassEditFieldValueChanged(app, event)
            app.PlotShow.Hpass = app.HighPassEditField.Value;
            if(app.iState~=0)
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: CheckBox_HighPass
        function CheckBox_HighPassValueChanged(app, event)
            app.PlotShow.HpassOn = app.CheckBox_HighPass.Value;
            if(app.iState~=0)
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: LowPassEditField
        function LowPassEditFieldValueChanged(app, event)
            app.PlotShow.Lpass = app.LowPassEditField.Value;
            if(app.iState~=0)
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: InfoButton
        function InfoButtonValueChanged(app, event)
            app.PlotShow.ShowInfo = app.InfoButton.Value;
            if(app.iState~=0)
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: RejectCheckBox
        function RejectCheckBoxValueChanged(app, event)
            app.PlotShow.HideRejcet = app.RejectCheckBox.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
            
        end

        % Value changed function: UnDoCheckBox
        function UnDoCheckBoxValueChanged(app, event)
            app.PlotShow.HideUnDo = app.UnDoCheckBox.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: AC_CheckBox
        function AC_CheckBoxValueChanged(app, event)
            app.PlotShow.HideAC = app.AC_CheckBox.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: OL_CheckBox
        function OL_CheckBoxValueChanged(app, event)
            app.PlotShow.HideOL = app.OL_CheckBox.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            if(app.iState==1)
                app.AllSpikeView.Event.EventValue = app.ListBox.Value;
                UpdateEvent(app);
                UpdatePlot(app, 0);
                if(app.Spike_ShowTopo.Value)
                    PlotCurrentTopo(app);
                end 
            elseif(app.iState==2)
                app.AllHFOView.Event.EventValue = app.ListBox.Value;
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Button pushed function: Spike_AccpetButton
        function Spike_AccpetButtonPushed(app, event)
            if(app.iState==1&&~isempty(app.AllSpikeView.Event.EventValue))
                EventValueTemp = app.AllSpikeView.Event.EventValue;
                EventValueTemp = strrep(EventValueTemp,'Reject: ','');EventValueTemp = strrep(EventValueTemp,'AC_Accept: ','');EventValueTemp = strrep(EventValueTemp,'OL_Accept: ','');
                if(app.isShift==1)
                    EventValueTemp = ['AC_Accept: ',EventValueTemp];
                elseif(app.isShift==0)
                    EventValueTemp = ['OL_Accept: ',EventValueTemp];   
                end             
                app.AllSpikeView.Event.EventName{cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName)} = EventValueTemp;
                EventNameTemp = app.AllSpikeView.Event.EventName(app.AllSpikeView.Event.EventToDisp);
                EventIdx = find(cellfun(@(x)strcmp(x,EventValueTemp),EventNameTemp));                
                EventIdx = EventIdx+1;             
                if(EventIdx>length(EventNameTemp))
                    app.AllSpikeView.Event.EventValue = EventNameTemp{1};
                else
                    app.AllSpikeView.Event.EventValue = EventNameTemp{EventIdx};
                end
                
                UpdateEvent(app);
                UpdatePlot(app, 0);
            end            
        end

        % Button pushed function: Spike_RejectButton
        function Spike_RejectButtonPushed(app, event)
            if(app.iState==1&&~isempty(app.AllSpikeView.Event.EventValue))
                EventValueTemp = app.AllSpikeView.Event.EventValue;
                EventValueTemp = strrep(EventValueTemp,'Reject: ','');EventValueTemp = strrep(EventValueTemp,'AC_Accept: ','');EventValueTemp = strrep(EventValueTemp,'OL_Accept: ','');
                EventValueTemp = ['Reject: ',EventValueTemp];           
                app.AllSpikeView.Event.EventName{cellfun(@(x)strcmp(x,app.AllSpikeView.Event.EventValue),app.AllSpikeView.Event.EventName)} = EventValueTemp;
                EventNameTemp = app.AllSpikeView.Event.EventName(app.AllSpikeView.Event.EventToDisp);
                EventIdx = find(cellfun(@(x)strcmp(x,EventValueTemp),EventNameTemp));                
                EventIdx = EventIdx+1;             
                if(EventIdx>length(EventNameTemp))
                    app.AllSpikeView.Event.EventValue = EventNameTemp{1};
                else
                    app.AllSpikeView.Event.EventValue = EventNameTemp{EventIdx};
                end
                
                UpdateEvent(app);
                UpdatePlot(app, 0);
            end             
        end

        % Button pushed function: HFO_AccpetButton
        function HFO_AccpetButtonPushed(app, event)
            if(app.iState==2)
                EventValueTemp = app.AllHFOView.Event.EventValue;
                EventValueTemp = strrep(EventValueTemp,'Reject: ','');EventValueTemp = strrep(EventValueTemp,'AC_Accept: ','');EventValueTemp = strrep(EventValueTemp,'OL_Accept: ','');
                if(app.isShift==1)
                    EventValueTemp = ['AC_Accept: ',EventValueTemp];
                elseif(app.isShift==0)
                    EventValueTemp = ['OL_Accept: ',EventValueTemp];   
                end           
                app.AllHFOView.Event.EventName{cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName)} = EventValueTemp;
                EventNameTemp = app.AllHFOView.Event.EventName(app.AllHFOView.Event.EventToDisp);
                EventIdx = find(cellfun(@(x)strcmp(x,EventValueTemp),EventNameTemp));                
                EventIdx = EventIdx+1;             
                if(EventIdx>length(EventNameTemp))
                    app.AllHFOView.Event.EventValue = EventNameTemp{1};
                else
                    app.AllHFOView.Event.EventValue = EventNameTemp{EventIdx};
                end
                
                UpdateEvent(app);
                UpdatePlot(app, 0);
            end              
        end

        % Button pushed function: HFO_RejectButton
        function HFO_RejectButtonPushed(app, event)
            if(app.iState==2)
                EventValueTemp = app.AllHFOView.Event.EventValue;
                EventValueTemp = strrep(EventValueTemp,'Reject: ','');EventValueTemp = strrep(EventValueTemp,'AC_Accept: ','');EventValueTemp = strrep(EventValueTemp,'OL_Accept: ','');
                EventValueTemp = ['Reject: ',EventValueTemp];            
                app.AllHFOView.Event.EventName{cellfun(@(x)strcmp(x,app.AllHFOView.Event.EventValue),app.AllHFOView.Event.EventName)} = EventValueTemp;
                EventNameTemp = app.AllHFOView.Event.EventName(app.AllHFOView.Event.EventToDisp);
                EventIdx = find(cellfun(@(x)strcmp(x,EventValueTemp),EventNameTemp));                
                EventIdx = EventIdx+1;             
                if(EventIdx>length(EventNameTemp))
                    app.AllHFOView.Event.EventValue = EventNameTemp{1};
                else
                    app.AllHFOView.Event.EventValue = EventNameTemp{EventIdx};
                end
                
                UpdateEvent(app);
                UpdatePlot(app, 0);
            end               
        end

        % Callback function
        function HFO_ButtonGroupSelectionChanged(app, event)
            if(app.iState==2)
                selectedButton = app.HFO_ButtonGroup.SelectedObject;
                if(strcmp(selectedButton.Text,'AP'))
                    app.AllHFOView.AcceptType = 1;               
                else
                    app.AllHFOView.AcceptType = 2;               
                end
            end
        end

        % Value changed function: HFO_ShowSpikeCheckBox
        function HFO_ShowSpikeCheckBoxValueChanged(app, event)
            app.PlotShow.ShowSpike = app.HFO_ShowSpikeCheckBox.Value;
            if(app.iState~=0)
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: View_DropDown_Rows
        function View_DropDown_RowsValueChanged(app, event)
            value = str2double(app.View_DropDown_Rows.Value);
            app.AxView = value;
            UpdatePlot(app, 0);
        end

        % Value changed function: View_DropDown_Columns
        function View_DropDown_ColumnsValueChanged(app, event)
            value = str2double(app.View_DropDown_Columns.Value);
            app.AxViewNum = value;
            UpdatePlot(app, 0);
        end

        % Value changed function: HFO_AmpSpikeEditField
        function HFO_AmpSpikeEditFieldValueChanged(app, event)
            app.AllHFOView.Event.AmpSpike = app.HFO_AmpSpikeEditField.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: HFO_CheckBox_AmpSpike
        function HFO_CheckBox_AmpSpikeValueChanged(app, event)
            app.AllHFOView.Event.AmpSpikeOn = app.HFO_CheckBox_AmpSpike.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: HFO_LLSpikeEditField
        function HFO_LLSpikeEditFieldValueChanged(app, event)
            app.AllHFOView.Event.LLSpike = app.HFO_LLSpikeEditField.Value * 2;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: HFO_CheckBox_LLSpike
        function HFO_CheckBox_LLSpikeValueChanged(app, event)
            app.AllHFOView.Event.LLSpikeOn = app.HFO_CheckBox_LLSpike.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: HFO_AmpHFOEditField
        function HFO_AmpHFOEditFieldValueChanged(app, event)
            app.AllHFOView.Event.AmpHFO = app.HFO_AmpHFOEditField.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: HFO_CheckBox_AmpHFO
        function HFO_CheckBox_AmpHFOValueChanged(app, event)
            app.AllHFOView.Event.AmpHFOOn = app.HFO_CheckBox_AmpHFO.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: HFO_LLHFOEditField
        function HFO_LLHFOEditFieldValueChanged(app, event)
            app.AllHFOView.Event.LLHFO = app.HFO_LLHFOEditField.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: HFO_CheckBox_LLHFO
        function HFO_CheckBox_LLHFOValueChanged(app, event)
            app.AllHFOView.Event.LLHFOOn = app.HFO_CheckBox_LLHFO.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: HFO_EntropySpikeEditField
        function HFO_EntropySpikeEditFieldValueChanged(app, event)
            app.AllHFOView.Event.EntropySpike = app.HFO_EntropySpikeEditField.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: HFO_CheckBox_EntropySpike
        function HFO_CheckBox_EntropySpikeValueChanged(app, event)
            app.AllHFOView.Event.EntropySpikeOn = app.HFO_CheckBox_EntropySpike.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: HFO_EntropyHFOEditField
        function HFO_EntropyHFOEditFieldValueChanged(app, event)
            app.AllHFOView.Event.EntropyHFO = app.HFO_EntropyHFOEditField.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: HFO_CheckBox_EntropyHFO
        function HFO_CheckBox_EntropyHFOValueChanged(app, event)
            app.AllHFOView.Event.EntropyHFOOn = app.HFO_CheckBox_EntropyHFO.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_AmpEditField
        function Spike_AmpEditFieldValueChanged(app, event)
            app.AllSpikeView.Event.Amp = app.Spike_AmpEditField.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_CheckBox_Amp
        function Spike_CheckBox_AmpValueChanged(app, event)
            app.AllSpikeView.Event.AmpOn = app.Spike_CheckBox_Amp.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_SlopeEditField
        function Spike_SlopeEditFieldValueChanged(app, event)
            app.AllSpikeView.Event.Slope = app.Spike_SlopeEditField.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_CheckBox_Slope
        function Spike_CheckBox_SlopeValueChanged(app, event)
            app.AllSpikeView.Event.SlopeOn = app.Spike_CheckBox_Slope.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_SharpEditField
        function Spike_SharpEditFieldValueChanged(app, event)
            app.AllSpikeView.Event.Sharp = app.Spike_SharpEditField.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_CheckBox_Sharp
        function Spike_CheckBox_SharpValueChanged(app, event)
            app.AllSpikeView.Event.SharpOn = app.Spike_CheckBox_Sharp.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_CheckBox_Corr
        function Spike_CheckBox_CorrValueChanged(app, event)
            app.AllSpikeView.Event.CorrOn = app.Spike_CheckBox_Corr.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_CorrEditField
        function Spike_CorrEditFieldValueChanged(app, event)
            app.AllSpikeView.Event.Corr = app.Spike_CorrEditField.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_DistEditField
        function Spike_DistEditFieldValueChanged(app, event)
            app.AllSpikeView.Event.Dist = app.Spike_DistEditField.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_CheckBox_Dist
        function Spike_CheckBox_DistValueChanged(app, event)
            app.AllSpikeView.Event.DistOn = app.Spike_CheckBox_Dist.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_MinChanEditField
        function Spike_MinChanEditFieldValueChanged(app, event)
            app.AllSpikeView.Event.MinChan = app.Spike_MinChanEditField.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_CheckBox_MinChan
        function Spike_CheckBox_MinChanValueChanged(app, event)
            app.AllSpikeView.Event.MinChanOn = app.Spike_CheckBox_MinChan.Value;
            if(app.iState~=0)
                EventSelected(app);
                UpdateEvent(app);
                UpdatePlot(app, 0); 
            end
        end

        % Value changed function: Spike_ShowTopo
        function Spike_ShowTopoValueChanged(app, event)
            if(app.Spike_ShowTopo.Value)
                if(isempty(app.WExtrap))
                    app.UIAxesTopo.XAxis.Visible = 0;app.UIAxesTopo.YAxis.Visible = 0;app.UIAxesTopo.Color = [0.94, 0.94, 0.94];
                    WaitBar = uiprogressdlg(app.UIFigure,'Title','Prepare Topo','Message','Preparing Topo','Indeterminate','on');
                    selChan = find(contains({app.AllSpikeView.toShow.ChannelMat(1).Channel.Type}, 'MEG'));
                    chan_loc = cellfun(@(x)mean(x,2), {app.AllSpikeView.toShow.ChannelMat(1).Channel.Loc}, 'UniformOutput', false);chan_loc = cell2mat(chan_loc(selChan))';
                    FpreStim = in_fread(app.AllSpikeView.toShow.sFile(1),app.AllSpikeView.toShow.ChannelMat(1),1, ...
                                       (app.AllSpikeView.toShow.sFile(1).prop.times([1,1]) + [0, 10]) * app.AllSpikeView.toShow.sFile(1).prop.sfreq,selChan);
                    % ===== CREATE A HIGH-DEF SURFACE =====
                    % Remove the duplicated positions
                    precision = 1e4;
                    VerticesTemp = unique(round(chan_loc * precision)/precision,'rows');
                    % Remove the points at (0,0,0)
                    iZero = find(all(abs(VerticesTemp) < 1/precision, 2));
                    if ~isempty(iZero)
                        VerticesTemp(iZero,:) = [];
                    end
                    % Compute best fitting sphere from sensors
                    [bfs_center, bfs_radius] = bst_bfs(VerticesTemp);
                    % Tesselate sensor cap
                    FacesTemp = channel_tesselate(VerticesTemp, 1);
                    % Remove some  pathological triangles
                    FacesTemp = tess_threshold(VerticesTemp, FacesTemp, 5, 3, 170);
                    % Refine mesh
                    [VerticesTemp, FacesTemp] = tess_refine(VerticesTemp, FacesTemp, [], [], 1);
                    % Refine only low resolution surfaces
                    if (size(chan_loc, 1) < 100)
                        [VerticesTemp, FacesTemp] = tess_refine(VerticesTemp, FacesTemp, [], [], 1);
                    end
                    % 2D Projection
                    if all(VerticesTemp(:,3) < 0.0001)
                        X = VerticesTemp(:,1);
                        Y = VerticesTemp(:,2);
                    else
                        [X,Y] = bst_project_2d(VerticesTemp(:,1), VerticesTemp(:,2), VerticesTemp(:,3), '2dcap');
                    end
                    % Center and scale in a [-1,-1,2,2] reclangle
                    Xm = [min(X), max(X)];
                    Ym = [min(Y), max(Y)];
                    R = max(Xm(2)-Xm(1), Ym(2)-Ym(1));
                    X = (X - Xm(1)) / R * 2 - (Xm(2)-Xm(1))/R;
                    Y = (Y - Ym(1)) / R * 2 - (Ym(2)-Ym(1))/R;
                    % Get 2D vertices coordinates, re-tesselate
                    app.Vertices = [X, Y, 0*X];
                    app.Faces = delaunay(X,Y);
                    % Clean from some pathological triangles
                    app.Faces = tess_threshold(app.Vertices, app.Faces, 25, 20, 179);
                    % ===== COMPUTE INTERPOLATION =====
                    % Add Path
                    PrivateFigure3dPath = which('Main.mlapp');
                    addpath(genpath([fileparts(fileparts(PrivateFigure3dPath)),'/ExternalFun']));
                    % Magnetic interpolation: we want values everywhere
                    app.WExtrap = channel_extrapm('GetTopoInterp', app.AllSpikeView.toShow.ChannelMat(1), selChan, VerticesTemp, FacesTemp, bfs_center, bfs_radius, FpreStim);
                    selChan = find(contains({app.AllSpikeView.toShow.ChannelMat(1).Channel.Type}, 'MAG'));
                    app.WExtrapMag = channel_extrapm('GetTopoInterp', app.AllSpikeView.toShow.ChannelMat(1), selChan, VerticesTemp, FacesTemp, bfs_center, bfs_radius, FpreStim);
                    selChan = find(contains({app.AllSpikeView.toShow.ChannelMat(1).Channel.Type}, 'GRAD'));
                    app.WExtrapGrad = channel_extrapm('GetTopoInterp', app.AllSpikeView.toShow.ChannelMat(1), selChan, VerticesTemp, FacesTemp, bfs_center, bfs_radius, FpreStim);
                    PrivateFigure3dPath = which('Main.mlapp');
                    rmpath(genpath([fileparts(fileparts(PrivateFigure3dPath)),'/ExternalFun']));
                    close(WaitBar);
                end
                app.UIAxesMEG.Layout.Column = 5;app.UIAxesMEG.Layout.Row = 1;app.UIAxesMEG.Visible = 'on';
                app.UIAxesTopo.Layout.Column = [1,4];app.UIAxesTopo.Layout.Row = 1;app.UIAxesTopo.Visible = 'on';
                PlotCurrentTopo(app);
            else
                app.UIAxesMEG.Layout.Column = [1,5];app.UIAxesMEG.Layout.Row = 1;app.UIAxesMEG.Visible = 'on';
                app.UIAxesTopo.Layout.Column = 5;app.UIAxesTopo.Layout.Row = 2;app.UIAxesTopo.Visible = 'off';
                app.UIAxesTopo.clo; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PanelTop = uipanel(app.GridLayout);
            app.GridLayoutTop = uigridlayout(app.PanelTop);
            app.ViewHFOButton = uibutton(app.GridLayoutTop, 'push');
            app.ViewHFOButton.ButtonPushedFcn = createCallbackFcn(app, @ViewHFOButtonPushed, true);
            app.ViewHFOButton.Text = 'View HFO';
            app.LoadProject = uibutton(app.GridLayoutTop, 'push');
            app.LoadProject.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProject.Text = 'Load';
            app.Arrow = uilabel(app.GridLayoutTop);
            app.Arrow.Text = '-->';
            app.SubjectDropDownLabel = uilabel(app.GridLayoutTop);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDown = uidropdown(app.GridLayoutTop);
            app.SubjectDown.Items = {'None', ''};
            app.SubjectDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDownValueChanged, true);
            app.SubjectDown.Value = 'None';
            app.SaveProject = uibutton(app.GridLayoutTop, 'push');
            app.SaveProject.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectButtonPushed, true);
            app.SaveProject.Text = 'Save';
            app.ShowAllSoucesButton = uibutton(app.GridLayoutTop, 'push');
            app.ShowAllSoucesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllSoucesButtonPushed, true);
            app.ShowAllSoucesButton.Text = 'Show All Souces';
            app.Process = uibutton(app.GridLayoutTop, 'push');
            app.Process.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.Process.Text = 'Process';
            app.MEGFilesButton = uibutton(app.GridLayoutTop, 'push');
            app.MEGFilesButton.ButtonPushedFcn = createCallbackFcn(app, @MEGFilesButtonPushed, true);
            app.MEGFilesButton.Text = 'MEG Files';
            app.ShowSoucesButton = uibutton(app.GridLayoutTop, 'push');
            app.ShowSoucesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSoucesButtonPushed, true);
            app.ShowSoucesButton.Text = 'Show Event Source';
            app.ViewSpikeButton = uibutton(app.GridLayoutTop, 'push');
            app.ViewSpikeButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSpikeButtonPushed, true);
            app.ViewSpikeButton.Text = 'View Spike';
            app.ProcessStatus = uibutton(app.GridLayoutTop, 'push');
            app.ProcessStatus.ButtonPushedFcn = createCallbackFcn(app, @ProcessStatusButtonPushed, true);
            app.ProcessStatus.Text = 'Process Status';
            app.PanelRight = uipanel(app.GridLayout);
            app.GridLayoutRight = uigridlayout(app.PanelRight);
            app.ListBox = uilistbox(app.GridLayoutRight);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.CheckBox_HighPass = uicheckbox(app.GridLayoutRight);
            app.CheckBox_HighPass.ValueChangedFcn = createCallbackFcn(app, @CheckBox_HighPassValueChanged, true);
            app.CheckBox_HighPass.Text = '';
            app.CheckBox_HighPass.Value = true;
            app.HighPassEditFieldLabel = uilabel(app.GridLayoutRight);
            app.HighPassEditFieldLabel.Text = 'High Pass';
            app.HighPassEditField = uieditfield(app.GridLayoutRight, 'numeric');
            app.HighPassEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassEditFieldValueChanged, true);
            app.HighPassEditField.Value = 80;
            app.LowPassEditFieldLabel = uilabel(app.GridLayoutRight);
            app.LowPassEditFieldLabel.Text = 'Low Pass';
            app.LowPassEditField = uieditfield(app.GridLayoutRight, 'numeric');
            app.LowPassEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassEditFieldValueChanged, true);
            app.LowPassEditField.Value = 200;
            app.RejectCheckBox = uicheckbox(app.GridLayoutRight);
            app.RejectCheckBox.ValueChangedFcn = createCallbackFcn(app, @RejectCheckBoxValueChanged, true);
            app.RejectCheckBox.Text = 'Reject';
            app.CheckBox_LowPass = uicheckbox(app.GridLayoutRight);
            app.CheckBox_LowPass.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LowPassValueChanged, true);
            app.CheckBox_LowPass.Text = '';
            app.CheckBox_LowPass.Value = true;
            app.Spike_AccpetButton = uibutton(app.GridLayoutRight, 'push');
            app.Spike_AccpetButton.ButtonPushedFcn = createCallbackFcn(app, @Spike_AccpetButtonPushed, true);
            app.Spike_AccpetButton.Visible = 'off';
            app.Spike_AccpetButton.Text = 'Accpet';
            app.HFO_AccpetButton = uibutton(app.GridLayoutRight, 'push');
            app.HFO_AccpetButton.ButtonPushedFcn = createCallbackFcn(app, @HFO_AccpetButtonPushed, true);
            app.HFO_AccpetButton.Text = 'Accpet';
            app.Spike_RejectButton = uibutton(app.GridLayoutRight, 'push');
            app.Spike_RejectButton.ButtonPushedFcn = createCallbackFcn(app, @Spike_RejectButtonPushed, true);
            app.Spike_RejectButton.Visible = 'off';
            app.Spike_RejectButton.Text = 'Reject';
            app.HFO_RejectButton = uibutton(app.GridLayoutRight, 'push');
            app.HFO_RejectButton.ButtonPushedFcn = createCallbackFcn(app, @HFO_RejectButtonPushed, true);
            app.HFO_RejectButton.Text = 'Reject';
            app.UnDoCheckBox = uicheckbox(app.GridLayoutRight);
            app.UnDoCheckBox.ValueChangedFcn = createCallbackFcn(app, @UnDoCheckBoxValueChanged, true);
            app.UnDoCheckBox.Text = 'UnDo';
            app.OL_CheckBox = uicheckbox(app.GridLayoutRight);
            app.OL_CheckBox.ValueChangedFcn = createCallbackFcn(app, @OL_CheckBoxValueChanged, true);
            app.OL_CheckBox.Text = 'oHFO';
            app.AC_CheckBox = uicheckbox(app.GridLayoutRight);
            app.AC_CheckBox.ValueChangedFcn = createCallbackFcn(app, @AC_CheckBoxValueChanged, true);
            app.AC_CheckBox.Text = 'sHFO';
            app.InfoButton = uibutton(app.GridLayoutRight, 'state');
            app.InfoButton.ValueChangedFcn = createCallbackFcn(app, @InfoButtonValueChanged, true);
            app.InfoButton.Text = 'Info';
            app.PanelLeft = uipanel(app.GridLayout);
            app.GridLayoutLeft = uigridlayout(app.PanelLeft);
            app.Button_UP = uibutton(app.GridLayoutLeft, 'push');
            app.Button_UP.ButtonPushedFcn = createCallbackFcn(app, @Button_UPPushed, true);
            app.Button_UP.Text = '+';
            app.Button_Down = uibutton(app.GridLayoutLeft, 'push');
            app.Button_Down.ButtonPushedFcn = createCallbackFcn(app, @Button_DownPushed, true);
            app.Button_Down.Text = '-';
            app.HFO_CheckBox_EntropySpike = uicheckbox(app.GridLayoutLeft);
            app.HFO_CheckBox_EntropySpike.ValueChangedFcn = createCallbackFcn(app, @HFO_CheckBox_EntropySpikeValueChanged, true);
            app.HFO_CheckBox_EntropySpike.Text = 'Epy_S';
            app.HFO_EntropySpikeText = uitextarea(app.GridLayoutLeft);
            app.HFO_EntropySpikeText.Editable = 'off';
            app.HFO_CheckBox_LLSpike = uicheckbox(app.GridLayoutLeft);
            app.HFO_CheckBox_LLSpike.ValueChangedFcn = createCallbackFcn(app, @HFO_CheckBox_LLSpikeValueChanged, true);
            app.HFO_CheckBox_LLSpike.Text = 'LL_S';
            app.HFO_LLSpikeEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.HFO_LLSpikeEditField.ValueChangedFcn = createCallbackFcn(app, @HFO_LLSpikeEditFieldValueChanged, true);
            app.HFO_LLSpikeEditField.Value = 3;
            app.HFO_LLSpikeText = uitextarea(app.GridLayoutLeft);
            app.HFO_LLSpikeText.Editable = 'off';
            app.HFO_CheckBox_EntropyHFO = uicheckbox(app.GridLayoutLeft);
            app.HFO_CheckBox_EntropyHFO.ValueChangedFcn = createCallbackFcn(app, @HFO_CheckBox_EntropyHFOValueChanged, true);
            app.HFO_CheckBox_EntropyHFO.Text = 'Epy_H';
            app.HFO_EntropyHFOEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.HFO_EntropyHFOEditField.ValueChangedFcn = createCallbackFcn(app, @HFO_EntropyHFOEditFieldValueChanged, true);
            app.HFO_EntropyHFOEditField.Value = 0.8;
            app.HFO_EntropyHFOText = uitextarea(app.GridLayoutLeft);
            app.HFO_EntropyHFOText.Editable = 'off';
            app.HFO_CheckBox_AmpHFO = uicheckbox(app.GridLayoutLeft);
            app.HFO_CheckBox_AmpHFO.ValueChangedFcn = createCallbackFcn(app, @HFO_CheckBox_AmpHFOValueChanged, true);
            app.HFO_CheckBox_AmpHFO.Text = 'Amp_H';
            app.HFO_AmpHFOEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.HFO_AmpHFOEditField.ValueChangedFcn = createCallbackFcn(app, @HFO_AmpHFOEditFieldValueChanged, true);
            app.HFO_AmpHFOEditField.Value = 3;
            app.HFO_AmpHFOext = uitextarea(app.GridLayoutLeft);
            app.HFO_AmpHFOext.Editable = 'off';
            app.HFO_TimeFeaturesLabel = uilabel(app.GridLayoutLeft);
            app.HFO_TimeFeaturesLabel.Text = 'Time Features';
            app.HFO_EntropySpikeEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.HFO_EntropySpikeEditField.ValueChangedFcn = createCallbackFcn(app, @HFO_EntropySpikeEditFieldValueChanged, true);
            app.HFO_EntropySpikeEditField.Value = 0.8;
            app.HFO_ShowSpikeCheckBox = uicheckbox(app.GridLayoutLeft);
            app.HFO_ShowSpikeCheckBox.ValueChangedFcn = createCallbackFcn(app, @HFO_ShowSpikeCheckBoxValueChanged, true);
            app.HFO_ShowSpikeCheckBox.Text = 'Show Spike';
            app.HFO_AmpSpikeText = uitextarea(app.GridLayoutLeft);
            app.HFO_AmpSpikeText.Editable = 'off';
            app.HFO_AmpSpikeEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.HFO_AmpSpikeEditField.ValueChangedFcn = createCallbackFcn(app, @HFO_AmpSpikeEditFieldValueChanged, true);
            app.HFO_AmpSpikeEditField.Value = 3;
            app.HFO_CheckBox_AmpSpike = uicheckbox(app.GridLayoutLeft);
            app.HFO_CheckBox_AmpSpike.ValueChangedFcn = createCallbackFcn(app, @HFO_CheckBox_AmpSpikeValueChanged, true);
            app.HFO_CheckBox_AmpSpike.Text = 'Amp_S';
            app.HFO_TimeFeequencyFeaturesLabel = uilabel(app.GridLayoutLeft);
            app.HFO_TimeFeequencyFeaturesLabel.Text = 'Time Frequency Features';
            app.HFO_CheckBox_LLHFO = uicheckbox(app.GridLayoutLeft);
            app.HFO_CheckBox_LLHFO.ValueChangedFcn = createCallbackFcn(app, @HFO_CheckBox_LLHFOValueChanged, true);
            app.HFO_CheckBox_LLHFO.Text = 'LL_H';
            app.HFO_LLHFOEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.HFO_LLHFOEditField.ValueChangedFcn = createCallbackFcn(app, @HFO_LLHFOEditFieldValueChanged, true);
            app.HFO_LLHFOEditField.Value = 3;
            app.HFO_LLHFOText = uitextarea(app.GridLayoutLeft);
            app.HFO_LLHFOText.Editable = 'off';
            app.PanelView = uipanel(app.GridLayoutLeft);
            app.GridLayoutView = uigridlayout(app.PanelView);
            app.VSButton = uibutton(app.GridLayoutView, 'state');
            app.VSButton.ValueChangedFcn = createCallbackFcn(app, @VSButtonValueChanged, true);
            app.VSButton.Text = 'VS';
            app.MEGButton = uibutton(app.GridLayoutView, 'state');
            app.MEGButton.ValueChangedFcn = createCallbackFcn(app, @MEGButtonValueChanged, true);
            app.MEGButton.Text = 'MEG';
            app.TFButton = uibutton(app.GridLayoutView, 'state');
            app.TFButton.ValueChangedFcn = createCallbackFcn(app, @TFButtonValueChanged, true);
            app.TFButton.Text = 'TF';
            app.TemplateButton = uibutton(app.GridLayoutView, 'state');
            app.TemplateButton.ValueChangedFcn = createCallbackFcn(app, @TemplateButtonValueChanged, true);
            app.TemplateButton.Text = 'Template';
            app.UIAxesVS = uiaxes(app.GridLayoutView);
            title(app.UIAxesVS, '')
            xlabel(app.UIAxesVS, '')
            ylabel(app.UIAxesVS, '')
            app.UIAxesVS.XTickLabel = '';
            app.UIAxesVS.YTickLabel = '';
            app.UIAxesVS.Visible = 'off';
            app.UIAxesMEG = uiaxes(app.GridLayoutView);
            title(app.UIAxesMEG, '')
            xlabel(app.UIAxesMEG, '')
            ylabel(app.UIAxesMEG, '')
            app.UIAxesMEG.XTickLabel = '';
            app.UIAxesMEG.YTickLabel = '';
            app.UIAxesMEG.Visible = 'off';
            app.UIAxesTF = uiaxes(app.GridLayoutView);
            title(app.UIAxesTF, '')
            xlabel(app.UIAxesTF, '')
            ylabel(app.UIAxesTF, '')
            app.UIAxesTF.XTickLabel = '';
            app.UIAxesTF.YTickLabel = '';
            app.UIAxesTF.Visible = 'off';
            app.UIAxesTemplate = uiaxes(app.GridLayoutView);
            title(app.UIAxesTemplate, '')
            xlabel(app.UIAxesTemplate, '')
            ylabel(app.UIAxesTemplate, '')
            app.UIAxesTemplate.XTickLabel = '';
            app.UIAxesTemplate.YTickLabel = '';
            app.UIAxesTemplate.Visible = 'off';
            app.UIAxesTopo = uiaxes(app.GridLayoutView);
            title(app.UIAxesTopo, '')
            xlabel(app.UIAxesTopo, '')
            ylabel(app.UIAxesTopo, '')
            app.UIAxesTopo.XTickLabel = '';
            app.UIAxesTopo.YTickLabel = '';
            app.UIAxesTopo.Visible = 'off';
            app.View_Row = uilabel(app.GridLayoutLeft);
            app.View_Row.Text = 'Row';
            app.View_DropDown_Rows = uidropdown(app.GridLayoutLeft);
            app.View_DropDown_Rows.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39'};
            app.View_DropDown_Rows.ValueChangedFcn = createCallbackFcn(app, @View_DropDown_RowsValueChanged, true);
            app.View_DropDown_Rows.Value = '16';
            app.View_Col = uilabel(app.GridLayoutLeft);
            app.View_Col.Text = 'Col';
            app.View_DropDown_Columns = uidropdown(app.GridLayoutLeft);
            app.View_DropDown_Columns.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.View_DropDown_Columns.ValueChangedFcn = createCallbackFcn(app, @View_DropDown_ColumnsValueChanged, true);
            app.View_DropDown_Columns.Value = '3';
            app.Spike_SlopeEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.Spike_SlopeEditField.ValueChangedFcn = createCallbackFcn(app, @Spike_SlopeEditFieldValueChanged, true);
            app.Spike_SlopeEditField.Visible = 'off';
            app.Spike_SlopeEditField.Value = 3;
            app.Spike_CheckBox_Slope = uicheckbox(app.GridLayoutLeft);
            app.Spike_CheckBox_Slope.ValueChangedFcn = createCallbackFcn(app, @Spike_CheckBox_SlopeValueChanged, true);
            app.Spike_CheckBox_Slope.Visible = 'off';
            app.Spike_CheckBox_Slope.Text = 'Slope';
            app.Spike_SlopeText = uitextarea(app.GridLayoutLeft);
            app.Spike_SlopeText.Editable = 'off';
            app.Spike_SlopeText.Visible = 'off';
            app.Spike_AmpEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.Spike_AmpEditField.ValueChangedFcn = createCallbackFcn(app, @Spike_AmpEditFieldValueChanged, true);
            app.Spike_AmpEditField.Visible = 'off';
            app.Spike_AmpEditField.Value = 3;
            app.Spike_CheckBox_Amp = uicheckbox(app.GridLayoutLeft);
            app.Spike_CheckBox_Amp.ValueChangedFcn = createCallbackFcn(app, @Spike_CheckBox_AmpValueChanged, true);
            app.Spike_CheckBox_Amp.Visible = 'off';
            app.Spike_CheckBox_Amp.Text = 'Amp';
            app.Spike_AmpText = uitextarea(app.GridLayoutLeft);
            app.Spike_AmpText.Editable = 'off';
            app.Spike_AmpText.Visible = 'off';
            app.Spike_SharpEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.Spike_SharpEditField.ValueChangedFcn = createCallbackFcn(app, @Spike_SharpEditFieldValueChanged, true);
            app.Spike_SharpEditField.Visible = 'off';
            app.Spike_SharpEditField.Value = 3;
            app.Spike_CheckBox_Sharp = uicheckbox(app.GridLayoutLeft);
            app.Spike_CheckBox_Sharp.ValueChangedFcn = createCallbackFcn(app, @Spike_CheckBox_SharpValueChanged, true);
            app.Spike_CheckBox_Sharp.Visible = 'off';
            app.Spike_CheckBox_Sharp.Text = 'Sharp';
            app.Spike_SharpText = uitextarea(app.GridLayoutLeft);
            app.Spike_SharpText.Editable = 'off';
            app.Spike_SharpText.Visible = 'off';
            app.Spike_TimeFeaturesLabel = uilabel(app.GridLayoutLeft);
            app.Spike_TimeFeaturesLabel.Visible = 'off';
            app.Spike_TimeFeaturesLabel.Text = 'Time Features';
            app.Spike_SimilarityFeaturesLabel = uilabel(app.GridLayoutLeft);
            app.Spike_SimilarityFeaturesLabel.Visible = 'off';
            app.Spike_SimilarityFeaturesLabel.Text = 'Similarity Features';
            app.Spike_CorrText = uitextarea(app.GridLayoutLeft);
            app.Spike_CorrText.Editable = 'off';
            app.Spike_CorrText.Visible = 'off';
            app.Spike_CheckBox_Corr = uicheckbox(app.GridLayoutLeft);
            app.Spike_CheckBox_Corr.ValueChangedFcn = createCallbackFcn(app, @Spike_CheckBox_CorrValueChanged, true);
            app.Spike_CheckBox_Corr.Visible = 'off';
            app.Spike_CheckBox_Corr.Text = 'Corr';
            app.Spike_CorrEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.Spike_CorrEditField.ValueChangedFcn = createCallbackFcn(app, @Spike_CorrEditFieldValueChanged, true);
            app.Spike_CorrEditField.Visible = 'off';
            app.Spike_CorrEditField.Value = 0.75;
            app.Spike_DistEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.Spike_DistEditField.ValueChangedFcn = createCallbackFcn(app, @Spike_DistEditFieldValueChanged, true);
            app.Spike_DistEditField.Visible = 'off';
            app.Spike_DistEditField.Value = 2.5;
            app.Spike_CheckBox_Dist = uicheckbox(app.GridLayoutLeft);
            app.Spike_CheckBox_Dist.ValueChangedFcn = createCallbackFcn(app, @Spike_CheckBox_DistValueChanged, true);
            app.Spike_CheckBox_Dist.Visible = 'off';
            app.Spike_CheckBox_Dist.Text = 'Dist';
            app.Spike_DistText = uitextarea(app.GridLayoutLeft);
            app.Spike_DistText.Editable = 'off';
            app.Spike_DistText.Visible = 'off';
            app.Spike_MinChanEditField = uieditfield(app.GridLayoutLeft, 'numeric');
            app.Spike_MinChanEditField.ValueChangedFcn = createCallbackFcn(app, @Spike_MinChanEditFieldValueChanged, true);
            app.Spike_MinChanEditField.Visible = 'off';
            app.Spike_MinChanEditField.Value = 5;
            app.Spike_CheckBox_MinChan = uicheckbox(app.GridLayoutLeft);
            app.Spike_CheckBox_MinChan.ValueChangedFcn = createCallbackFcn(app, @Spike_CheckBox_MinChanValueChanged, true);
            app.Spike_CheckBox_MinChan.Visible = 'off';
            app.Spike_CheckBox_MinChan.Text = 'Chan';
            app.Spike_MinChanText = uitextarea(app.GridLayoutLeft);
            app.Spike_MinChanText.Editable = 'off';
            app.Spike_MinChanText.Visible = 'off';
            app.Spike_ShowTopo = uicheckbox(app.GridLayoutLeft);
            app.Spike_ShowTopo.ValueChangedFcn = createCallbackFcn(app, @Spike_ShowTopoValueChanged, true);
            app.Spike_ShowTopo.Visible = 'off';
            app.Spike_ShowTopo.Text = 'Show Topo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end